classdef appCombinedloop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Gage_RR_App                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BackendCanTab                   matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UITable8                        matlab.ui.control.Table
        SummaryTableLabel               matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GageNameSNEditFieldLabel        matlab.ui.control.Label
        GageNameSNEditField             matlab.ui.control.EditField
        CompanyEditFieldLabel           matlab.ui.control.Label
        CompanyEditField                matlab.ui.control.EditField
        OperatorEditFieldLabel          matlab.ui.control.Label
        OperatorEditField               matlab.ui.control.EditField
        FilenameInfoLabel               matlab.ui.control.Label
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        HeightToleranceEditFieldLabel   matlab.ui.control.Label
        HeightToleranceEditField        matlab.ui.control.NumericEditField
        FlangeWidthToleranceEditFieldLabel  matlab.ui.control.Label
        FlangeWidthToleranceEditField   matlab.ui.control.NumericEditField
        PlugDiameterToleranceEditFieldLabel  matlab.ui.control.Label
        PlugDiameterToleranceEditField  matlab.ui.control.NumericEditField
        MaxReformDiameterToleranceEditFieldLabel  matlab.ui.control.Label
        MaxReformDiameterToleranceEditField  matlab.ui.control.NumericEditField
        DomeDepthToleranceEditFieldLabel  matlab.ui.control.Label
        DomeDepthToleranceEditField     matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        SigmaLevelEditFieldLabel        matlab.ui.control.Label
        SigmaLevelEditField             matlab.ui.control.NumericEditField
        Panel_4                         matlab.ui.container.Panel
        ShowReformResultsCheckBox       matlab.ui.control.CheckBox
        ShowAnovatablesCheckBox         matlab.ui.control.CheckBox
        Panel_5                         matlab.ui.container.Panel
        RunanalysisButton               matlab.ui.control.Button
        ShowResultsforDropDownLabel     matlab.ui.control.Label
        ShowResultsforDropDown          matlab.ui.control.DropDown
        BackendSPTab                    matlab.ui.container.Tab
        UITable2_2                      matlab.ui.control.Table
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        UITable8_2                      matlab.ui.control.Table
        SummaryTableLabel_2             matlab.ui.control.Label
        UpdateButton_2                  matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        GageNameSNEditField_2Label      matlab.ui.control.Label
        GageNameSNEditField_2           matlab.ui.control.EditField
        CompanyEditField_2Label         matlab.ui.control.Label
        CompanyEditField_2              matlab.ui.control.EditField
        OperatorEditField_2Label        matlab.ui.control.Label
        OperatorEditField_2             matlab.ui.control.EditField
        FilenameInfoLabel_2             matlab.ui.control.Label
        DirectoryEditField_2Label       matlab.ui.control.Label
        DirectoryEditField_2            matlab.ui.control.EditField
        Panel_7                         matlab.ui.container.Panel
        HeightToleranceEditField_2Label  matlab.ui.control.Label
        HeightToleranceEditField_2      matlab.ui.control.NumericEditField
        ReformDiameterToleranceEditFieldLabel  matlab.ui.control.Label
        ReformDiameterToleranceEditField  matlab.ui.control.NumericEditField
        DomeDepthToleranceEditField_3Label  matlab.ui.control.Label
        DomeDepthToleranceEditField_3   matlab.ui.control.NumericEditField
        Diameter1ToleranceEditFieldLabel  matlab.ui.control.Label
        Diameter1ToleranceEditField     matlab.ui.control.NumericEditField
        Diameter2ToleranceLabel         matlab.ui.control.Label
        Diameter2ToleranceEditField     matlab.ui.control.NumericEditField
        Panel_8                         matlab.ui.container.Panel
        SigmaLevelEditField_2Label      matlab.ui.control.Label
        SigmaLevelEditField_2           matlab.ui.control.NumericEditField
        Panel_9                         matlab.ui.container.Panel
        ShowReformResultsCheckBox_2     matlab.ui.control.CheckBox
        ShowAnovatablesCheckBox_2       matlab.ui.control.CheckBox
        Panel_10                        matlab.ui.container.Panel
        RunanalysisButton_2             matlab.ui.control.Button
        ShowResultsforDropDown_2Label   matlab.ui.control.Label
        ShowResultsforDropDown_2        matlab.ui.control.DropDown
        FrontendCanTab                  matlab.ui.container.Tab
        UITable2_5                      matlab.ui.control.Table
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes3_5                       matlab.ui.control.UIAxes
        UIAxes4_5                       matlab.ui.control.UIAxes
        UITable8_4                      matlab.ui.control.Table
        SummaryTableLabel_4             matlab.ui.control.Label
        UpdateButton_4                  matlab.ui.control.Button
        Panel_22                        matlab.ui.container.Panel
        GageNameSNEditField_5Label      matlab.ui.control.Label
        GageNameSNEditField_5           matlab.ui.control.EditField
        CompanyEditField_5Label         matlab.ui.control.Label
        CompanyEditField_5              matlab.ui.control.EditField
        OperatorEditField_5Label        matlab.ui.control.Label
        OperatorEditField_5             matlab.ui.control.EditField
        FilenameInfoLabel_5             matlab.ui.control.Label
        DirectoryEditField_5Label       matlab.ui.control.Label
        DirectoryEditField_5            matlab.ui.control.EditField
        Panel_23                        matlab.ui.container.Panel
        CanHeightToleranceEditFieldLabel  matlab.ui.control.Label
        CanHeightToleranceEditField     matlab.ui.control.NumericEditField
        CanDomeDepthToleranceEditFieldLabel  matlab.ui.control.Label
        CanDomeDepthToleranceEditField  matlab.ui.control.NumericEditField
        CanTopWallToleranceEditFieldLabel  matlab.ui.control.Label
        CanTopWallToleranceEditField    matlab.ui.control.NumericEditField
        CanMidWallToleranceEditFieldLabel  matlab.ui.control.Label
        CanMidWallToleranceEditField    matlab.ui.control.NumericEditField
        Panel_24                        matlab.ui.container.Panel
        SigmaLevelEditField_5Label      matlab.ui.control.Label
        SigmaLevelEditField_5           matlab.ui.control.NumericEditField
        Panel_25                        matlab.ui.container.Panel
        ShowAnovatablesCheckBox_5       matlab.ui.control.CheckBox
        Panel_26                        matlab.ui.container.Panel
        RunanalysisButton_5             matlab.ui.control.Button
        ShowResultsforDropDown_4Label   matlab.ui.control.Label
        ShowResultsforDropDown_4        matlab.ui.control.DropDown
        FrontendSPTab                   matlab.ui.container.Tab
        UITable2_7                      matlab.ui.control.Table
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
        UIAxes3_7                       matlab.ui.control.UIAxes
        UIAxes4_7                       matlab.ui.control.UIAxes
        UITable8_6                      matlab.ui.control.Table
        SummaryTableLabel_6             matlab.ui.control.Label
        UpdateButton_7                  matlab.ui.control.Button
        Panel_34                        matlab.ui.container.Panel
        GageNameSNEditField_9Label      matlab.ui.control.Label
        GageNameSNEditField_9           matlab.ui.control.EditField
        CompanyEditField_7Label         matlab.ui.control.Label
        CompanyEditField_7              matlab.ui.control.EditField
        OperatorEditField_9Label        matlab.ui.control.Label
        OperatorEditField_9             matlab.ui.control.EditField
        FilenameInfoLabel_9             matlab.ui.control.Label
        DirectoryEditField_10Label      matlab.ui.control.Label
        DirectoryEditField_10           matlab.ui.control.EditField
        Panel_35                        matlab.ui.container.Panel
        SettingPieceHeightToleranceEditField_2Label  matlab.ui.control.Label
        SettingPieceHeightToleranceEditField_2  matlab.ui.control.NumericEditField
        SettingPieceDomeDepthToleranceEditField_3Label  matlab.ui.control.Label
        SettingPieceDomeDepthToleranceEditField_3  matlab.ui.control.NumericEditField
        Panel_36                        matlab.ui.container.Panel
        SigmaLevelEditField_7Label      matlab.ui.control.Label
        SigmaLevelEditField_7           matlab.ui.control.NumericEditField
        Panel_37                        matlab.ui.container.Panel
        ShowAnovatablesCheckBox_7       matlab.ui.control.CheckBox
        Panel_38                        matlab.ui.container.Panel
        RunanalysisButton_7             matlab.ui.control.Button
        ShowResultsforDropDown_7Label   matlab.ui.control.Label
        ShowResultsforDropDown_7        matlab.ui.control.DropDown
        DRGCanTab                       matlab.ui.container.Tab
        UITable2_6                      matlab.ui.control.Table
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes3_6                       matlab.ui.control.UIAxes
        UIAxes4_6                       matlab.ui.control.UIAxes
        UITable8_5                      matlab.ui.control.Table
        SummaryTableLabel_5             matlab.ui.control.Label
        UpdateButton_5                  matlab.ui.control.Button
        Panel_27                        matlab.ui.container.Panel
        GageNameSNEditField_6Label      matlab.ui.control.Label
        GageNameSNEditField_6           matlab.ui.control.EditField
        CompanyEditField_6Label         matlab.ui.control.Label
        CompanyEditField_6              matlab.ui.control.EditField
        OperatorEditField_6Label        matlab.ui.control.Label
        OperatorEditField_6             matlab.ui.control.EditField
        FilenameInfoLabel_6             matlab.ui.control.Label
        DirectoryEditField_6Label       matlab.ui.control.Label
        DirectoryEditField_6            matlab.ui.control.EditField
        Panel_28                        matlab.ui.container.Panel
        CanDomeDepthToleranceEditField_2Label  matlab.ui.control.Label
        CanDomeDepthToleranceEditField_2  matlab.ui.control.NumericEditField
        CanMaxReformDiameterToleranceEditFieldLabel  matlab.ui.control.Label
        CanMaxReformDiameterToleranceEditField  matlab.ui.control.NumericEditField
        CanChuckValueToleranceEditFieldLabel  matlab.ui.control.Label
        CanChuckValueToleranceEditField  matlab.ui.control.NumericEditField
        CanReformHeightToleranceEditFieldLabel  matlab.ui.control.Label
        CanReformHeightToleranceEditField  matlab.ui.control.NumericEditField
        Panel_29                        matlab.ui.container.Panel
        SigmaLevelEditField_6Label      matlab.ui.control.Label
        SigmaLevelEditField_6           matlab.ui.control.NumericEditField
        Panel_30                        matlab.ui.container.Panel
        ShowAnovatablesCheckBox_6       matlab.ui.control.CheckBox
        Panel_31                        matlab.ui.container.Panel
        RunanalysisButton_6             matlab.ui.control.Button
        ShowResultsforDropDown_5Label   matlab.ui.control.Label
        ShowResultsforDropDown_5        matlab.ui.control.DropDown
        DRGSPTab                        matlab.ui.container.Tab
        UITable2_8                      matlab.ui.control.Table
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes2_8                       matlab.ui.control.UIAxes
        UIAxes3_8                       matlab.ui.control.UIAxes
        UIAxes4_8                       matlab.ui.control.UIAxes
        UITable8_7                      matlab.ui.control.Table
        SummaryTableLabel_7             matlab.ui.control.Label
        UpdateButton_8                  matlab.ui.control.Button
        Panel_39                        matlab.ui.container.Panel
        GageNameSNEditField_10Label     matlab.ui.control.Label
        GageNameSNEditField_10          matlab.ui.control.EditField
        CompanyEditField_8Label         matlab.ui.control.Label
        CompanyEditField_8              matlab.ui.control.EditField
        OperatorEditField_10Label       matlab.ui.control.Label
        OperatorEditField_10            matlab.ui.control.EditField
        FilenameInfoLabel_10            matlab.ui.control.Label
        DirectoryEditField_11Label      matlab.ui.control.Label
        DirectoryEditField_11           matlab.ui.control.EditField
        Panel_40                        matlab.ui.container.Panel
        SettingPieceDomeDepthToleranceEditField_4Label  matlab.ui.control.Label
        SettingPieceDomeDepthToleranceEditField_4  matlab.ui.control.NumericEditField
        SettingPieceMaxReformDiameterToleranceEditField_2Label  matlab.ui.control.Label
        SettingPieceMaxReformDiameterToleranceEditField_2  matlab.ui.control.NumericEditField
        SettingPieceChuckValueToleranceEditField_2Label  matlab.ui.control.Label
        SettingPieceChuckValueToleranceEditField_2  matlab.ui.control.NumericEditField
        ShowResultsforDropDown_8Label   matlab.ui.control.Label
        ShowResultsforDropDown_8        matlab.ui.control.DropDown
        Panel_41                        matlab.ui.container.Panel
        SigmaLevelEditField_8Label      matlab.ui.control.Label
        SigmaLevelEditField_8           matlab.ui.control.NumericEditField
        Panel_42                        matlab.ui.container.Panel
        ShowAnovatablesCheckBox_8       matlab.ui.control.CheckBox
        Panel_43                        matlab.ui.container.Panel
        RunanalysisButton_8             matlab.ui.control.Button
        FilmscanTab                     matlab.ui.container.Tab
        UITable2_3                      matlab.ui.control.Table
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes4_3                       matlab.ui.control.UIAxes
        UITable8_3                      matlab.ui.control.Table
        SummaryTableLabel_3             matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GageNameSNEditField_3Label      matlab.ui.control.Label
        GageNameSNEditField_3           matlab.ui.control.EditField
        CompanyEditField_3Label         matlab.ui.control.Label
        CompanyEditField_3              matlab.ui.control.EditField
        OperatorEditField_3Label        matlab.ui.control.Label
        OperatorEditField_3             matlab.ui.control.EditField
        DirectoryEditField_3Label       matlab.ui.control.Label
        DirectoryEditField_3            matlab.ui.control.EditField
        Panel_12                        matlab.ui.container.Panel
        DomeToleranceEditFieldLabel     matlab.ui.control.Label
        DomeToleranceEditField          matlab.ui.control.NumericEditField
        ExternalToleranceEditFieldLabel  matlab.ui.control.Label
        ExternalToleranceEditField      matlab.ui.control.NumericEditField
        InternalWallToleranceEditFieldLabel  matlab.ui.control.Label
        InternalWallToleranceEditField  matlab.ui.control.NumericEditField
        ReverseWallToleranceEditFieldLabel  matlab.ui.control.Label
        ReverseWallToleranceEditField   matlab.ui.control.NumericEditField
        RimCoatToleranceEditFieldLabel  matlab.ui.control.Label
        RimCoatToleranceEditField       matlab.ui.control.NumericEditField
        Panel_13                        matlab.ui.container.Panel
        SliceAverageSigmaLevelEditFieldLabel  matlab.ui.control.Label
        SliceAverageSigmaLevelEditField  matlab.ui.control.NumericEditField
        SinglePointSigmaLevelEditFieldLabel  matlab.ui.control.Label
        SinglePointSigmaLevelEditField  matlab.ui.control.NumericEditField
        Panel_14                        matlab.ui.container.Panel
        ShowAnovatablesCheckBox_3       matlab.ui.control.CheckBox
        Panel_15                        matlab.ui.container.Panel
        RunanalysisButton_3             matlab.ui.control.Button
        ShowResultsforDropDown_3Label   matlab.ui.control.Label
        ShowResultsforDropDown_3        matlab.ui.control.DropDown
        UpdateButton_3                  matlab.ui.control.Button
        Panel_54                        matlab.ui.container.Panel
        Measure1EditFieldLabel          matlab.ui.control.Label
        Measure1EditField               matlab.ui.control.NumericEditField
        Measure2EditFieldLabel          matlab.ui.control.Label
        Measure2EditField               matlab.ui.control.NumericEditField
        Measure3EditFieldLabel          matlab.ui.control.Label
        Measure3EditField               matlab.ui.control.NumericEditField
        ReferenceValuesLabel            matlab.ui.control.Label
        IncludeLinearityCertificateinreportCheckBox  matlab.ui.control.CheckBox
        ToleranceumEditFieldLabel       matlab.ui.control.Label
        ToleranceumEditField            matlab.ui.control.NumericEditField
        RunIndividuallyButton           matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        ToleranceumEditField_2          matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Panel_61                        matlab.ui.container.Panel
        LacquerPlateAnalysisLabel       matlab.ui.control.Label
        IncludeLPanalyisinfilmscanreportCheckBox  matlab.ui.control.CheckBox
        RunIndividuallyButton_2         matlab.ui.control.Button
        ES8000Tab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        IndividualProccessingTab        matlab.ui.container.Tab
        UITable9_4                      matlab.ui.control.Table
        UITable9_5                      matlab.ui.control.Table
        Panel_64                        matlab.ui.container.Panel
        GageNameSNEditField_7Label_2    matlab.ui.control.Label
        GageNameSNEditField_13          matlab.ui.control.EditField
        CompanyMiscEditFieldLabel_2     matlab.ui.control.Label
        CompanyMiscEditField_3          matlab.ui.control.EditField
        OperatorEditField_7Label_2      matlab.ui.control.Label
        OperatorEditField_13            matlab.ui.control.EditField
        FilenameInfoLabel_13            matlab.ui.control.Label
        DirectoryEditField_8Label_2     matlab.ui.control.Label
        DirectoryEditField_14           matlab.ui.control.EditField
        RunAnalysisButton_3             matlab.ui.control.Button
        CloseAllButton_2                matlab.ui.control.Button
        SpecifyCSVFileEditFieldLabel_2  matlab.ui.control.Label
        SpecifyCSVFileEditField_2       matlab.ui.control.EditField
        CSVFoundLampLabel_2             matlab.ui.control.Label
        CSVFoundLamp_2                  matlab.ui.control.Lamp
        ShowAnovaTablesCheckBox_2       matlab.ui.control.CheckBox
        Label_11                        matlab.ui.control.Label
        Knob_2                          matlab.ui.control.DiscreteKnob
        MeaurementsinImperialCheckBox_3  matlab.ui.control.CheckBox
        UITable9_6                      matlab.ui.control.Table
        ReportGeneratorTab              matlab.ui.container.Tab
        Panel_65                        matlab.ui.container.Panel
        GageNameSNEditField_7Label_3    matlab.ui.control.Label
        GageNameSNEditField_14          matlab.ui.control.EditField
        CompanyMiscEditFieldLabel_3     matlab.ui.control.Label
        CompanyMiscEditField_4          matlab.ui.control.EditField
        OperatorEditField_7Label_3      matlab.ui.control.Label
        OperatorEditField_14            matlab.ui.control.EditField
        DirectoryEditField_8Label_3     matlab.ui.control.Label
        DirectoryEditField_15           matlab.ui.control.EditField
        RunAnalysisButton_4             matlab.ui.control.Button
        MeaurementsinImperialCheckBox_4  matlab.ui.control.CheckBox
        CSVFoundLampLabel_3             matlab.ui.control.Label
        CSVFoundLamp_3                  matlab.ui.control.Lamp
        Label_12                        matlab.ui.control.Label
        Panel_67                        matlab.ui.container.Panel
        PathofFolderwithExcelresultsinLabel  matlab.ui.control.Label
        SpecifyCSVFileEditField_3       matlab.ui.control.EditField
        Panel_66                        matlab.ui.container.Panel
        NotesatstartofreportEditFieldLabel  matlab.ui.control.Label
        NotesatstartofreportEditField   matlab.ui.control.EditField
        CustomTab                       matlab.ui.container.Tab
        UITable2_10                     matlab.ui.control.Table
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes2_10                      matlab.ui.control.UIAxes
        UIAxes3_10                      matlab.ui.control.UIAxes
        UIAxes4_10                      matlab.ui.control.UIAxes
        SummaryTableLabel_9             matlab.ui.control.Label
        Panel_49                        matlab.ui.container.Panel
        GageNameSNEditField_12Label     matlab.ui.control.Label
        GageNameSNEditField_12          matlab.ui.control.EditField
        MiscEditFieldLabel              matlab.ui.control.Label
        MiscEditField                   matlab.ui.control.EditField
        OperatorEditField_12Label       matlab.ui.control.Label
        OperatorEditField_12            matlab.ui.control.EditField
        FilenameInfoLabel_12            matlab.ui.control.Label
        DirectoryEditField_13Label      matlab.ui.control.Label
        DirectoryEditField_13           matlab.ui.control.EditField
        Panel_50                        matlab.ui.container.Panel
        FeatureNameEditFieldLabel       matlab.ui.control.Label
        FeatureNameEditField            matlab.ui.control.EditField
        Panel_51                        matlab.ui.container.Panel
        SigmaLevelEditField_10Label     matlab.ui.control.Label
        SigmaLevelEditField_10          matlab.ui.control.NumericEditField
        ShowAnovatablesCheckBox_10      matlab.ui.control.CheckBox
        Panel_53                        matlab.ui.container.Panel
        RunanalysisButton_10            matlab.ui.control.Button
        Panel_55                        matlab.ui.container.Panel
        ToleranceEditField_2Label       matlab.ui.control.Label
        ToleranceEditField_2            matlab.ui.control.NumericEditField
        Panel_56                        matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        FilmweightTab                   matlab.ui.container.Tab
        UITable2_9                      matlab.ui.control.Table
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes2_9                       matlab.ui.control.UIAxes
        UIAxes3_9                       matlab.ui.control.UIAxes
        UIAxes4_9                       matlab.ui.control.UIAxes
        UITable8_8                      matlab.ui.control.Table
        SummaryTableLabel_8             matlab.ui.control.Label
        UpdateButton_9                  matlab.ui.control.Button
        Panel_44                        matlab.ui.container.Panel
        GageNameSNEditField_11Label     matlab.ui.control.Label
        GageNameSNEditField_11          matlab.ui.control.EditField
        CompanyEditField_9Label         matlab.ui.control.Label
        CompanyEditField_9              matlab.ui.control.EditField
        OperatorEditField_11Label       matlab.ui.control.Label
        OperatorEditField_11            matlab.ui.control.EditField
        FilenameInfoLabel_11            matlab.ui.control.Label
        DirectoryEditField_12Label      matlab.ui.control.Label
        DirectoryEditField_12           matlab.ui.control.EditField
        Panel_45                        matlab.ui.container.Panel
        ExternalCoatingToleranceEditFieldLabel  matlab.ui.control.Label
        ExternalCoatingToleranceEditField  matlab.ui.control.NumericEditField
        InternalCoatingToleranceEditField_2Label  matlab.ui.control.Label
        InternalCoatingToleranceEditField_2  matlab.ui.control.NumericEditField
        Panel_46                        matlab.ui.container.Panel
        SigmaLevelEditField_9Label      matlab.ui.control.Label
        SigmaLevelEditField_9           matlab.ui.control.NumericEditField
        Panel_48                        matlab.ui.container.Panel
        RunanalysisButton_9             matlab.ui.control.Button
        ShowAnovatablesCheckBox_9       matlab.ui.control.CheckBox
        ShowResultsforDropDown_9Label   matlab.ui.control.Label
        ShowResultsforDropDown_9        matlab.ui.control.DropDown
        Panel_62                        matlab.ui.container.Panel
        LacquerPlateAnalysisLabel_2     matlab.ui.control.Label
        IncludeLPanalyisinReportCheckBox  matlab.ui.control.CheckBox
        RunIndividuallyButton_3         matlab.ui.control.Button
        Panel_63                        matlab.ui.container.Panel
        Measure1EditFieldLabel_2        matlab.ui.control.Label
        Measure1EditField_2             matlab.ui.control.NumericEditField
        Measure2EditFieldLabel_2        matlab.ui.control.Label
        Measure2EditField_2             matlab.ui.control.NumericEditField
        Measure3EditFieldLabel_2        matlab.ui.control.Label
        Measure3EditField_2             matlab.ui.control.NumericEditField
        ReferenceValuesLabel_2          matlab.ui.control.Label
        IncludeLinearityCertificateinreportCheckBox_2  matlab.ui.control.CheckBox
        ToleranceumEditFieldLabel_2     matlab.ui.control.Label
        ToleranceumEditField_3          matlab.ui.control.NumericEditField
        RunIndividuallyButton_4         matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        ToleranceumEditField_4          matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        FilmweightSPTab                 matlab.ui.container.Tab
        UITable2_4                      matlab.ui.control.Table
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes3_4                       matlab.ui.control.UIAxes
        UIAxes4_4                       matlab.ui.control.UIAxes
        EvaluationTableLabel            matlab.ui.control.Label
        Panel_16                        matlab.ui.container.Panel
        GageNameSNEditField_4Label      matlab.ui.control.Label
        GageNameSNEditField_4           matlab.ui.control.EditField
        CompanyEditField_4Label         matlab.ui.control.Label
        CompanyEditField_4              matlab.ui.control.EditField
        OperatorEditField_4Label        matlab.ui.control.Label
        OperatorEditField_4             matlab.ui.control.EditField
        FilenameInfoLabel_4             matlab.ui.control.Label
        DirectoryEditField_4Label       matlab.ui.control.Label
        DirectoryEditField_4            matlab.ui.control.EditField
        Panel_17                        matlab.ui.container.Panel
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        Panel_18                        matlab.ui.container.Panel
        SigmaLevelEditField_4Label      matlab.ui.control.Label
        SigmaLevelEditField_4           matlab.ui.control.NumericEditField
        Panel_19                        matlab.ui.container.Panel
        ShowAnovatablesCheckBox_4       matlab.ui.control.CheckBox
        Panel_20                        matlab.ui.container.Panel
        RunanalysisButton_4             matlab.ui.control.Button
        Panel_21                        matlab.ui.container.Panel
        LightwillilluminateredifnovaliddatasetcanbeselectedLampLabel  matlab.ui.control.Label
        LightwillilluminateredifnovaliddatasetcanbeselectedLamp  matlab.ui.control.Lamp
        UpdateButton_6                  matlab.ui.control.Button
        ShowResultsforDropDown_6Label   matlab.ui.control.Label
        ShowResultsforDropDown_6        matlab.ui.control.DropDown
        EPGTab                          matlab.ui.container.Tab
        Panel_33                        matlab.ui.container.Panel
        GageNameSNEditField_8Label      matlab.ui.control.Label
        GageNameSNEditField_8           matlab.ui.control.EditField
        CompanyMiscEditField_2Label     matlab.ui.control.Label
        CompanyMiscEditField_2          matlab.ui.control.EditField
        OperatorEditField_8Label        matlab.ui.control.Label
        OperatorEditField_8             matlab.ui.control.EditField
        DirectoryEditField_9Label       matlab.ui.control.Label
        DirectoryEditField_9            matlab.ui.control.EditField
        UITable2_11                     matlab.ui.control.Table
        UITable8_9                      matlab.ui.control.Table
        SummaryTableLabel_10            matlab.ui.control.Label
        Panel_57                        matlab.ui.container.Panel
        SigmaLevelEditField_11Label     matlab.ui.control.Label
        SigmaLevelEditField_11          matlab.ui.control.NumericEditField
        ShowAnovatablesCheckBox_11      matlab.ui.control.CheckBox
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes2_11                      matlab.ui.control.UIAxes
        UIAxes4_11                      matlab.ui.control.UIAxes
        UIAxes2_12                      matlab.ui.control.UIAxes
        UIAxes4_12                      matlab.ui.control.UIAxes
        UIAxes4_13                      matlab.ui.control.UIAxes
        UpdateButton_10                 matlab.ui.control.Button
        ShowResultsforDropDown_10Label  matlab.ui.control.Label
        ShowResultsforDropDown_10       matlab.ui.control.DropDown
        Panel_58                        matlab.ui.container.Panel
        ExceldatacompleteLampLabel      matlab.ui.control.Label
        ExceldatacompleteLamp           matlab.ui.control.Lamp
        Panel_59                        matlab.ui.container.Panel
        Usecodetoflipresultsinstack0204CheckBox  matlab.ui.control.CheckBox
        RunAnalysisButton_2             matlab.ui.control.Button
        MeaurementsinImperialCheckBox   matlab.ui.control.CheckBox
        OperatorALabel                  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        OperatorBLabel                  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Panel_60                        matlab.ui.container.Panel
        Feature1DropDownLabel           matlab.ui.control.Label
        Feature1DropDown                matlab.ui.control.DropDown
        Feature2DropDownLabel           matlab.ui.control.Label
        Feature2DropDown                matlab.ui.control.DropDown
        Feature3DropDownLabel           matlab.ui.control.Label
        Feature3DropDown                matlab.ui.control.DropDown
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        ToleranceLabel                  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        BackendCanLabel                 matlab.ui.control.Label
        BackendSettingPieceLabel        matlab.ui.control.Label
        CMatlabBackendRR_CancsvLabel    matlab.ui.control.Label
        CMatlabBackendRR_SPcsvLabel     matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_2  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_3  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_4  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_5  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_6  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_7  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_8  matlab.ui.control.Label
        HeightB2B31FlangeWIdthF2F31PlugDiamLabel_9  matlab.ui.control.Label
        FrontendCanLabel                matlab.ui.control.Label
        FrontendSettingPieceLabel       matlab.ui.control.Label
        DRGCanLabel                     matlab.ui.control.Label
        DRGSettingPieceLabel            matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        FilmscanLabel                   matlab.ui.control.Label
        OpticalFilmweightLabel          matlab.ui.control.Label
        OpticalFIlmweightArdaghSPLabel  matlab.ui.control.Label
        CMatlabFrontendRR_CancsvLabel   matlab.ui.control.Label
        CMatlabFrontendRR_SPcsvLabel    matlab.ui.control.Label
        CMatlabDRGRR_CANcsvLabel        matlab.ui.control.Label
        CMatlabDRGRR_SPcsvLabel         matlab.ui.control.Label
        CMatlabFilmscanLabel            matlab.ui.control.Label
        CMatlabOpticalFilmweightRR_EXTcsvLabel  matlab.ui.control.Label
        RR_DomecsvLabel                 matlab.ui.control.Label
        RR_RimcsvLabel                  matlab.ui.control.Label
        RR_IntcsvLabel                  matlab.ui.control.Label
        RR_ExtcsvLabel                  matlab.ui.control.Label
        RR_RevcsvLabel                  matlab.ui.control.Label
        CMatlabOpticalFilmweightRR_INTcsvLabel  matlab.ui.control.Label
        CMatlabOpticalFilmweightSPRR_EXTcsvLabel  matlab.ui.control.Label
        CMatlabOpticalFilmweightSPRR_INTcsvLabel  matlab.ui.control.Label
        ES8000Label                     matlab.ui.control.Label
        EPGLabel                        matlab.ui.control.Label
        CMatlabES8000RR_ES8000csvLabel  matlab.ui.control.Label
        CMatlabEPGStack01xlsLabel       matlab.ui.control.Label
        DataD11D20D30Label              matlab.ui.control.Label
        FeaturenamesD11andD12Label      matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        GageLabel                       matlab.ui.control.Label
        FilepathLabel                   matlab.ui.control.Label
        ExcelcolumnsforeachfeatureLabel  matlab.ui.control.Label
        Ifthereismorethan10resultsincolumnDitwillassumeLabel  matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        inthecolumnLabel                matlab.ui.control.Label
        test                            matlab.ui.control.Label
        Stack02xlsLabel                 matlab.ui.control.Label
        Stack03xlsLabel                 matlab.ui.control.Label
        Stack04xlsLabel                 matlab.ui.control.Label
        ifthereis30resultsitwilltakeevery3rdresultLabel  matlab.ui.control.Label
        NorepeatslastvalueinXcolumnLabel  matlab.ui.control.Label
        NoSampleslastvalueinXcolumnLabel  matlab.ui.control.Label
        FeaturenamefullACcolumnLabel    matlab.ui.control.Label
        LacquerPlatesLabel              matlab.ui.control.Label
        ProccessesLPAGfromeitherfilmscanorfilmweighttabsLabel  matlab.ui.control.Label
        filmscantakesthe4thresultandthenevery8thLabel  matlab.ui.control.Label
        FIlmweight3rdresultandthenevery3rdLabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        DrivelettertoPullStoredataEditFieldLabel  matlab.ui.control.Label
        DrivelettertoPullStoredataEditField  matlab.ui.control.EditField
        IfblankdefaultstoCLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
    % Check if the .mat file exists
    % Get the path of the app's folder
appPath = fileparts(mfilename('fullpath'))  % Current file path of the app
fullPath = fullfile(appPath, 'appData.mat') % Create full path

% Check if the .mat file exists using full path
if exist(fullPath, 'file') == 2
        % Load the data from the .mat file
        data = load(fullPath)
       
        % Restore the values to the Operator Edit Fields
        app.OperatorEditField.Value = data.EditFieldValue1;
        app.OperatorEditField_2.Value = data.EditFieldValue2;
        app.OperatorEditField_5.Value = data.EditFieldValue3;
        app.OperatorEditField_9.Value = data.EditFieldValue4;
        app.OperatorEditField_6.Value = data.EditFieldValue5;
        app.OperatorEditField_10.Value = data.EditFieldValue6;
        app.OperatorEditField_3.Value = data.EditFieldValue7;
        app.OperatorEditField_13.Value = data.EditFieldValue8;
        app.OperatorEditField_14.Value = data.EditFieldValue9;
        app.OperatorEditField_12.Value = data.EditFieldValue10;
        app.OperatorEditField_11.Value = data.EditFieldValue11;
        app.OperatorEditField_4.Value = data.EditFieldValue12;
        app.OperatorEditField_8.Value = data.EditFieldValue13;

        % Restore the values to the Directory Edit Fields
        app.DirectoryEditField.Value = data.EditFieldValue14;
        app.DirectoryEditField_2.Value = data.EditFieldValue15;
        app.DirectoryEditField_5.Value = data.EditFieldValue16;
        app.DirectoryEditField_10.Value = data.EditFieldValue18;
        app.DirectoryEditField_6.Value = data.EditFieldValue19;
        app.DirectoryEditField_11.Value = data.EditFieldValue20;
        app.DirectoryEditField_3.Value = data.EditFieldValue21;
        app.DirectoryEditField_14.Value = data.EditFieldValue22;
        app.DirectoryEditField_15.Value = data.EditFieldValue23;
        app.DirectoryEditField_13.Value = data.EditFieldValue24;
        app.DirectoryEditField_12.Value = data.EditFieldValue25;
        app.DirectoryEditField_4.Value = data.EditFieldValue26;
        app.DirectoryEditField_9.Value = data.EditFieldValue27;
         app.DrivelettertoPullStoredataEditField.Value = data.EditFieldValue28;
        
    end


        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
             
            
% Get user inputs
            
            gname = app.GageNameSNEditField.Value;
          
            operator = app.OperatorEditField.Value;
            misc = app.CompanyEditField.Value;
            sigma_level = app.SigmaLevelEditField.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox.Value;
            show_reform_internal_diameter_results = app.ShowReformResultsCheckBox.Value;
            ch_tolerancepercentage = app.HeightToleranceEditField.Value;
            cfw_tolerancepercentage = app.FlangeWidthToleranceEditField.Value;
            cpd_tolerancepercentage = app.PlugDiameterToleranceEditField.Value;
            cid_tolerancepercentage =   app.MaxReformDiameterToleranceEditField.Value;
            cdd_tolerancepercentage = app.DomeDepthToleranceEditField.Value;
            
            
            % Clear previous plots
        cla(app.UIAxes, 'reset');
        cla(app.UIAxes2, 'reset');
        cla(app.UIAxes3, 'reset');
        cla(app.UIAxes4, 'reset');
    
          % Clear previous table data
        app.UITable2.Data = {};
    
          %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Backend');
    
    %create folder 
    
% Get the base directory from the edit field
baseDirectory = app.DirectoryEditField.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Backend');
end

% Create the folder name using the variables
folderName = sprintf('Backend-Can-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc;sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});


intro_f = figure('Name', 'Gage R&R Study - Backend Finished Can', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Backend Can');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames,'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1],'ColumnWidth', {150, 348});

filename = sprintf('1-Can-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


% Combine the folder path with the filename
filePath2 = fullfile(folderPath2, 'RR_CAN.csv');


%check if reform is imcluded then loop calculations for each feature
if show_reform_internal_diameter_results ==1 
    x = 5;
else
    x=4;
end

for i = 1:x


      if i == 1
    try
        measurements = readmatrix(filePath2, 'Range', 'B2:B31');  %will try to read values, if not availiable will assign 30 Nan Values
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Height'; %sets feature details for each loop
    pdfno = 2;
    tolerance_percentage = ch_tolerancepercentage;
    
elseif i == 2
    try
        measurements = readmatrix(filePath2, 'Range', 'F2:F31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Flange Width';
    pdfno = 3;
    tolerance_percentage = cfw_tolerancepercentage;
    
elseif i == 3
    try
        measurements = readmatrix(filePath2, 'Range', 'J2:J31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Plug Diameter';
    pdfno = 4;
    tolerance_percentage = cpd_tolerancepercentage;
    
elseif i == 4
    try
        measurements = readmatrix(filePath2, 'Range', 'R2:R31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Dome Depth';
    pdfno = 5;
    tolerance_percentage = cdd_tolerancepercentage;
    
elseif i == 5
    try
        measurements = readmatrix(filePath2, 'Range', 'N2:N31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Max Reform Diameter';
    pdfno = 6;
    tolerance_percentage = cid_tolerancepercentage;
      end

part1column = measurements(1:6); %seperates the data into each parts repeats
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6
if part2partvarcomp < 0 %part to part variance can become negative if repeatability variation>> part2part variation, so can be ignored by setting to 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp

% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance (sigma * sd)
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('Finished Can %s - ANOVA Tables', feature))

    filename = sprintf('%d-Can%sANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('BackendMk2- R&R - Finished Can %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');
grid(ax2, 'on'); % Add grid to the Sample Range Graph

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);

formattedLabel = sprintf('Can %s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.');
ytickformat(ax3, '%.3f');
grid(ax3, 'on'); % Add grid to the Scatter Graph

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.');
ytickformat(ax4, '%.4f');
grid(ax4, 'on'); % Add grid to the Mean Chart


% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
       'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-Can %s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');

%for each loop extract the results by setting equal to different variables
%to use in summary tables
 if i == 1 
    ch_tolerance_repeatability = tolerance_repeatability;
    ch_tolerance = tolerance
    ch_sv_repeatability = sv_repeatability;
elseif i == 2
    cfw_tolerance_repeatability = tolerance_repeatability
     cfw_tolerance = tolerance
    cfw_sv_repeatability = sv_repeatability
elseif i == 3
    cpd_tolerance_repeatability = tolerance_repeatability
     cpd_tolerance = tolerance
    cpd_sv_repeatability = sv_repeatability
elseif i == 4
    cdd_tolerance_repeatability = tolerance_repeatability
     cdd_tolerance = tolerance
    cdd_sv_repeatability = sv_repeatability
elseif i == 5
    cid_tolerance_repeatability = tolerance_repeatability
     cid_tolerance = tolerance
    cid_sv_repeatability = sv_repeatability
 end 
end %end of feature loop

%pass/fail checks

% Check ch_tolerance_repeatability and assign result

if ch_tolerance_repeatability < 10
    ch_result = {'Pass (<10%)'};
elseif ch_tolerance_repeatability >= 30
    ch_result = {'Fail (>30%)'};
else
    ch_result = {'Pass (10%-30%)'};
end

% Check cfw_tolerance_repeatability and assign result
if cfw_tolerance_repeatability < 10
    cfw_result = {'Pass (<10%)'};
elseif cfw_tolerance_repeatability >= 30
    cfw_result = {'Fail (>30%)'};
else
    cfw_result = {'Pass (10%-30%)'};
end

% Check cpd_tolerance_repeatability and assign result
if cpd_tolerance_repeatability < 10
    cpd_result = {'Pass (<10%)'};
elseif cpd_tolerance_repeatability >= 30
    cpd_result = {'Fail (>30%)'};
else
    cpd_result = {'Pass (10%-30%)'};
end

% check is reform results are availiable
if show_reform_internal_diameter_results ==1
% Check cid_tolerance_repeatability and assign result
if cid_tolerance_repeatability < 10
    cid_result = {'Pass (<10%)'};
elseif cid_tolerance_repeatability >= 30
    cid_result = {'Fail (>30%)'};
else
    cid_result = {'Pass (10%-30%)'};
end
end

% Check cdd_tolerance_repeatability and assign result
if cdd_tolerance_repeatability < 10
    cdd_result = {'Pass (<10%)'};
elseif cdd_tolerance_repeatability >= 30
    cdd_result = {'Fail (>30%)'};
else
    cdd_result = {'Pass (10%-30%)'};
end

 %check if missing data
 if isnan(ch_tolerance_repeatability)
    ch_result = {'Missing data'};
 end
 
if isnan(cfw_tolerance_repeatability)
    cfw_result = {'Missing data'};
end

if isnan(cpd_tolerance_repeatability)
    cpd_result = {'Missing data'};
end

if isnan(cdd_tolerance_repeatability)
    cdd_result = {'Missing data'};
end

if show_reform_internal_diameter_results
if isnan(cid_tolerance_repeatability)
    cid_result = {'Missing data'};
end
end
%summary table - adjusted to whether reform results are included

if show_reform_internal_diameter_results ==1
    
height_data = [ch_tolerancepercentage; ch_sv_repeatability; ch_tolerance_repeatability; ch_result];
flange_width_data = [cfw_tolerancepercentage; cfw_sv_repeatability; cfw_tolerance_repeatability; cfw_result];
plug_diameter_data = [cpd_tolerancepercentage; cpd_sv_repeatability; cpd_tolerance_repeatability; cpd_result];
reform_diameter_data = [cid_tolerancepercentage; cid_sv_repeatability; cid_tolerance_repeatability; cid_result];
dome_depth_data = [cdd_tolerancepercentage; cdd_sv_repeatability; cdd_tolerance_repeatability; cdd_result];

% Create the table
summary_table = table(height_data, flange_width_data, plug_diameter_data, dome_depth_data,  reform_diameter_data, ...
                      'VariableNames', {'Height', 'Flange W', 'Plug D', 'Dome D', 'Reform D'}, ...
                      'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
              app.UITable8.Data = summary_table;
              
else 
    
height_data = [ch_tolerancepercentage; ch_sv_repeatability; ch_tolerance_repeatability; ch_result];
flange_width_data = [cfw_tolerancepercentage; cfw_sv_repeatability; cfw_tolerance_repeatability; cfw_result];
plug_diameter_data = [cpd_tolerancepercentage; cpd_sv_repeatability; cpd_tolerance_repeatability; cpd_result];
dome_depth_data = [cdd_tolerancepercentage; cdd_sv_repeatability; cdd_tolerance_repeatability; cdd_result];

% Create the table

summary_table = table(height_data, flange_width_data, plug_diameter_data, dome_depth_data, ...
                      'VariableNames', {'Height', 'Flange W', 'Plug D', 'Dome D'}, ...
                      'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
              app.UITable8.Data = summary_table;
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data - summary bar chart
    if show_reform_internal_diameter_results == 1
   
    summary_barx = categorical(["Height", "Flange Width", 'Plug Diameter', 'Max Internal Diameter', 'Dome Depth']);
    summary_bary = [ch_tolerance_repeatability, cfw_tolerance_repeatability, cpd_tolerance_repeatability, cid_tolerance_repeatability, cdd_tolerance_repeatability];
    else
        summary_barx = categorical(["Height", "Flange Width", 'Plug Diameter',  'Dome Depth']);
    summary_bary = [ch_tolerance_repeatability,  cfw_tolerance_repeatability, cpd_tolerance_repeatability, cdd_tolerance_repeatability];
    
    end
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);

    
      if show_reform_internal_diameter_results == 1
    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {107,107,107,107,107})
      else
          % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {134,134,134,134});
      end
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('7-Can-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%%PDF COMBINE - uses a package, if stops working there is a seperate app
%%%with no PDF combine, or update package and recompile app

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Backend Can R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);


close all; %closes all figure windows that have been created


        end
    end

    % App initialization and construction
    methods (Access = public)

        % Construct app
        function app = CanHeightAnalysisApp

            % Create UIFigure and hide until all components are created
            app.Gage_RR_App = uifigure('Visible', 'off');
            app.Gage_RR_App.Position = [100 100 640 480];
            app.Gage_RR_App.Name = 'MATLAB App';

            % Create GageNameEditFieldLabel
            app.GageNameSNEditFieldLabel = uilabel(app.Gage_RR_App);
            app.GageNameSNEditFieldLabel.HorizontalAlignment = 'right';
            app.GageNameSNEditFieldLabel.Position = [14 425 100 22];
            app.GageNameSNEditFieldLabel.Text = 'Gage Name';

            % Create GageNameEditField
            app.GageNameSNEditField = uieditfield(app.Gage_RR_App, 'text');
            app.GageNameSNEditField.Position = [129 425 100 22];

            % Create DateEditFieldLabel
            app.ddEditFieldLabel = uilabel(app.Gage_RR_App);
            app.ddEditFieldLabel.HorizontalAlignment = 'right';
            app.ddEditFieldLabel.Position = [14 393 100 22];
            app.ddEditFieldLabel.Text = 'Date';

            % Create DateEditField
            app.ddEditField = uieditfield(app.Gage_RR_App, 'text');
            app.ddEditField.Position = [129 393 100 22];

            % Create OperatorEditFieldLabel
            app.OperatorEditFieldLabel = uilabel(app.Gage_RR_App);
            app.OperatorEditFieldLabel.HorizontalAlignment = 'right';
            app.OperatorEditFieldLabel.Position = [14 361 100 22];
            app.OperatorEditFieldLabel.Text = 'Operator';

            % Create OperatorEditField
            app.OperatorEditField = uieditfield(app.Gage_RR_App, 'text');
            app.OperatorEditField.Position = [129 361 100 22];

            % Create MiscEditFieldLabel
            app.CompanyEditFieldLabel = uilabel(app.Gage_RR_App);
            app.CompanyEditFieldLabel.HorizontalAlignment = 'right';
            app.CompanyEditFieldLabel.Position = [14 329 100 22];
            app.CompanyEditFieldLabel.Text = 'Misc';

            % Create MiscEditField
            app.CompanyEditField = uieditfield(app.Gage_RR_App, 'text');
            app.CompanyEditField.Position = [129 329 100 22];

            % Create SigmaLevelEditFieldLabel
            app.SigmaLevelEditFieldLabel = uilabel(app.Gage_RR_App);
            app.SigmaLevelEditFieldLabel.HorizontalAlignment = 'right';
            app.SigmaLevelEditFieldLabel.Position = [14 297 100 22];


        end

        % Value changed function: ShowResultsforDropDown
        function ShowResultsforDropDownValueChanged(app, event)
            graph = app.ShowResultsforDropDown.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
% Get user inputs
            gname = app.GageNameSNEditField.Value;
            %date = app.ddEditField.Value;
            operator = app.OperatorEditField.Value;
            misc = app.CompanyEditField.Value;
            sigma_level = app.SigmaLevelEditField.Value;
            show_reform_internal_diameter_results = app.ShowReformResultsCheckBox.Value;
            ch_tolerancepercentage = app.HeightToleranceEditField.Value;
            cfw_tolerancepercentage = app.FlangeWidthToleranceEditField.Value;
            cpd_tolerancepercentage = app.PlugDiameterToleranceEditField.Value;
            cid_tolerancepercentage =   app.MaxReformDiameterToleranceEditField.Value;
            cdd_tolerancepercentage = app.DomeDepthToleranceEditField.Value;
            graph = app.ShowResultsforDropDown.Value; 
     
% Clear previous plots
cla(app.UIAxes, 'reset');
cla(app.UIAxes2, 'reset');
cla(app.UIAxes3, 'reset');
cla(app.UIAxes4, 'reset');
    
% Clear previous table data
app.UITable2.Data = {};
    
%%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end

% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Backend');

% Combine the folder path with the filename
filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    
        if strcmp(graph, 'Can Height') % checks which drop down is selected and pulls the corresponding values and tolerance
    measurements = readmatrix(filePath2, 'range', 'B2:B31');
     c_tolerance_percentage = ch_tolerancepercentage;
elseif strcmp(graph, 'Can Flange Width')
    measurements = readmatrix(filePath2, 'range', 'F2:F31');
     c_tolerance_percentage = cfw_tolerancepercentage;
elseif strcmp(graph, 'Can Plug Diameter')
    measurements = readmatrix(filePath2, 'range', 'J2:J31');
     c_tolerance_percentage = cpd_tolerancepercentage;
elseif show_reform_internal_diameter_results == 1 && strcmp(graph, 'Can Reform Diameter')
    measurements = readmatrix(filePath2, 'range', 'N2:N31');
     c_tolerance_percentage = cid_tolerancepercentage;
elseif strcmp(graph, 'Can Dome Depth')
    measurements = readmatrix(filePath2, 'range', 'R2:R31');
     c_tolerance_percentage = cdd_tolerancepercentage;
        end
        
c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;
c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;
c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;

c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp

c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;

% Create table1
c_table1 = table({gname}, {date}, {operator}, {c_tolerance_percentage}, {misc}, ...
    'VariableNames', {'Gage Name', 'Date of Study', 'Reported by', 'Tolerance', 'Misc'});

% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes, 'Percentage Tolerance');
bar(app.UIAxes, c_barx, c_bary);
title(app.UIAxes, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

% ylabel(app.UIAxes2, 'Part');
ylabel(app.UIAxes2, 'Sample Range');
title(app.UIAxes2, 'R Chart');
ytickformat(app.UIAxes2, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3, 'on');
plot(app.UIAxes3, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3, 'Feature');
title(app.UIAxes3, 'Feature vs Part');
xlabel(app.UIAxes3, 'Part No.');

ytickformat(app.UIAxes3, '%.3f');

% Mean chart
plot(app.UIAxes4, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4, 'on');
plot(app.UIAxes4, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

% xlabel(app.UIAxes4, 'Part');
ylabel(app.UIAxes4, 'Sample Mean');
title(app.UIAxes4, 'XBar Chart');
xlabel(app.UIAxes4, 'Part No.');
ytickformat(app.UIAxes4, '%.4f');


        end

        % Button pushed function: RunanalysisButton_2
        function RunanalysisButton_2Pushed(app, event)
% Get user inputs
            gname = app.GageNameSNEditField_2.Value;
            
            operator = app.OperatorEditField_2.Value;
            misc = app.CompanyEditField_2.Value;
            sigma_level = app.SigmaLevelEditField_2.Value;
            show_reform_internal_diameter_results = app.ShowReformResultsCheckBox_2.Value;
            sph_tolerancepercentage = app.HeightToleranceEditField_2.Value;
            sprd_tolerancepercentage = app.ReformDiameterToleranceEditField.Value;
            spdd_tolerancepercentage = app.DomeDepthToleranceEditField_3.Value;
            spd1_tolerancepercentage =   app.Diameter1ToleranceEditField.Value;
            spd2_tolerancepercentage = app.Diameter2ToleranceEditField.Value;
            graph = app.ShowResultsforDropDown_2.Value 
            show_ANOVA_table = app.ShowAnovatablesCheckBox_2.Value;
            
 % Clear previous plots
        cla(app.UIAxes_2, 'reset');
        cla(app.UIAxes2_2, 'reset');
        cla(app.UIAxes3_2, 'reset');
        cla(app.UIAxes4_2, 'reset');
    
% Clear previous table data
        app.UITable2_2.Data = {};
    
%create date
        %date = sprintf('%s-%s-%s', yy, mm, dd);
        
 %create folder to save pdfs to 
    
baseDirectory = app.DirectoryEditField_2.Value;  %location to save to from user input

%%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Backend');

% Check if the inputted base directory is empty and set it to the current directory if it is

        if isempty(baseDirectory)
          baseDirectory = fullfile([drive ':'],'Matlab','Backend');
            end

% Create the folder name using the variables
folderName = sprintf('Backend-SP-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end
    
%% Create Intro Table and figure

sph_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc;sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});


intro_f = figure('Name', 'Gage R&R Study - Backend Setting Piece', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Backend Setting Piece');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);
uitable('Parent', intro_panel, 'Data', sph_table1{:,:}, 'ColumnName', sph_table1.Properties.VariableNames,'RowName', sph_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1],'ColumnWidth', {150, 348});

%create file name and save figure
filename = sprintf('1-SettingPiece-Intro,%s,%s,%s,pdf', gname, date, operator);   
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


% Combine the folder path with the filename
filePath2 = fullfile(folderPath2, 'RR_SP.csv');

            if show_reform_internal_diameter_results ==1 
    x = 5
else
    x=4
end

for i = 1:x


    if i == 1
    try
        measurements = readmatrix(filePath2, 'Range', 'B2:B31');
         % Check if measurements is empty
        if isempty(measurements)
            measurements = NaN(30, 1); % Assign NaN if data is empty
        end
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Height';
    pdfno = 2;
    tolerance_percentage = sph_tolerancepercentage;
    
elseif i == 2
    try
        measurements = readmatrix(filePath2, 'Range', 'R2:R31');
         % Check if measurements is empty
        if isempty(measurements)
            measurements = NaN(30, 1); % Assign NaN if data is empty
        end
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Dome Depth';
    pdfno = 3;
    tolerance_percentage = spdd_tolerancepercentage;
    
elseif i == 3
    try
        measurements = readmatrix(filePath2, 'Range', 'S2:S31');
         % Check if measurements is empty
        if isempty(measurements)
            measurements = NaN(30, 1); % Assign NaN if data is empty
        end
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Diameter 1';
    pdfno = 4;
    tolerance_percentage = spd1_tolerancepercentage;
    
elseif i == 4
    try
        measurements = readmatrix(filePath2, 'Range', 'T2:T31');
         % Check if measurements is empty
        if isempty(measurements)
            measurements = NaN(30, 1); % Assign NaN if data is empty
        end
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Diameter 2';
    pdfno = 5;
    tolerance_percentage = spd2_tolerancepercentage;
    
elseif i == 5
    try
        measurements = readmatrix(filePath2, 'Range', 'P2:P31');
         if isempty(measurements)
            measurements = NaN(30, 1); % Assign NaN if data is empty
        end
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Reform Diameter';
    pdfno = 6;
    tolerance_percentage = sprd_tolerancepercentage;
    end
measurements
part1column = measurements(1:6);
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6
if part2partvarcomp < 0
    part2partvarcomp = 0
end

totalvar = repeatabilityvarcomp + part2partvarcomp

% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('%s - ANOVA Tables', feature))

    filename = sprintf('%d-%s-ANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Backend Mk2 R&R - %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf('%s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-%s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');


 if i == 1
    sph_tolerance_repeatability = tolerance_repeatability;
    sph_tolerance = tolerance;
    sph_sv_repeatability = sv_repeatability;
elseif i == 2
    spdd_tolerance_repeatability = tolerance_repeatability;
     spdd_tolerance = tolerance;
    spdd_sv_repeatability = sv_repeatability;
elseif i == 3
    spd1_tolerance_repeatability = tolerance_repeatability;
     spd1_tolerance = tolerance;
    spd1_sv_repeatability = sv_repeatability;
elseif i == 4
    spd2_tolerance_repeatability = tolerance_repeatability;
     spd2_tolerance = tolerance;
    spd2_sv_repeatability = sv_repeatability;
elseif i == 5
    sprd_tolerance_repeatability = tolerance_repeatability;
     sprd_tolerance = tolerance;
    sprd_sv_repeatability = sv_repeatability;
 end 
end


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%pass/fail checks

%pass/fail checks

% Check sph_tolerance_repeatability and assign result
if sph_tolerance_repeatability < 20
    sph_result = {'Pass (<20%)'};
elseif sph_tolerance_repeatability >= 30
    sph_result = {'Fail (>30%)'};
else
    sph_result = {'Pass (20%-30%)'};
end

% Check spd1_tolerance_repeatability and assign result
if spd1_tolerance_repeatability < 20
    spd1_result = {'Pass (<20%)'};
elseif spd1_tolerance_repeatability >= 30
    spd1_result = {'Fail (>30%)'};
else
    spd1_result = {'Pass (20%-30%)'};
end

% Check spd2_tolerance_repeatability and assign result

if spd2_tolerance_repeatability < 20
    spd2_result = {'Pass (<20%)'};
elseif spd2_tolerance_repeatability >= 30
    spd2_result = {'Fail (>30%)'};
else
    spd2_result = {'Pass (20%-30%)'};
end

if show_reform_internal_diameter_results ==1
% Check sprd_tolerance_repeatability and assign result
if sprd_tolerance_repeatability < 20
    sprd_result = {'Pass (<20%)'};
elseif sprd_tolerance_repeatability >= 30
    sprd_result = {'Fail (>30%)'};
else
    sprd_result = {'Pass (20%-30%)'};
end
end

% Check spdd_tolerance_repeatability and assign result
if spdd_tolerance_repeatability < 20
    spdd_result = {'Pass (<20%)'};
elseif spdd_tolerance_repeatability >= 30
    spdd_result = {'Fail (>30%)'};
else
    spdd_result = {'Pass (20%-30%)'};
end

%check if missing data

 if isnan(sph_tolerance_repeatability)
    sph_result = {'Missing data'};
 end
 
 if show_reform_internal_diameter_results ==1
if isnan(sprd_tolerance_repeatability)
    sprd_result = {'Missing data'};
end
 end
 
if isnan(spdd_tolerance_repeatability)
    spdd_result = {'Missing data'};
end

if isnan(spd1_tolerance_repeatability)
    spd1_result = {'Missing data'};
end

if isnan(spd2_tolerance_repeatability)
    spd2_result = {'Missing data'};
end

%summary table

if show_reform_internal_diameter_results == 1
    

summary_table = table([sph_tolerancepercentage; sph_sv_repeatability; sph_tolerance_repeatability;sph_result], ...
                   [spd1_tolerancepercentage; spd1_sv_repeatability; spd1_tolerance_repeatability;spd1_result], ...
                   [spd2_tolerancepercentage; spd2_sv_repeatability; spd2_tolerance_repeatability;spd2_result], ...
                    [spdd_tolerance; spdd_sv_repeatability; spdd_tolerance_repeatability;spdd_result], [sprd_tolerancepercentage; sprd_sv_repeatability; sprd_tolerance_repeatability;sprd_result]...
                   ,'VariableNames', {'H', 'D1', 'D2', 'DD', 'RD'}, ...
                   'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
              
    
else
    summary_table = table([sph_tolerancepercentage; sph_sv_repeatability; sph_tolerance_repeatability;sph_result], ...
                   [spd1_tolerancepercentage; spd1_sv_repeatability; spd1_tolerance_repeatability;spd1_result], ...
                   [spd2_tolerancepercentage; spd2_sv_repeatability; spd2_tolerance_repeatability;spd2_result], ...
                    [spdd_tolerancepercentage; spdd_sv_repeatability; spdd_tolerance_repeatability;spdd_result], ...
                   'VariableNames', {'H', 'D1', 'D2',  'DD'}, ...
                   'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
                              
end
             
app.UITable8_2.Data = summary_table;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


if show_reform_internal_diameter_results == 1
    
% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data
    summary_barx = categorical(["Height", "Reform Diameter", 'Dome Depth', 'Diameter 1', 'Diameter 2']);
    summary_bary = [sph_tolerance_repeatability, sprd_tolerance_repeatability, spdd_tolerance_repeatability, spd1_tolerance_repeatability, spd2_tolerance_repeatability];
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    sgtitle('Results Summary')
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);

    % Create the table data
    height_data = [sph_tolerance; sph_sv_repeatability; sph_tolerance_repeatability; sph_result];
    rd_data = [sprd_tolerance; sprd_sv_repeatability; sprd_tolerance_repeatability; sprd_result];
    dd_data = [spdd_tolerance; spdd_sv_repeatability; spdd_tolerance_repeatability; spdd_result];
    d1_data = [spd1_tolerance; spd1_sv_repeatability; spd1_tolerance_repeatability; spd1_result];
    d2_data = [spd2_tolerance; spd2_sv_repeatability; spd2_tolerance_repeatability; spd2_result];
    
    % Create the table
    summary_table = table(height_data, rd_data, dd_data, d1_data, d2_data, ...
                          'VariableNames', {'Height', 'Reform D', 'Dome D', 'Diameter 1', 'Diameter 2'}, ...
                          'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
    
    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {107,107,107,107,107});


else
    
    % Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data
    summary_barx = categorical(["Height", 'Dome Depth', 'Diameter 1', 'Diameter 2']);
    summary_bary = [sph_tolerance_repeatability, spdd_tolerance_repeatability, spd1_tolerance_repeatability, spd2_tolerance_repeatability];
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
 ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    sgtitle('Results Summary')
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);

    % Create the table data
    height_data = [sph_tolerance; sph_sv_repeatability; sph_tolerance_repeatability; sph_result];
    dd_data = [spdd_tolerance; spdd_sv_repeatability; spdd_tolerance_repeatability; spdd_result];
    d1_data = [spd1_tolerance; spd1_sv_repeatability; spd1_tolerance_repeatability; spd1_result];
    d2_data = [spd2_tolerance; spd2_sv_repeatability; spd2_tolerance_repeatability; spd2_result];
    
    % Create the table
    summary_table = table(height_data, dd_data, d1_data, d2_data, ...
                          'VariableNames', {'Height', 'DomeDepth', 'Diameter1', 'Diameter2'}, ...
                          'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
    
    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {133,133,133,133});


end

summary_f.Position = [200, 200, 800, 600]; 
% Print the figure to a PDF file
filename = sprintf('7-SettingPiece-Summary,%s,%s,%s.pdf', gname, date, operator);   
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Backend SP R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);


% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all;

        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            % Get user inputs
            gname = app.GageNameSNEditField_2.Value;
            %date = app.ddEditField_2.Value;
            operator = app.OperatorEditField_2.Value;
            misc = app.CompanyEditField_2.Value;
            sigma_level = app.SigmaLevelEditField_2.Value;
            show_reform_internal_diameter_results = app.ShowReformResultsCheckBox_2.Value;
            sph_tolerancepercentage = app.HeightToleranceEditField_2.Value;
            sprd_tolerancepercentage = app.ReformDiameterToleranceEditField.Value;
            spdd_tolerancepercentage = app.DomeDepthToleranceEditField_3.Value;
            spd1_tolerancepercentage =   app.Diameter1ToleranceEditField.Value;
            spd2_tolerancepercentage = app.Diameter2ToleranceEditField.Value;
            graph = app.ShowResultsforDropDown_2.Value; 
            show_ANOVA_table = app.ShowAnovatablesCheckBox_2.Value
            
            % Clear previous plots
    cla(app.UIAxes_2, 'reset');
     cla(app.UIAxes2_2, 'reset');
      cla(app.UIAxes3_2, 'reset');
       cla(app.UIAxes4_2, 'reset');
    
       
             %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Backend');

% Combine the folder path with the filename
filePath2 = fullfile(folderPath2, 'RR_SP.csv');

    % Clear previous table data
    app.UITable2_2.Data = {};
    
            if strcmp(graph,'Setting Piece Height')
    measurements = readmatrix(filePath2, 'range', 'B2:B31');
     c_tolerance_percentage = sph_tolerancepercentage;
            elseif strcmp(graph,'Setting Piece Dome Depth')
    measurements = readmatrix(filePath2, 'range', 'R2:R31');
     c_tolerance_percentage = spdd_tolerancepercentage;
            elseif strcmp(graph,'Setting Piece Diameter 1')
    measurements = readmatrix(filePath2, 'range', 'S2:S31');
     c_tolerance_percentage = spd1_tolerancepercentage;
            elseif show_reform_internal_diameter_results == 1 && strcmp(graph,'Setting Piece Reform Diameter')
    measurements = readmatrix(filePath2, 'range', 'P2:P31');
     c_tolerance_percentage = sprd_tolerancepercentage;
            elseif strcmp(graph,'Setting Piece Diameter 2')
    measurements = readmatrix(filePath2, 'range', 'T2:T31');
     c_tolerance_percentage = spd2_tolerancepercentage
            end
            
c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;
c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;
c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;
c_pvalue = 0; % Placeholder for actual p-value calculation

c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp
c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;

% Create table1
c_table1 = table({gname}, {date}, {operator}, {c_tolerance_percentage}, {misc}, ...
    'VariableNames', {'Gage Name', 'Date of Study', 'Reported by', 'Tolerance', 'Misc'});

% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_2.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_2, 'Percentage Tolerance');
bar(app.UIAxes_2, c_barx, c_bary);
title(app.UIAxes_2, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_2, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_2, 'on');
plot(app.UIAxes2_2, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_2, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_2, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_2, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_2, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');


ylabel(app.UIAxes2_2, 'Sample Range');
title(app.UIAxes2_2, 'R Chart');
ytickformat(app.UIAxes2_2, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_2, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_2, 'on');
plot(app.UIAxes3_2, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_2, 'Feature');
title(app.UIAxes3_2, 'Feature vs Part');
xlabel(app.UIAxes3_2, 'Part No.');

ytickformat(app.UIAxes3, '%.3f');

% Mean chart
plot(app.UIAxes4_2, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_2, 'on');
plot(app.UIAxes4_2, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_2, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_2, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_2, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_2, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');


ylabel(app.UIAxes4_2, 'Sample Mean');
title(app.UIAxes4_2, 'XBar Chart');
xlabel(app.UIAxes4_2, 'Part No.');
ytickformat(app.UIAxes4_2, '%.4f');
        end

        % Button pushed function: RunanalysisButton_3
        function RunanalysisButton_3Pushed(app, event)
             % Get user inputs
            
            gname = app.GageNameSNEditField_3.Value;
           
            operator = app.OperatorEditField_3.Value;
            misc = app.CompanyEditField_3.Value;
            sasigma_level = app.SliceAverageSigmaLevelEditField.Value;
            spsigma_level = app.SinglePointSigmaLevelEditField.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_3.Value;
            cd_tolerancepercentage = app.DomeToleranceEditField.Value;
            ce_tolerancepercentage = app.ExternalToleranceEditField.Value;
            ci_tolerancepercentage = app.InternalWallToleranceEditField.Value;
            cr_tolerancepercentage =   app.ReverseWallToleranceEditField.Value;
            crm_tolerancepercentage = app.RimCoatToleranceEditField.Value;
            graph = app.ShowResultsforDropDown_3.Value; 
            crsp_tolerancepercentage = cr_tolerancepercentage
             cisp_tolerancepercentage = ci_tolerancepercentage
              cesp_tolerancepercentage = ce_tolerancepercentage
               cdsp_tolerancepercentage = cd_tolerancepercentage
            linearity = app.IncludeLinearityCertificateinreportCheckBox.Value
            % Clear previous plots
        cla(app.UIAxes_3, 'reset');
        cla(app.UIAxes2_3, 'reset');
        cla(app.UIAxes3_3, 'reset');
        cla(app.UIAxes4_3, 'reset');
    
          % Clear previous table data
        app.UITable2_3.Data = {};
    
           %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'Filmscan');
    
    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_3.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Filmscan');
end

% Create the folder name using the variables
folderName = sprintf('Filmscan-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

LP = app.IncludeLPanalyisinfilmscanreportCheckBox.Value
try
if LP == 1


            % Folder where CSV files are located


% File prefixes (A to G)
filePrefixes = 'A':'G';

% Initialize an empty table to store the results with the correct column types
resultsCellArray = cell(0, 7);

% Loop through each file (LPA.csv to LPG.csv)
for i = 1:length(filePrefixes)
    % Construct the full file path for the current CSV file
    fileName = ['LP', filePrefixes(i), '.csv'];
    filePath = fullfile(folderPath, fileName);
    
    % Try to read the file, if it doesn't exist, skip it
    try
        % Read the CSV file without variable names
        if exist(filePath, 'file') == 2
            data = readtable(filePath, 'ReadVariableNames', false, 'Format', '%s');
        else
        
            continue; % Skip the file if not found
        end
        
        % Extract data from the single column
        dataArray = data.Var1;
        
        % Debug: Check if dataArray contains any data
        if isempty(dataArray)
            
            continue;
        end
        
        % Extract every 3rd value
        lpmeasurements = dataArray(4:8:end);
        
        % Convert cell array of strings to numeric values
        numericValues = cellfun(@str2double, lpmeasurements);
        
        % Remove NaN values (which were non-numeric in the original data)
        numericValues = numericValues(~isnan(numericValues));
        
        % Debug: Check if numericValues has any valid values
        if isempty(numericValues)
            
            continue;
        end
        
        % Calculate required statistics
        meanValue = mean(numericValues);
        stdevValue = std(numericValues);
        minValue = min(numericValues);
        medianValue = median(numericValues);
        maxValue = max(numericValues);
        rangeValue = max(numericValues) - min(numericValues);


          % Create a new row in cell format
        newRow = {char(fileName), num2str(meanValue), num2str(stdevValue), num2str(minValue), ...
                   num2str(medianValue), num2str(maxValue), num2str(rangeValue)};
        % Append the new row to the resultsTable
        resultsCellArray = [resultsCellArray; newRow];
        
    catch ME
        % If file is not found or there's an error, skip to the next one
       
        continue;
    end
end


%% Create Intro Table 
intro_table = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company'}, ...
                   {gname; date; operator; misc}, ...
                   'VariableNames', {'Description', 'Value'});
               
% Create figure and panels
        intro_f = figure('Name', 'Lacquer Plate Analysis', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
        
% Add a title to the figure
sgtitle('Filmscan - Lacquer Plate Analysis');
      
        % Create panels for plotting and table
        intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.6, 0.9, 0.25]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.2, 0.9, 0.35]);

        % Create the table
       uitable('Parent', intro_panel, 'Data', intro_table{:,:}, 'ColumnName', intro_table.Properties.VariableNames, 'RowName', intro_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});


       % Convert all numeric values in resultsCellArray to strings
resultsCellArray(:, 2:end) = cellfun(@num2str, resultsCellArray(:, 2:end), 'UniformOutput', false);

% Create the uitable with cell data

    uitable('Parent', table_panel, 'Data', resultsCellArray, ...
        'ColumnName', {'Filename', 'Mean', 'StdDev', 'Min', 'Median', 'Max', 'Range'}, ...
        'RowName', [], ... % Use default row names
        'Units', 'Normalized', ...
        'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 75, 75, 75, 75, 75, 75}); 
    
 % Create the filename with the desired format
        filename = sprintf('2-Filmscan-Lacquer Plate Analysis,%s,%s,%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all

end
catch
end
%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Slice Average Sigma';'Single Point Sigma'}, ...
                   {gname; date; operator; misc; sasigma_level;spsigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - Filmscan', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Filmscan');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-Filmscan-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');

         %%%%%% specify folder to pull values from 

try
    % Check if linearity should be processed
    if linearity == 1
         referenceValues  = [app.Measure1EditField.Value,app.Measure2EditField.Value,app.Measure3EditField.Value]    
        % Attempt to read the NIST CSV file
        filePaths = {
    'Matlab\Filmscan\Nist1.csv', 
    'Matlab\Filmscan\Nist2.csv', 
    'Matlab\Filmscan\Nist3.csv'};
% Construct full paths
filePaths = cellfun(@(folder) fullfile([drive ':'], folder), filePaths, 'UniformOutput', false);

% Initialize an empty cell array to hold the combined data
combinedData = {};
validFileCount = 0
usedReferences = [];

       % Loop through each file path and read the data
for i = 1:length(filePaths)
    if isfile(filePaths{i});
    data = readtable(filePaths{i}, 'ReadVariableNames', false, 'Format', '%s');
    validFileCount = validFileCount + 1;
    % Extract data from the single column and append to combinedData
    combinedData = [combinedData; data.Var1]; % Append column-wise
     usedReferences = [usedReferences, referenceValues(i)]
    end
end
        dataArray = combinedData

            % Define parameters
        numGroups = validFileCount;
        numMeasurements = 8;
        
        % Convert usedReferences to a column vector
reference = usedReferences(:)


        linearitytolerancepositive = app.ToleranceumEditField.Value;
        linearitytolerancenegative = app.ToleranceumEditField_2.Value

        % Initialize variables
        groupData = cell(numGroups, numMeasurements);

        % Parsing data
        currentIndex = 1;
      for group = 1:numGroups
    if currentIndex <= length(dataArray)
        for measurement = 1:8  % 8 measurements expected
            % Ensure there's enough data for the current group and measurement
            if currentIndex + 5 <= length(dataArray)
                groupData{group, measurement} = str2double(dataArray(currentIndex + 3 : currentIndex + 5));
            else
                groupData{group, measurement} = NaN(3, 1); % Fill with NaN if not enough data
            end
            currentIndex = currentIndex + 8; % Move to the next set of data
        end
    else
        % If there's no data for this group, fill with NaNs
        groupData{group, :} = {NaN(3, 1)};  % Fill all measurements with NaN
    end
       end

        % Calculate statistics for each group
        averages = zeros(numGroups, 1);
        mins = zeros(numGroups, 1);
        maxs = zeros(numGroups, 1);
        ranges = zeros(numGroups, 1);
        biases = zeros(numGroups, numMeasurements);
        PassFail = cell(numGroups, 1);
        averageBias = zeros(numGroups, 1);

        % For storing confidence intervals
        upperCI = zeros(numGroups, 1);
        lowerCI = zeros(numGroups, 1);


      for group = 1:numGroups
            allValues = [];
            % Concatenate all measurements into a single array for the group
    for measurement = 1:8  % 8 measurements expected
        if ismatrix(groupData{group, measurement}) && ~isempty(groupData{group, measurement})
            allValues = [allValues; groupData{group, measurement}];
        end
    end
    % Exclude NaN values
    allValues = allValues(~isnan(allValues))
    
            if ~isempty(allValues)
                averages(group) = mean(allValues(:));
                mins(group) = min(allValues(:));
                maxs(group) = max(allValues(:));
                ranges(group) = maxs(group) - mins(group);
                
                % Compute biases
                biases(group, :) = mean(reshape(allValues, [], 8), 1) - reference(group);
                
                % Calculate average bias
                averageBias(group) = mean(biases(group, :));
                
                % Determine pass/fail
                if any(biases(group, :) > linearitytolerancepositive | biases(group, :) < -linearitytolerancenegative)
            PassFail{group} = 'Fail';
        else
            PassFail{group} = 'Pass';
                end
                else
        % If all values are NaN, set defaults
        averages(group) = NaN;
        mins(group) = NaN;
        maxs(group) = NaN;
        ranges(group) = NaN;
        averageBias(group) = NaN;
        PassFail{group} = 'Not Applicable'; % No data to evaluate
            end
        end


       % Create table with results
        resultsTable = table(reference, averages, mins, maxs, ranges, averageBias, PassFail, ...
            'RowNames',  arrayfun(@(x) sprintf('Group %d', x), 1:numGroups, 'UniformOutput', false));


        % Create figure and panels
        intro_f = figure('Name', 'Gage R&R Study - Filmscan', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);

        % Create panels for plotting and table
        plot_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.3, 0.9, 0.65]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.05, 0.9, 0.25]);

        % Create the table
        uitable('Parent', table_panel, 'Data', [num2cell(reference), num2cell(averages), num2cell(mins), num2cell(maxs), num2cell(ranges), num2cell(averageBias), PassFail], ...
            'ColumnName', resultsTable.Properties.VariableNames, 'RowName', resultsTable.Properties.RowNames, ...
            'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {91, 91, 91, 91, 91, 91, 91});

        % Create a new axis in the plot panel
        ax = axes('Parent', plot_panel);

        % Plot biases
        hold(ax, 'on');

        % Perform linear regression using polyfit only if there are valid data points
if numGroups > 0
    % Perform linear regression
    [p, S] = polyfit(reference, averageBias, 1); % p contains the coefficients, S contains the structure with stats

    % Generate fitted values
    xfit = linspace(min(reference), max(reference), 100);
    [yfit, delta] = polyval(p, xfit, S); % yfit are the fitted values, delta gives the confidence intervals

    % Standard error of the regression (S)
    S_value = sqrt(S.normr^2 / S.df); % normr is the norm of residuals, df is degrees of freedom

    % Plot data points with legend entry
    Data = scatter(ax, reference, averageBias, 50, 'b', 'filled', 'DisplayName', 'Data');

    % Plot regression line with legend entry
    Regress = plot(ax, xfit, yfit, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Regression');

    % Plot confidence intervals with legend entry
    CIHigh = plot(ax, xfit, yfit + delta, 'g--', 'LineWidth', 1, 'DisplayName', '95% CI');
    CILow = plot(ax, xfit, yfit - delta, 'g--', 'LineWidth', 1);

    % Plot biases for each group (only for groups that have data)
    for group = 1:numGroups
        if any(~isnan(biases(group, :))) % Check if there's any valid bias data
            plot(reference(group) * ones(numMeasurements, 1), biases(group, :), 'o', 'Color', [173/255, 216/255, 230/255]);
        end
    end
end
        % Plot a dummy point to handle the legend entry for 'Avg Bias'
        AvgBias = plot(NaN, NaN, 'o', 'Color', [173/255, 216/255, 230/255]);
        
  % Add legend only if data exists
legendEntries = {};
legendLabels = {};

        % Customize plot
        xlabel(ax, 'Reference Value');
        ylabel(ax, 'Bias');
        title(ax, 'Linearity and Bias Report');

      if exist('Data', 'var')
    legendEntries{end+1} = Data;
    legendLabels{end+1} = 'Data';
end
if exist('Regress', 'var')
    legendEntries{end+1} = Regress;
    legendLabels{end+1} = 'Regression';
end
if exist('CIHigh', 'var')
    legendEntries{end+1} = CIHigh;
    legendLabels{end+1} = '95% CI';
end
legendEntries{end+1} = AvgBias;
legendLabels{end+1} = 'Avg Bias';

        hold(ax, 'off');

        % Create the filename with the desired format
        filename = sprintf('3-Filmscan -Linearity+Bias Report,%s,%s,%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all
    end
catch
end

for i = 1:9


       if i == 1
    filePath2 = fullfile(folderPath, 'RR_Dome.csv');
    
    feature = 'Dome';
    pdfno = 4;
    tolerance_percentage = cd_tolerancepercentage
    sigma_level = sasigma_level
elseif i == 2
    filePath2 = fullfile(folderPath, 'RR_Dome.csv');
    feature = 'Dome Single Point';
    pdfno = 4;
    tolerance_percentage = cdsp_tolerancepercentage
    sigma_level = spsigma_level;
elseif i == 3
    filePath2 = fullfile(folderPath, 'RR_Ext.csv');
    feature = 'External';
    pdfno = 5;
    tolerance_percentage = ce_tolerancepercentage;
    sigma_level = sasigma_level;
elseif i == 4
    filePath2 = fullfile(folderPath, 'RR_Ext.csv');
    feature = 'External Single Point';
    pdfno = 5;
    tolerance_percentage = cesp_tolerancepercentage;
    sigma_level = spsigma_level
elseif i == 5
    filePath2 = fullfile(folderPath, 'RR_Int.csv');
    feature = 'Internall Wall';
    pdfno = 6;
    tolerance_percentage = ci_tolerancepercentage;
    sigma_level = sasigma_level;
    elseif i == 6
    filePath2 = fullfile(folderPath, 'RR_Int.csv');
    feature = 'Internal Single Point';
    pdfno =6;
    tolerance_percentage = cisp_tolerancepercentage;
    sigma_level = spsigma_level;
elseif i == 7
    filePath2 = fullfile(folderPath, 'RR_Rev.csv');
    feature = 'Reverse Wall';
    pdfno = 7;
    tolerance_percentage = cr_tolerancepercentage;
    sigma_level = sasigma_level;
    elseif i == 8
    filePath2 = fullfile(folderPath, 'RR_Rev.csv');
    feature = 'Reverse Single Point';
    pdfno = 7;
    tolerance_percentage = crsp_tolerancepercentage;
    sigma_level = spsigma_level;
elseif i == 9
    filePath2 = fullfile(folderPath, 'RR_Rim.csv');
    
    feature = 'Rim Coat';
    pdfno = 8;
    tolerance_percentage = crm_tolerancepercentage;
    sigma_level = sasigma_level;
end


       try
    % Check which range to read based on the value of i
    if i == 1 || i == 3 || i == 5 || i == 7 || i == 9
        measurements = readmatrix(filePath2, 'Range', 'D2:D31')
        fileread = true;
    else
        measurements = readmatrix(filePath2, 'Range', 'E2:E31')
        fileread = true;
    end
catch
    % If an error occurs (e.g., file not found), set measurements to NaN
    % and assign variable
   measurements = NaN(30, 1);
   fileread = false;
       end
        if isempty(measurements)
             measurements = NaN(30, 1);
        end


measurements = measurements(:, 1);
 

part1column = measurements(1:6);
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;


% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6;

if part2partvarcomp < 0
    part2partvarcomp = 0;
end
totalvar = repeatabilityvarcomp + part2partvarcomp;


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1 && fileread == true
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('Filmscan %s - ANOVA Tables', feature))

    filename = sprintf('%d-Filmscan %s ANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 
if fileread == true
% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R - Filmscan %s; - Tolerance = %.2f (um)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges)

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)))
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf('%s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-Filmscan %s ,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');
end

if i == 1
        cd_tolerance_repeatability = tolerance_repeatability;
        cd_tolerance = tolerance;
        cd_sv_repeatability = sv_repeatability;
    elseif i == 2
        cdsp_tolerance_repeatability = tolerance_repeatability;
        cdsp_tolerance = tolerance;
        cdsp_sv_repeatability = sv_repeatability;
    elseif i == 3
        ce_tolerance_repeatability = tolerance_repeatability;
        ce_tolerance = tolerance;
        ce_sv_repeatability = sv_repeatability;
    elseif i == 4
        cesp_tolerance_repeatability = tolerance_repeatability;
        cesp_tolerance = tolerance;
        cesp_sv_repeatability = sv_repeatability;
    elseif i == 5
        ci_tolerance_repeatability = tolerance_repeatability;
        ci_tolerance = tolerance;
        ci_sv_repeatability = sv_repeatability;
    elseif i == 6
        cisp_tolerance_repeatability = tolerance_repeatability;
        cisp_tolerance = tolerance;
        cisp_sv_repeatability = sv_repeatability;
    elseif i == 7
        cr_tolerance_repeatability = tolerance_repeatability;
        cr_tolerance = tolerance;
        cr_sv_repeatability = sv_repeatability;
    elseif i == 8
        crsp_tolerance_repeatability = tolerance_repeatability;
        crsp_tolerance = tolerance;
        crsp_sv_repeatability = sv_repeatability;
    elseif i == 9
        crm_tolerance_repeatability = tolerance_repeatability;
        crm_tolerance = tolerance;
        crm_sv_repeatability = sv_repeatability;
end
end


%pass/fail checks

% Check ch_tolerance_repeatability and assign result

if cd_tolerance_repeatability < 30
    cd_result = {'Pass'};
else
    cd_result = {'Fail'};
end

% Check cfw_tolerance_repeatability and assign result
if ce_tolerance_repeatability < 30
    ce_result = {'Pass'};
else
    ce_result = {'Fail'};
end

% Check cpd_tolerance_repeatability and assign result
if ci_tolerance_repeatability < 30
    ci_result = {'Pass'};
else
    ci_result = {'Fail'};
end


if cr_tolerance_repeatability < 30
    cr_result = {'Pass'};
else
    cr_result = {'Fail'};
end

% Check cdd_tolerance_repeatability and assign result
if crm_tolerance_repeatability < 30
    crm_result = {'Pass'};
else
    crm_result = {'Fail'};
end

if cdsp_tolerance_repeatability < 30
    cdsp_result = {'Pass'};
else
    cdsp_result = {'Fail'};
end

if crsp_tolerance_repeatability < 30
    crsp_result = {'Pass'};
else
    crsp_result = {'Fail'};
end

if cesp_tolerance_repeatability < 30
    cesp_result = {'Pass'};
else
    cesp_result = {'Fail'};
end

if cisp_tolerance_repeatability < 30
    cisp_result = {'Pass'};
else
    cisp_result = {'Fail'};
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %check if missing data
if isnan(cd_tolerance_repeatability)
    cd_result = {'Missing data'};
end
if isnan(ce_tolerance_repeatability)
    ce_result = {'Missing data'};
end

if isnan(ci_tolerance_repeatability)
    ci_result = {'Missing data'};
end

if isnan(cr_tolerance_repeatability)
    cr_result = {'Missing data'};
end

if isnan(crm_tolerance_repeatability)
    crm_result = {'Missing data'};
end

if isnan(cdsp_tolerance_repeatability)
    cdsp_result = {'Missing data'};
end

if isnan(crsp_tolerance_repeatability)
    crsp_result = {'Missing data'};
end

if isnan(cesp_tolerance_repeatability)
    cesp_result = {'Missing data'};
end

if isnan(cisp_tolerance_repeatability)
    cisp_result = {'Missing data'};
end


%summary table

dome_data = [cd_tolerancepercentage; cd_sv_repeatability; cd_tolerance_repeatability; cd_result];
ext_data = [ce_tolerancepercentage; ce_sv_repeatability; ce_tolerance_repeatability; ce_result];
int_diameter_data = [ci_tolerancepercentage; ci_sv_repeatability; ci_tolerance_repeatability; ci_result];
rev_diameter_data = [cr_tolerancepercentage; cr_sv_repeatability; cr_tolerance_repeatability; cr_result];
rim_depth_data = [crm_tolerancepercentage; crm_sv_repeatability; crm_tolerance_repeatability; crm_result];
domesp_data = [cdsp_tolerancepercentage; cdsp_sv_repeatability; cdsp_tolerance_repeatability; cdsp_result];
revsp_diameter_data = [crsp_tolerancepercentage; crsp_sv_repeatability; crsp_tolerance_repeatability; crsp_result];
extsp_data = [cesp_tolerancepercentage; cesp_sv_repeatability; cesp_tolerance_repeatability; cesp_result];
intsp_diameter_data = [cisp_tolerancepercentage; cisp_sv_repeatability; cisp_tolerance_repeatability; cisp_result];

% Create the table
summary_table = table(dome_data, ext_data, int_diameter_data, rev_diameter_data, rim_depth_data, domesp_data, revsp_diameter_data, extsp_data,intsp_diameter_data, ...
                      'VariableNames', {'Dome Avg', 'Ext Avg', 'Int Avg', 'Rev Avg', 'Rim Avg', 'Dome SP','Rev SP','Ext SP', 'Int SP'}, ...
                      'RowNames', {'Tol(um)', 'RR (um)', 'RR (%)', 'Pass/Fail'});
              app.UITable8_3.Data = summary_table;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

   
   % Bar Chart Data
    
    summary_barx = categorical(["Dome Avg", "External Avg", "Internal Avg", "Reverse Avg", "Rim Avg", "Dome SP","Rev SP","Ext SP", "Int SP"]);
    summary_bary = [cd_tolerance_repeatability, ce_tolerance_repeatability, ci_tolerance_repeatability, cr_tolerance_repeatability, crm_tolerance_repeatability, cdsp_tolerance_repeatability, crsp_tolerance_repeatability, cesp_tolerance_repeatability, cisp_tolerance_repeatability];
  
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {61,59,59,59,59,59,59,59,59})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('9-Filmscan-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Filmscan R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all;
        end

        % Callback function
        function UpdateButton_3Pushed(app, event)
             
        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed2(app, event)
            % Get user inputs
            
            gname = app.GageNameSNEditField_3.Value;
            
            operator = app.OperatorEditField_3.Value;
            misc = app.CompanyEditField_3.Value;
            sasigma_level = app.SliceAverageSigmaLevelEditField.Value;
            spsigma_level = app.SinglePointSigmaLevelEditField.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_3.Value;
            cd_tolerancepercentage = app.DomeToleranceEditField.Value;
            ce_tolerancepercentage = app.ExternalToleranceEditField.Value;
            ci_tolerancepercentage = app.InternalWallToleranceEditField.Value;
            cr_tolerancepercentage =   app.ReverseWallToleranceEditField.Value;
            crm_tolerancepercentage = app.RimCoatToleranceEditField.Value;
              crsp_tolerancepercentage = cr_tolerancepercentage
             cisp_tolerancepercentage = ci_tolerancepercentage
              cesp_tolerancepercentage = ce_tolerancepercentage
               cdsp_tolerancepercentage = cd_tolerancepercentage
            graph = app.ShowResultsforDropDown_3.Value; 
            
           % Clear previous plots
cla(app.UIAxes_3, 'reset');
cla(app.UIAxes2_3, 'reset');
cla(app.UIAxes3_3, 'reset');
cla(app.UIAxes4_3, 'reset');
    
% Clear previous table data
app.UITable2_3.Data = {};

  %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Filmscan');

if strcmp(graph, 'Can Dome')
    filePath2 = fullfile(folderPath2, 'RR_Dome.csv')
    measurements = readmatrix(filePath2, 'range', 'D2:D31');
    c_tolerance_percentage = cd_tolerancepercentage
    sigma_level = sasigma_level
elseif strcmp(graph, 'Dome Single Point')
    filePath2 = fullfile(folderPath2, 'RR_Dome.csv')
    measurements = readmatrix(filePath2, 'range', 'E2:E31');
     c_tolerance_percentage = cd_tolerancepercentage
     sigma_level = spsigma_level
elseif strcmp(graph, 'Can External')
    filePath2 = fullfile(folderPath2, 'RR_Ext.csv')
     measurements = readmatrix(filePath2, 'range', 'D2:D31');
      c_tolerance_percentage = ce_tolerancepercentage
      sigma_level = sasigma_level
elseif strcmp(graph, 'External Single Point')
    filePath2 = fullfile(folderPath2, 'RR_Ext.csv')
    measurements = readmatrix(filePath2, 'range', 'E2:E31');
     c_tolerance_percentage = ce_tolerancepercentage
     sigma_level = spsigma_level
            elseif strcmp(graph, 'Can Internal Wall')
                filePath2 = fullfile(folderPath2, 'RR_Int.csv')
    measurements = readmatrix(filePath2, 'range', 'D2:D31');
     c_tolerance_percentage = ci_tolerancepercentage
     sigma_level = sasigma_level
    elseif strcmp(graph, 'Internal Single Point')
        filePath2 = fullfile(folderPath2, 'RR_Int.csv')
     measurements = readmatrix(filePath2, 'range', 'E2:E31');
      c_tolerance_percentage = ci_tolerancepercentage;
      sigma_level = spsigma_level
            elseif strcmp(graph, 'Can Reverse Wall')
                filePath2 = fullfile(folderPath2, 'RR_Rev.csv')
    measurements = readmatrix(filePath2, 'range', 'D2:D31');
     c_tolerance_percentage = cr_tolerancepercentage
     sigma_level = sasigma_level
            elseif strcmp(graph, 'Reverse Single Point')
                filePath2 = fullfile(folderPath2, 'RR_Rev.csv')
   measurements = readmatrix(filePath2, 'range', 'E2:E31');
    c_tolerance_percentage = cr_tolerancepercentage
    sigma_level = spsigma_level
   elseif strcmp(graph, 'Can Rim Coat')
       filePath2 = fullfile(folderPath2, 'RR_Rim.csv')
   measurements = readmatrix(filePath2, 'range', 'D2:D31');
    c_tolerance_percentage = crm_tolerancepercentage
    sigma_level = sasigma_level
        end
     
        measurements = measurements(:, 1);
 c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;
c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;
c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;
c_pvalue = 0; % Placeholder for actual p-value calculation
c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp


c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;

% Create table1
c_table1 = table({gname}, {date}, {operator}, {c_tolerance_percentage}, {misc}, ...
    'VariableNames', {'Gage Name', 'Date of Study', 'Reported by', 'Tolerance', 'Misc'});

% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_3.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_3, 'Percentage Tolerance');
bar(app.UIAxes_3, c_barx, c_bary);
title(app.UIAxes_3, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_3, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_3, 'on');
plot(app.UIAxes2_3, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_3, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_3, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_3, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_3, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_3, 'Sample Range');
title(app.UIAxes2_3, 'R Chart');
ytickformat(app.UIAxes2_3, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_3, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_3, 'on');
plot(app.UIAxes3_3, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_3, 'Feature');
title(app.UIAxes3_3, 'Feature vs Part');
xlabel(app.UIAxes3_3, 'Part No.');

ytickformat(app.UIAxes3_3, '%.3f');

% Mean chart
plot(app.UIAxes4_3, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_3, 'on');
plot(app.UIAxes4_3, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_3, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_3, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_3, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_3, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_3, 'Sample Mean');
title(app.UIAxes4_3, 'XBar Chart');
xlabel(app.UIAxes4_3, 'Part No.');
ytickformat(app.UIAxes4_3, '%.4f');


        end

        % Button pushed function: RunanalysisButton_4
        function RunanalysisButton_4Pushed(app, event)
             % Get user inputs
            
            gname = app.GageNameSNEditField_4.Value;
            %date = app.ddEditField_4.Value;
            operator = app.OperatorEditField_4.Value;
            misc = app.CompanyEditField_4.Value;
            sigma_level = app.SigmaLevelEditField_4.Value;
           c_tolerancepercentage = app.ToleranceEditField.Value;
           
            show_ANOVA_table = app.ShowAnovatablesCheckBox_4.Value;
            
            % Clear previous plots
    cla(app.UIAxes_4, 'reset');
     cla(app.UIAxes2_4, 'reset');
      cla(app.UIAxes3_4, 'reset');
       cla(app.UIAxes4_4, 'reset');


    %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Optical Filmweight');
        
            %create folder to save pdfs to 
    
        baseDirectory = app.DirectoryEditField_4.Value;  %location to save to from user input

% Check if the inputted base directory is empty and set it to the current directory if it is

        if isempty(baseDirectory)
          baseDirectory = fullfile([drive ':'],'Matlab','Optical Filmweight');
            end

% Create the folder name using the variables
folderName = sprintf('Coatings Optical Ardagh,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end
    
%% Create Intro Table and figure

table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc;sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});


intro_f = figure('Name', 'Gage R&R Study - Optical Filmweight Ardagh Single Point', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Optical Filmweight Ardagh Single Point');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);
uitable('Parent', intro_panel, 'Data', table1{:,:}, 'ColumnName', table1.Properties.VariableNames,'RowName', table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1],'ColumnWidth', {150, 348});

%create file name and save figure
filename = sprintf('1-Optical Filmweight Ardagh Single Point-Intro,%s,%s,%s,pdf', gname, date, operator);   
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


for i = 1:2
    
        if i == 1
    filePath2 = fullfile(folderPath2, 'SPRR_EXT.csv');
    try
        data = readmatrix(filePath2, 'Range', 'D2:D151');
    catch
        data = NaN(150, 1);
    end
    feature = 'Single Point External';
    pdfno = 2;
    tolerancepercentage = c_tolerancepercentage;

elseif i == 2
    filePath2 = fullfile(folderPath2, 'SPRR_INT.csv');
    try
        data = readmatrix(filePath2, 'Range', 'D2:D151');
    catch
        data = NaN(150, 1);
    end
    feature = 'Single Point Internal';
    pdfno = 3;
    tolerancepercentage = c_tolerancepercentage;
        end


% Extract every 5th value starting from different initial rows
col1 = data(1:5:end) % Start from D2
col2 = data(2:5:end) % Start from D3
col3 = data(3:5:end) % Start from D4
col4 = data(4:5:end) % Start from D5
col5 = data(5:5:end) % Start from D6

% Initialize c_selected as empty
c_selected = [];

% Function to check for negative or NaN values
checkColumn = @(col) all(col >= 0 & ~isnan(col));

% Check each column and select the first one without negative or NaN values
if checkColumn(col1)
    c_selected = col1
   
elseif checkColumn(col2)
    c_selected = col2;
elseif checkColumn(col3)
    c_selected = col3;
elseif checkColumn(col4)
    c_selected = col4;
elseif checkColumn(col5)
    c_selected = col5;
else
    app.LightwillilluminateredifnovaliddatasetcanbeselectedLamp.Color = 'r';
end

numValues = length(c_selected)

if numValues == 30
  repeats = 6
part1column = c_selected(1:6);
part2column = c_selected(7:12);
part3column = c_selected(13:18);
part4column = c_selected(19:24);
part5column = c_selected(25:30);
elseif numValues ==20
    repeats = 4
    part1column = c_selected(1:4);
part2column = c_selected(5:8);
part3column = c_selected(9:12);
part4column = c_selected(13:16);
part5column = c_selected(17:20);
end

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(c_selected);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 5*(repeats-1)
totaldf = repeatabilitydf + partsdf;
            
% Part sum of squares
partss = repeats * sum((allmeans - mean_all).^2);
            
% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((c_selected - repelem(allmeans', repeats)).^2);
           
% Total sum of squares            
totalss = partss + repeatabilityss;
            
% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;
            
% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / repeats
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerancepercentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

    
%% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

%% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle('Coatings Optical Ext/Int Ardaght ANOVA Tables');

    anova_f.Position = [200, 200, 600, 800]; 
    filename = sprintf('%d-%sANOVA,%s,%s,%s.pdf',pdfno,feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    print(anova_f, filePath, '-dpdf');
end
            
% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);


%% Create a new figure 
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f);
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R - %s - Ardagh method; - Tolerance = %.2f (mm/inch)', feature, tolerancepercentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f);
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level/2)*(repeatabilitysd/sqrt(repeats)));
lcl = rangemean - ((sigma_level/2)*(repeatabilitysd/sqrt(repeats)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f);
scatterx = repelem(1:5, repeats);
scattery = c_selected;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf('%s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);

xlabel(ax3, 'Part No.');
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f);
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level/2)*(repeatabilitysd/sqrt(repeats)));
lcl2 = meanofmeans - ((sigma_level/2)*(repeatabilitysd/sqrt(repeats)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.');
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-%s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');

 if i == 1
    spi_tolerance_repeatability = tolerance_repeatability;
    spi_tolerance = tolerance
    spi_sv_repeatability = sv_repeatability
elseif i == 2
    spe_tolerance_repeatability = tolerance_repeatability
     spe_tolerance = tolerance
    spe_sv_repeatability = sv_repeatability
 end

end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%pass/fail checks


if spi_sv_repeatability < 0.4
    spi_result = {'Pass'};
else
    spi_result = {'Fail'};
end


if spe_sv_repeatability < 0.4
    spe_result = {'Pass'};
else
    spe_result = {'Fail'};
end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %check if missing data
if isnan(spi_tolerance_repeatability)
    spi_result = {'Missing data'};
end
if isnan(spe_tolerance_repeatability)
    spe_result = {'Missing data'};
end


%summary table

int_data = [c_tolerancepercentage; sigma_level; spi_sv_repeatability; spi_result];
ext_data = [c_tolerancepercentage; sigma_level; spe_sv_repeatability; spe_result];


% Create the table
summary_table = table(ext_data, int_data, ...
                      'VariableNames', {'External', 'Internal'}, ...
                      'RowNames', {'Tol(um)', 'σ', 'SV (σ x SD)', 'Pass/Fail'});
              app.UITable2_4.Data = summary_table;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

   
   % Bar Chart Data
    
    summary_barx = categorical(["External", "Internal"]);
    summary_bary = [spe_tolerance_repeatability,spi_tolerance_repeatability];
  
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {255,255})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('4-Coatings Optical Ardagh-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Optical Filmweight Ardagh Single Point R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);


close all

        end

        % Button pushed function: RunanalysisButton_5
        function RunanalysisButton_5Pushed(app, event)
             
            % Get user inputs
            
            gname = app.GageNameSNEditField_5.Value;
           
            operator = app.OperatorEditField_5.Value;
            misc = app.CompanyEditField_5.Value;
            sigma_level = app.SigmaLevelEditField_5.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_5.Value;
           
            fch_tolerancepercentage = app.CanHeightToleranceEditField.Value;
            fcdd_tolerancepercentage = app.CanDomeDepthToleranceEditField.Value;
            fctw_tolerancepercentage = app.CanTopWallToleranceEditField.Value;
            fcmw_tolerancepercentage =   app.CanMidWallToleranceEditField.Value;
    
            
            % Clear previous plots
        cla(app.UIAxes_5, 'reset');
        cla(app.UIAxes2_5, 'reset');
        cla(app.UIAxes3_5, 'reset');
        cla(app.UIAxes4_5, 'reset');
    
          % Clear previous table data
        app.UITable2_5.Data = {};
    
             %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Frontend');

    
    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_5.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Frontend');
end

% Create the folder name using the variables
folderName = sprintf('Frontend Can -R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc; sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - Frontend Can', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Frontend Can');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-Frontend Can-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


for i = 1:4


     if i == 1
    try
        filePath2 = fullfile(folderPath2, 'RR_Can.csv');
        measurements = readmatrix(filePath2, 'Range', 'C2:C31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Height';
    pdfno = 2;
    tolerance_percentage = fch_tolerancepercentage;
    
elseif i == 2
    try
        filePath2 = fullfile(folderPath2, 'RR_Can.csv');
        measurements = readmatrix(filePath2, 'Range', 'E2:E31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Dome Depth';
    pdfno = 3;
    tolerance_percentage = fcdd_tolerancepercentage;
    
elseif i == 3
    try
        filePath2 = fullfile(folderPath2, 'RR_Can.csv');
        measurements = readmatrix(filePath2, 'Range', 'F2:F31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Top Wall';
    pdfno = 4;
    tolerance_percentage = fctw_tolerancepercentage;
    
elseif i == 4
    try
        filePath2 = fullfile(folderPath2, 'RR_Can.csv');
        measurements = readmatrix(filePath2, 'Range', 'H2:H31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Mid Wall';
    pdfno = 5;
    tolerance_percentage = fcmw_tolerancepercentage;
     end

part1column = measurements(1:6);
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('FRONTEND %s - ANOVA Tables', feature))

    filename = sprintf('%d-%s-ANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames); 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R -  %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf(' %s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-%s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');


 if i == 1
    fch_tolerance_repeatability = tolerance_repeatability;
    fch_tolerance = tolerance
    fch_sv_repeatability = sv_repeatability
elseif i == 2
    fcdd_tolerance_repeatability = tolerance_repeatability
     fcdd_tolerance = tolerance
    fcdd_sv_repeatability = sv_repeatability
elseif i == 3
    fctw_tolerance_repeatability = tolerance_repeatability
     fctw_tolerance = tolerance
    fctw_sv_repeatability = sv_repeatability
elseif i == 4
    fcmw_tolerance_repeatability = tolerance_repeatability
     fcmw_tolerance = tolerance
    fcmw_sv_repeatability = sv_repeatability
 end 
end


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
%pass/fail checks

% Check fch_tolerance_repeatability and assign result
if fch_tolerance_repeatability < 30
    fch_result = {'Pass (<30%)'};
else
    fch_result = {'Fail (>=30%)'};
end

% Check fcdd_tolerance_repeatability and assign result
if fcdd_tolerance_repeatability < 30
    fcdd_result = {'Pass (<30%)'};
else
    fcdd_result = {'Fail (>=30%)'};
end

% Check fctw_tolerance_repeatability and assign result
if fctw_tolerance_repeatability < 30
    fctw_result = {'Pass (<30%)'};
else
    fctw_result = {'Fail (>=30%)'};
end

% Check fcmw_tolerance_repeatability and assign result
if fcmw_tolerance_repeatability < 30
    fcmw_result = {'Pass (<30%)'};
else
    fcmw_result = {'Fail (>=30%)'};
end


 %check if missing data
 
if isnan(fch_tolerance_repeatability)
    fch_result = {'Missing data'};
end

if isnan(fcdd_tolerance_repeatability)
    fcdd_result = {'Missing data'};
end

if isnan(fctw_tolerance_repeatability)
    fctw_result = {'Missing data'};
end

if isnan(fcmw_tolerance_repeatability)
    fcmw_result = {'Missing data'};
end


%summary table - adjusted to whether reform results are included


Height_data = [fch_tolerancepercentage; fch_sv_repeatability; fch_tolerance_repeatability; fch_result];
Dome_Depth_data = [fcdd_tolerancepercentage; fcdd_sv_repeatability; fcdd_tolerance_repeatability; fcdd_result];
Top_Wall_data = [fctw_tolerancepercentage; fctw_sv_repeatability; fctw_tolerance_repeatability; fctw_result];
Mid_Wall_data = [fcmw_tolerancepercentage; fcmw_sv_repeatability; fcmw_tolerance_repeatability; fcmw_result];

% Create the table
summary_table = table(Height_data, Dome_Depth_data, Top_Wall_data, Mid_Wall_data,  ...
                      'VariableNames', {'Can Height', 'Can DomeD', 'Can TopWall', 'Can MidWall'}, ...
                      'RowNames', {'Tol(mm)', 'RR(mm)', 'RR(%)', 'Pass/Fail'});
              app.UITable8_4.Data = summary_table;
              

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data

   
    summary_barx = categorical(["Can Height", 'Can DomeD', 'Can TopWall', 'Can MidWall']);
    summary_bary = [fch_tolerance_repeatability, fcdd_tolerance_repeatability, fctw_tolerance_repeatability, fcmw_tolerance_repeatability];
    
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {133,133,133,133})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('5-Frontend Can-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Frontend Can R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all; %closes all figure windows that have been created

        end

        % Button pushed function: UpdateButton_4
        function UpdateButton_4Pushed(app, event)
              %%script for when update button is pressed - each features
             %%code begins with if statement to check if its selected in
             %%drop down, if it is it calculates results again and plots


% Get user inputs
            gname = app.GageNameSNEditField_5.Value;
            %date = app.ddEditField_5.Value;
            operator = app.OperatorEditField_5.Value;
            misc = app.CompanyEditField_5.Value;
            sigma_level = app.SigmaLevelEditField_5.Value;
          
            fch_tolerancepercentage = app.CanHeightToleranceEditField.Value;
            fcdd_tolerancepercentage = app.CanDomeDepthToleranceEditField.Value;
           fctw_tolerancepercentage = app.CanTopWallToleranceEditField.Value;
            fcmw_tolerancepercentage =   app.CanMidWallToleranceEditField.Value;
      
            graph = app.ShowResultsforDropDown_4.Value; 
            
            % Clear previous plots
    cla(app.UIAxes_5, 'reset');
     cla(app.UIAxes2_5, 'reset');
      cla(app.UIAxes3_5, 'reset');
       cla(app.UIAxes4_5, 'reset');
    
    % Clear previous table data
    app.UITable2_5.Data = {};
    
    %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Frontend');


    if strcmp(graph, 'Can Height')
        filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    measurements = readmatrix(filePath2, 'range', 'C2:C31');
    c_tolerance_percentage = fch_tolerancepercentage
elseif strcmp(graph, 'Can Dome Depth')
    filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    measurements = readmatrix(filePath2, 'range', 'E2:E31');
     c_tolerance_percentage = fcdd_tolerancepercentage
elseif strcmp(graph, 'Can Top Wall')
    filePath2 = fullfile(folderPath2, 'RR_Can.csv');
     measurements = readmatrix(filePath2, 'range', 'F2:F31');
      c_tolerance_percentage = fctw_tolerancepercentage
elseif strcmp(graph, 'Can Mid Wall')
    filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    measurements = readmatrix(filePath2, 'range', 'H2:H31');
     c_tolerance_percentage = fcmw_tolerancepercentage
 
           
        end
     
 c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;

c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;

c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;


c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp


c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;

% Create table1
c_table1 = table({gname}, {date}, {operator}, {c_tolerance_percentage}, {misc}, ...
    'VariableNames', {'Gage Name', 'Date of Study', 'Reported by', 'Tolerance', 'Misc'});

% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_5.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_5, 'Percentage Tolerance');
bar(app.UIAxes_5, c_barx, c_bary);
title(app.UIAxes_5, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_5, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_5, 'on');
plot(app.UIAxes2_5, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_5, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_5, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_5, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_5, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_5, 'Sample Range');
title(app.UIAxes2_5, 'R Chart');
ytickformat(app.UIAxes2_5, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_5, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_5, 'on');
plot(app.UIAxes3_5, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_5, 'Feature');
title(app.UIAxes3_5, 'Feature vs Part');
xlabel(app.UIAxes3_5, 'Part No.');

ytickformat(app.UIAxes3_5, '%.3f');

% Mean chart
plot(app.UIAxes4_5, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_5, 'on');
plot(app.UIAxes4_5, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_5, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_5, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_5, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_5, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_5, 'Sample Mean');
title(app.UIAxes4_5, 'XBar Chart');
xlabel(app.UIAxes4_5, 'Part No.');
ytickformat(app.UIAxes4_5, '%.4f');
           
        end

        % Callback function
        function RunanalysisButton_6Pushed(app, event)
          
        end

        % Button pushed function: UpdateButton_5
        function UpdateButton_5Pushed(app, event)
         
            %%script for when update button is pressed - each features
             %%code begins with if statement to check if its selected in
             %%drop down, if it is it calculates results again and plots


% Get user inputs
            gname = app.GageNameSNEditField_6.Value;
            %date = app.ddEditField_6.Value;
            operator = app.OperatorEditField_6.Value;
            misc = app.CompanyEditField_6.Value;
            sigma_level = app.SigmaLevelEditField_6.Value;
           
            dcdd_tolerancepercentage = app.CanDomeDepthToleranceEditField_2.Value;
            dcrd_tolerancepercentage = app.CanMaxReformDiameterToleranceEditField.Value;
            dccv_tolerancepercentage = app.CanChuckValueToleranceEditField.Value;
            dcrh_tolerancepercentage =   app.CanReformHeightToleranceEditField.Value;
           
            graph = app.ShowResultsforDropDown_5.Value; 
            
            % Clear previous plots
    cla(app.UIAxes_6, 'reset');
     cla(app.UIAxes2_6, 'reset');
      cla(app.UIAxes3_6, 'reset');
       cla(app.UIAxes4_6, 'reset');
    
    % Clear previous table data
    app.UITable2_6.Data = {};
    
      %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'DRG');


            if strcmp(graph, 'Can Dome Depth')
                filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    measurements = readmatrix(filePath2, 'range', 'B2:B31');
    c_tolerance_percentage = dcdd_tolerancepercentage
elseif strcmp(graph, 'Can Max Reform Diameter')
    filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    measurements = readmatrix(filePath2, 'range', 'C2:C31');
     c_tolerance_percentage = dcrd_tolerancepercentage
            elseif strcmp(graph, 'Can Chuck Value')
                filePath2 = fullfile(folderPath2, 'RR_Can.csv');
     measurements = readmatrix(filePath2, 'range', 'G2:G31');
      c_tolerance_percentage = dccv_tolerancepercentage;
elseif strcmp(graph, 'Can Reform Height')
    filePath2 = fullfile(folderPath2, 'RR_Can.csv');
    measurements = readmatrix(filePath2, 'range', 'D2:D31');
     c_tolerance_percentage = dcrh_tolerancepercentage
         
           
        end
     
 c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;

c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;

c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;

c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp


c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;


% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_6.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_6, 'Percentage Tolerance');
bar(app.UIAxes_6, c_barx, c_bary);
title(app.UIAxes_6, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_6, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_6, 'on');
plot(app.UIAxes2_6, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_6, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_6, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_6, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_6, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_6, 'Sample Range');
title(app.UIAxes2_6, 'R Chart');
ytickformat(app.UIAxes2_6, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_6, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_6, 'on');
plot(app.UIAxes3_6, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_6, 'Feature');
title(app.UIAxes3_6, 'Feature vs Part');
xlabel(app.UIAxes3_6, 'Part No.');

ytickformat(app.UIAxes3_6, '%.3f');

% Mean chart
plot(app.UIAxes4_6, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_6, 'on');
plot(app.UIAxes4_6, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_6, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_6, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_6, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_6, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_6, 'Sample Mean');
title(app.UIAxes4_6, 'XBar Chart');
xlabel(app.UIAxes4_6, 'Part No.');
ytickformat(app.UIAxes4_6, '%.4f');


        end

        % Button pushed function: UpdateButton_6
        function UpdateButton_6Pushed(app, event)
            % Get user inputs
            gname = app.GageNameSNEditField_4.Value;
            %date = app.ddEditField_4.Value;
            operator = app.OperatorEditField_4.Value;
            misc = app.CompanyEditField_4.Value;
            sigma_level = app.SigmaLevelEditField_4.Value;
           c_tolerancepercentage = app.ToleranceEditField.Value;
           
            show_ANOVA_table = app.ShowAnovatablesCheckBox_4.Value
            
            graph = app.ShowResultsforDropDown_6.Value
    
            
            % Clear previous plots
    cla(app.UIAxes_4, 'reset');
     cla(app.UIAxes2_4, 'reset');
      cla(app.UIAxes3_4, 'reset');
       cla(app.UIAxes4_4, 'reset');


     %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Optical Filmweight');
    
    
        if strcmp(graph, 'External')
            filePath2 = fullfile(folderPath2, 'SPRR_EXT.csv');
    data = readmatrix(filePath2, 'Range', 'D2:D151');
    feature = 'Single Point External';
    tolerance_percentage = c_tolerancepercentage
        elseif strcmp(graph, 'Internal')
            filePath2 = fullfile(folderPath2, 'SPRR_INT.csv');
     data = readmatrix(filePath2, 'Range', 'D2:D151');
    tolerance_percentage = c_tolerancepercentage

end


% Extract every 5th value starting from different initial rows
col1 = data(1:5:end) % Start from D2
col2 = data(2:5:end) % Start from D3
col3 = data(3:5:end); % Start from D4
col4 = data(4:5:end) % Start from D5
col5 = data(5:5:end) % Start from D6

% Initialize c_selected as empty
c_selected = [];

% Function to check for negative or NaN values
checkColumn = @(col) all(col >= 0 & ~isnan(col));

% Check each column and select the first one without negative or NaN values
if checkColumn(col1)
    c_selected = col1;
   
elseif checkColumn(col2)
    c_selected = col2;
elseif checkColumn(col3)
    c_selected = col3;
elseif checkColumn(col4)
    c_selected = col4;
elseif checkColumn(col5)
    c_selected = col5;
end

numValues = length(c_selected)

if numValues == 30
  repeats = 6
part1column = c_selected(1:6);
part2column = c_selected(7:12);
part3column = c_selected(13:18);
part4column = c_selected(19:24);
part5column = c_selected(25:30);
elseif numValues ==20
    repeats = 4
  part1column = c_selected(1:4);
part2column = c_selected(5:8);
part3column = c_selected(9:12);
part4column = c_selected(13:16);
part5column = c_selected(17:20);
end

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(c_selected);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf =5*(repeats-1);
totaldf = repeatabilitydf + partsdf;
            
% Part sum of squares
partss = repeats * sum((allmeans - mean_all).^2);
            
% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((c_selected - repelem(allmeans', repeats)).^2);
           
% Total sum of squares            
totalss = partss + repeatabilityss;
            
% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;
            
% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / repeats
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = c_tolerancepercentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;


 % Components of variation graph

barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(app.UIAxes_4, barx, bary);
ylabel(app.UIAxes_4, 'Tolerance (%)');
title(app.UIAxes_4, 'Components of Variation');


% Sample Range Graph

rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level/2)*(repeatabilitysd/sqrt(5)));
lcl = rangemean - ((sigma_level/2)*(repeatabilitysd/sqrt(5)));

plot(app.UIAxes2_4, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_4, 'on');
plot(app.UIAxes2_4, rangex, repmat(rangemean, 1, 5));
plot(app.UIAxes2_4, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_4, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_4, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_4, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_4, 'Sample Range');
title(app.UIAxes2_4, 'R Chart');
ytickformat(app.UIAxes2_4, '%.4f');

% Scatter Graph

scatterx = repelem(1:5, repeats);
scattery = c_selected;

scatter(app.UIAxes3_4, scatterx, scattery, 'b');
hold(app.UIAxes3_4, 'on');
plot(app.UIAxes3_4, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
ylabel(app.UIAxes3_4, 'Feature');
title(app.UIAxes3_4, 'Feature vs Part');
xlabel(app.UIAxes3_4, 'Part No.');
ytickformat(app.UIAxes3_4, '%.3f');

% Mean Chart

plot(app.UIAxes4_4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_4, 'on');
plot(app.UIAxes4_4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level/2)*(repeatabilitysd/sqrt(repeats)));
lcl2 = meanofmeans - ((sigma_level/2)*(repeatabilitysd/sqrt(repeats)));

plot(app.UIAxes4_4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_4, 'Sample Mean');
title(app.UIAxes4_4, 'XBar Chart');
xlabel(app.UIAxes4_4, 'Part No.');
ytickformat(app.UIAxes4_4, '%.4f');

        end

        % Callback function
        function RunAnalysisButtonPushed(app, event)

sigma_level = 6;
gname = app.GageNameSNEditField_7.Value;
Imperial = app.MeaurementsinImperialCheckBox_2.Value

filename = app.SpecifyCSVFileEditField.Value;
filenameshortened = erase(filename, '.csv');
thresholds = struct();
thresholds.diameter = 0.01;
thresholds.height = 0.008;
thresholds.inside_curl = 0.01;
thresholds.material = 0.0005;
thresholds.panel_height = 0.006;
thresholds.countersink_depth = 0.008;
thresholds.curl_height = 0.006;
thresholds.curl_opening = 0.01;
thresholds.curl_conc = 0.013;
thresholds.lip_height = 0.008;
thresholds.off_draw = 0.01;
thresholds.uncurled_dia = 0.0125;
thresholds.panel_coin = 0.002;
thresholds.bubble_height = 0.004;
thresholds.bubble_top_thickness = 0.0008;
thresholds.bubble_wall_thickness = 0.001;
thresholds.cent_bead = 0.004;
thresholds.shadow_bead = 0.004;
thresholds.anti_missile = 0.002;
thresholds.rivet_diameter = 0.004;
thresholds.rivet_residual = 0.001;
thresholds.inner_bead = 0.002;
thresholds.vent_bead = 0.002;
thresholds.button_coin = 0.001;
thresholds.bubble_thickness = 0.0008;
thresholds.deboss_panel = 0.005;
thresholds.finger_panel = 0.007;
thresholds.smile_bead = 0.004;
thresholds.wall_bead = 0.004;
thresholds.reduction = 0.004;
thresholds.score = 0.004;
thresholds.outer_bead = 0.002;
thresholds.tear_panel = 0.004;
thresholds.stiffening_panel = 0.004;
thresholds.button_height = 0.004;
thresholds.Rivet_form_thickness = 0.0008
thresholds.anti_rotation = 0.004
% Adjust thresholds based on Knob value
Knob_value = app.Knob.Value;
if Knob_value == 2
    thresholds.countersink_depth = 0.01; 
    thresholds.height = 0.01;            
end


% Initialize passFail as 'N/A'
passFail = 'N/A';


operator = app.OperatorEditField_7.Value;
misc = app.CompanyMiscEditField.Value;

 %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'ES8000');

% Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_8.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','ES8000');
end

% Create the folder name using the variables
folderName = sprintf('ES8000-R&R -%s,%s,%s,%s', filenameshortened, gname, date, misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

secondFolderName = 'Feature Results';  % You can modify the name as per your requirements

% Construct the full path for the second folder inside the first folder
fullFolderPath2 = fullfile(fullFolderPath, secondFolderName);

% Create the second folder if it doesn't exist
if ~exist(fullFolderPath2, 'dir')
    mkdir(fullFolderPath2);
end
% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company/Misc';'Filename'}, ...
                   {gname; date; operator; misc;filename}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - ES8000', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - ES8000');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, ...
        'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 348});

filename = sprintf('1-Es8000-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');
close(intro_f)
%%%%%%%%%%%%%%%%

filename = app.SpecifyCSVFileEditField.Value;

if isempty(filename)
    filename = "RR_ES8000.csv";
end

filePath = fullfile(folderPath, filename);

if isfile(filePath)
    app.CSVFoundLamp.Color = 'green';
    
    % Read data from the file
    data = readtable(filePath);
    data1 = readcell(filePath, 'Range', 'AC:AC');

    data2 = readmatrix(filePath, 'Range', 'AH:AH');
    
    combinedData = table(data1, data2, 'VariableNames', {'Feature', 'Value'});
else
    app.CSVFoundLamp.Color = 'red';
end

features = combinedData.Feature;
values = combinedData.Value;

% Find unique features
uniqueFeatures = unique(features, 'stable');

% Extract the number of parts (x) and repeats (v) from the Excel data
v = data.Var22(end);  % Assuming 'Var22' is the variable name for column V  -  - repeats
x = data.Var24(end);  % Assuming 'Var24' is the variable name for column X  -   number of parts

% Create a single figure for all plots
f = figure( 'Position', [200, 200, 800, 600]);
% For each unique feature, extract corresponding values and create a variable
for i = 1:length(uniqueFeatures)
    
    % Sanitize the feature name to make it a valid MATLAB variable name
    sanitizedFeatureName = matlab.lang.makeValidName(uniqueFeatures{i});
    
    % Find the indices where the feature matches
    featureIndices = strcmp(features, uniqueFeatures{i});
    
    % Extract values for the current feature
    featureValues = values(featureIndices);
    
    % Assign these values to a variable with the sanitized feature name
    assignin('base', sanitizedFeatureName, featureValues);
end


resultsTable = table('Size', [0 5], ...
                     'VariableTypes', {'string', 'double', 'double', 'string', 'double'}, ...
                     'VariableNames', {'Feature', 'RepeatabilitySD', 'Repeatability6SD', 'PassFail', 'Threshold'})

                 
for i = 1:length(uniqueFeatures)
    feature = uniqueFeatures{i};
    featureIndices = strcmp(features, feature);
    measurements = values(featureIndices);
    measurements2 = [];
    
    % Dynamically generate part columns based on the value of x
    partMeans = zeros(1, x);
    for j = 1:x
        partColumn = measurements(j:x:end);
        measurements2 = [measurements2; partColumn];
        partMeans(j) = mean(partColumn);
    end
    
    meanofmeans = mean(partMeans);
    mean_all = mean(measurements);
    
    % ANOVA calculations
    partsdf = x - 1;
    repeatabilitydf = x * (v - 1);
    totaldf = repeatabilitydf + partsdf;
    
    % Part sum of squares
    partss = v * sum((partMeans - mean_all).^2);
    
    % Repeatability ss - summed squared difference of each part
    repeatabilityss = sum((measurements2 - repelem(partMeans', v)).^2);
    
    % Total sum of squares            
    totalss = partss + repeatabilityss;
    
    % Mean squares
    repeatabilityms = repeatabilityss / repeatabilitydf;
    partms = partss / partsdf;
    F = partms / repeatabilityms;
    
    % Variance components
    repeatabilityvarcomp = repeatabilityms;
    part2partvarcomp = (partms - repeatabilityms) / v;
    if part2partvarcomp < 0
        part2partvarcomp = 0;
    end
    totalvar = repeatabilityvarcomp + part2partvarcomp;
    
    % Standard deviation
    repeatabilitysd = sqrt(repeatabilityvarcomp);
    totalsd = sqrt(totalvar);
    part2partsd = sqrt(part2partvarcomp);
    repeatability6sd = 6 * repeatabilitysd;
    
    % Study variance
    sv_repeatability = repeatabilitysd * sigma_level;
    sv_part2part = part2partsd * sigma_level;
    sv_totalvariation = totalsd * sigma_level;
    repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
    part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
    totalvariation_percentSV = 100;
    
    % Tolerance calculations
  
    
  % Determine the threshold based on the feature name and set passFail
if startsWith(feature, 'diameter', 'IgnoreCase', true)
    threshold = thresholds.diameter;
elseif startsWith(feature, 'height', 'IgnoreCase', true)
    threshold = thresholds.height;
elseif startsWith(feature, 'inside_curl', 'IgnoreCase', true)
    threshold = thresholds.inside_curl;
elseif startsWith(feature, 'material', 'IgnoreCase', true)
    threshold = thresholds.material;
elseif startsWith(feature, 'panel_height', 'IgnoreCase', true)
    threshold = thresholds.panel_height;
elseif startsWith(feature, 'countersink_depth', 'IgnoreCase', true)
    threshold = thresholds.countersink_depth;
elseif startsWith(feature, 'curl_height', 'IgnoreCase', true)
    threshold = thresholds.curl_height;
elseif startsWith(feature, 'curl_opening', 'IgnoreCase', true)
    threshold = thresholds.curl_opening;
elseif startsWith(feature, 'curl_conc', 'IgnoreCase', true)
    threshold = thresholds.curl_conc;
elseif startsWith(feature, 'lip_height', 'IgnoreCase', true)
    threshold = thresholds.lip_height;
elseif startsWith(feature, 'off_draw', 'IgnoreCase', true)
    threshold = thresholds.off_draw;
elseif startsWith(feature, 'uncurled_dia', 'IgnoreCase', true)
    threshold = thresholds.uncurled_dia;
elseif startsWith(feature, 'panel_coin', 'IgnoreCase', true)
    threshold = thresholds.panel_coin;
elseif startsWith(feature, 'bubble_height', 'IgnoreCase', true)
    threshold = thresholds.bubble_height;
elseif startsWith(feature, 'bubble_top_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_top_thickness;
elseif startsWith(feature, 'bubble_wall_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_wall_thickness;
elseif startsWith(feature, 'cent_bead', 'IgnoreCase', true)
    threshold = thresholds.cent_bead;
elseif startsWith(feature, 'shadow_bead', 'IgnoreCase', true)
    threshold = thresholds.shadow_bead;
elseif startsWith(feature, 'anti_missile', 'IgnoreCase', true)
    threshold = thresholds.anti_missile;
elseif startsWith(feature, 'rivet_diameter', 'IgnoreCase', true)
    threshold = thresholds.rivet_diameter;
elseif startsWith(feature, 'rivet_residual', 'IgnoreCase', true)
    threshold = thresholds.rivet_residual;
elseif startsWith(feature, 'inner_bead', 'IgnoreCase', true)
    threshold = thresholds.inner_bead;
elseif startsWith(feature, 'vent_bead', 'IgnoreCase', true)
    threshold = thresholds.vent_bead;
elseif contains(feature, 'button_coin', 'IgnoreCase', true)
    threshold = thresholds.button_coin;
elseif contains(feature, 'bubble_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_thickness;
elseif contains(feature, 'deboss_panel', 'IgnoreCase', true)
    threshold = thresholds.deboss_panel;
elseif startsWith(feature, 'finger_panel', 'IgnoreCase', true)
    threshold = thresholds.finger_panel;
elseif contains(feature, 'Smile', 'IgnoreCase', true)
    threshold = thresholds.smile_bead;
elseif contains(feature, 'wall_bead', 'IgnoreCase', true)
    threshold = thresholds.wall_bead;
elseif contains(feature, 'Reduction', 'IgnoreCase', true)
    threshold = thresholds.reduction;
elseif contains(feature, 'Score', 'IgnoreCase', true)
    threshold = thresholds.score;
elseif contains(feature, 'outer_bead', 'IgnoreCase', true)
    threshold = thresholds.outer_bead;
elseif contains(feature, 'tear_panel', 'IgnoreCase', true)
    threshold = thresholds.tear_panel;
elseif contains(feature, 'stiffening_panel', 'IgnoreCase', true)
    threshold = thresholds.stiffening_panel;
elseif endsWith(feature, 'button_height', 'IgnoreCase', true)
    threshold = thresholds.button_height;
elseif startsWith(feature, 'fingerwell', 'IgnoreCase', true)
   threshold = thresholds.finger_panel;
elseif startsWith(feature, 'button_thickness', 'IgnoreCase', true)
   threshold = thresholds.Rivet_form_thickness;
elseif endsWith(feature, 'button_thickness', 'IgnoreCase', true)
   threshold = thresholds.Rivet_form_thickness;
elseif startsWith(feature, 'panel_form', 'IgnoreCase', true)
   threshold = thresholds.deboss_panel;
elseif startsWith(feature, 'anti_turn', 'IgnoreCase', true)
   threshold = thresholds.anti_rotation;
   elseif startsWith(feature, 'anti turn', 'IgnoreCase', true)
   threshold = thresholds.anti_rotation;
else
    threshold = []; % No specific threshold, set to empty to trigger 'N/A'
end

   % Determine Pass/Fail based on repeatabilitysd, only if threshold is defined
if isempty(threshold)
    passFail = 'N/A'; % or keep it as is if you want no change
else
    % Determine Pass/Fail based on repeatabilitysd, only if threshold is defined
    if Imperial == 1 
        threshold = threshold/25.399986284
    end
    if repeatabilitysd < threshold
        passFail = 'Pass';
    else
        passFail = 'Fail';
    end
end

  % Ensure 'feature' and 'passFail' are strings to match the table definition
feature = string(feature)
passFail = string(passFail);

    % Handle threshold, converting to NaN if it's not numeric
if isempty(threshold)
    threshold = NaN;  % Use NaN for non-numeric thresholds
else
    threshold = double(threshold); % Ensure it’s of type double
end
       % Create a new row to add to the results table, ensuring consistent data types
newRow = {feature, repeatabilitysd, repeatability6sd, passFail, threshold};

% Convert newRow to a table to ensure data types match
newRowTable = cell2table(newRow, 'VariableNames', resultsTable.Properties.VariableNames);

% Add the new row to the results table
resultsTable = [resultsTable; newRowTable];
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


feature = strrep(feature, ':', '_') % Rename as ':' invalidates file names
feature = strrep(feature, '_', ' ');

% Create the title string
title_str = sprintf('ES8000 - R&R - %s, 1sd threshold = %.8f', feature,threshold);
% Create subplots
 clf(f);
% Components of Variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [repeatabilitysd, part2partsd];
bar(ax1, barx, bary);
ylabel(ax1, 'SD');
title(ax1, 'Components of Variation');
title_str = sprintf('ES8000- R&R - %s;', feature);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:x;

% Compute ranges dynamically based on x
ranges = zeros(1, x);
for j = 1:x
    partColumn = measurements(j:x:end);
    ranges(j) = max(partColumn) - min(partColumn);
end
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, x));
plot(ax2, rangex, repmat(ucl, 1, x), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, x), 'Color', [0 1 0]);

text(ax2, x-0.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, x-0.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:x, v)
scattery = measurements2

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:x, partMeans, '-o', 'Color', [1, 0.5, 0]);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.');
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:x, partMeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:x, repmat(meanofmeans, 1, x));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));

plot(ax4, 1:x, repmat(ucl2, 1, x), 'Color', [0 1 0]);
plot(ax4, 1:x, repmat(lcl2, 1, x), 'Color', [0 1 0]);

text(ax4, x-0.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, x-0.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.');
ytickformat(ax4, '%.4f');

% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV) ;
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV) 
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {154,154,154,154});
f.Position = [200, 200, 800, 600]; % Increased figure size to stop title being cut off

filename = sprintf('%s,%s,%s,%s.pdf', feature, gname, date, operator);    
filePath = fullfile(fullFolderPath2, filename);
print(f, filePath, '-dpdf');

show_ANOVA_table = app.ShowAnovaTablesCheckBox.Value;
if show_ANOVA_table ==1;
% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});


    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('%s - ANOVA Tables', feature))

    filename = sprintf('%s-ANOVA,%s,%s,%s.pdf',  feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath2, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
    close(anova_f)
end


end

filename2 = sprintf('__Results Table -%s,%s,%s,%s.xlsx', filenameshortened, gname, date, misc);
fullFilePath3 = fullfile(fullFolderPath, filename2);
% Initialize a counter for duplicates
counter = 1;

% Check if the file already exists
while isfile(fullFilePath3)
    % Create a new filename with an incremented counter
    filename2 = sprintf('__Results Table -%s,%s,%s,%s (%d).xlsx', filenameshortened, gname, date, misc, counter);
    fullFilePath3 = fullfile(fullFolderPath, filename2);
    counter = counter + 1; % Increment the counter
end

writetable(resultsTable, fullFilePath3);
numTables = 3;

% Get the total number of rows in the results table
totalRows = height(resultsTable);

% Calculate the number of rows for each table
rowsPerTable = floor(totalRows / numTables);
remainderRows = mod(totalRows, numTables);

% Initialize the starting index for each table
startRow = 1;

% Loop to create each of the split tables
for i = 1:numTables
    % Determine the end row for the current table
    if i <= remainderRows
        endRow = startRow + rowsPerTable;
    else
        endRow = startRow + rowsPerTable - 1;
    end
    
    % Extract rows for the current table
    currentTable = resultsTable(startRow:endRow, :);
    
    % Assign the current table to the appropriate variable
    eval(sprintf('resultsTable%d = currentTable;', i));
    
    % Update the starting row for the next table
    startRow = endRow + 1;
end

    app.UITable9.Data = resultsTable1;
     app.UITable9_2.Data = resultsTable2;
        app.UITable9_3.Data = resultsTable3;
     
feature_names = uniqueFeatures;

totalRows = height(resultsTable);
splitIndex = floor(totalRows / 2);
resultsTable_1 = resultsTable(1:splitIndex, :);
resultsTable_2 = resultsTable(splitIndex+1:end,: );
feature_names1 = feature_names(1:splitIndex);
feature_names2 = feature_names(splitIndex+1:end);
dataCellArray1 = table2cell(resultsTable_1);
dataCellArray1 = dataCellArray1(:, 2:end);
        dataCellArray1 = cellfun(@num2str, dataCellArray1, 'UniformOutput', false);
dataCellArray2 = table2cell(resultsTable_2);
dataCellArray2 = dataCellArray2(:, 2:end);
        dataCellArray2= cellfun(@num2str, dataCellArray2, 'UniformOutput', false);

f1 = figure('Name', 'Figure 1', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
uitable('Data', dataCellArray1,"RowName", feature_names1,'ColumnName', {'1 SD', '6 SD', 'P/F','Threshold(1SD)'} ,'position',[10, 90, 780, 800],'columnwidth' ,{65,65,38,140});
sgtitle('ES8000 Feature Summary 1', 'Interpreter', 'none');

filename = sprintf('Z- Es8000-Summary-1');    
filePath = fullfile(fullFolderPath, filename);
print(f1, filePath, '-dpdf');


f2 = figure('Name', 'Figure 2', 'NumberTitle', 'off', 'position', [200, 200, 800, 1000]);
uitable( 'Data', dataCellArray2, "RowName", feature_names2, 'ColumnName', {'1 SD', '6 SD','P/F','Threshold(1SD)'} ,'position',[10, 90, 780, 800],'columnwidth' ,{65,65,38,140});
sgtitle('ES8000 Feature Summary 2');

filename = sprintf('Z- Es8000-Summary-2','Interpreter', 'none');   
filePath = fullfile(fullFolderPath, filename);
print(f2, filePath, '-dpdf');


% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_ES8000 R&R Report_%s_%s_%s_%s_%s.pdf', filenameshortened,gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all
        end

        % Callback function
        function CloseAllButtonPushed(app, event)
            close all
        end

        % Button pushed function: RunAnalysisButton_2
        function RunAnalysisButton_2Pushed(app, event)
         gname = app.GageNameSNEditField_8.Value;
        
            operator = app.OperatorEditField_8.Value;
            misc = app.CompanyMiscEditField_2.Value;
            sigma_level = app.SigmaLevelEditField_11.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_11.Value;
            %date = sprintf('%s-%s-%s', dd, mm, yy);
           
              %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'EPG');
        baseDirectory = app.DirectoryEditField_9.Value;
        flip = app.Usecodetoflipresultsinstack0204CheckBox.Value
        
        Imperial = app.MeaurementsinImperialCheckBox.Value

Feature1DropDown = app.Feature1DropDown.Value


if strcmp(Feature1DropDown, 'Bubble/Button Height')
    Feature1Tol = 0.12;
elseif strcmp(Feature1DropDown, 'Bubble/Button Thickness')
    Feature1Tol = 0.03;
elseif strcmp(Feature1DropDown, 'Button Coining')
    Feature1Tol = 0.024;
elseif strcmp(Feature1DropDown, 'Bead Coining')
    Feature1Tol = 0.03;
elseif strcmp(Feature1DropDown, 'Rivet Diameter')
    Feature1Tol = 0.16;
elseif strcmp(Feature1DropDown, 'Rivet Residual')
    Feature1Tol = 0.03;
elseif strcmp(Feature1DropDown, 'Form Stiffening')
    Feature1Tol = 0.12;
elseif strcmp(Feature1DropDown, 'Stolle Step')
    Feature1Tol = 0.16;
elseif strcmp(Feature1DropDown, 'Score Residual')
    Feature1Tol = 0.016;
elseif strcmp(Feature1DropDown, 'Custom (Entered to the right)')
    Feature1Tol = 1;
    elseif strcmp(Feature1DropDown, 'N/A')
    Feature1Tol = 0;
end

if Imperial == 1
Feature1Tol = Feature1Tol /25.399986284
end

if strcmp(Feature1DropDown ,'Custom (Entered to the right)')
Feature1Tol = app.EditField.Value
end


Feature2DropDown = app.Feature2DropDown.Value;

if strcmp(Feature2DropDown, 'Bubble/Button Height')
    Feature2Tol = 0.12;
elseif strcmp(Feature2DropDown, 'Bubble/Button Thickness')
    Feature2Tol = 0.03;
elseif strcmp(Feature2DropDown, 'Button Coining')
    Feature2Tol = 0.024; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Bead Coining')
    Feature2Tol = 0.03; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Rivet Diameter')
    Feature2Tol = 0.16; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Rivet Residual')
    Feature2Tol = 0.03; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Form Stiffening')
    Feature2Tol = 0.12; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Stolle Step')
    Feature2Tol = 0.16; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Score Residual')
    Feature2Tol = 0.016; % Changed from Feature1Tol to Feature2Tol
elseif strcmp(Feature2DropDown, 'Custom (Entered to the right)')
    Feature2Tol = 2; % Changed from 1 to 2
elseif strcmp(Feature2DropDown, 'N/A')
    Feature2Tol = 0;

end

if Imperial == 1
    Feature2Tol = Feature2Tol / 25.399986284; % Changed from Feature1Tol to Feature2Tol
end

if strcmp(Feature2DropDown, 'Custom (Entered to the right)')
    Feature2Tol = app.EditField_2.Value; % Changed from Feature1Tol to Feature2Tol
end

Feature3DropDown = app.Feature3DropDown.Value;

if strcmp(Feature3DropDown, 'Bubble/Button Height')
    Feature3Tol = 0.12;
elseif strcmp(Feature3DropDown, 'Bubble/Button Thickness')
    Feature3Tol = 0.03;
elseif strcmp(Feature3DropDown, 'Button Coining')
    Feature3Tol = 0.024; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Bead Coining')
    Feature3Tol = 0.03; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Rivet Diameter')
    Feature3Tol = 0.16; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Rivet Residual')
    Feature3Tol = 0.03; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Form Stiffening')
    Feature3Tol = 0.12; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Stolle Step')
    Feature3Tol = 0.16; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Score Residual')
    Feature3Tol = 0.016; % Changed from Feature2Tol to Feature3Tol
elseif strcmp(Feature3DropDown, 'Custom (Entered to the right)')
    Feature3Tol = 2; % Changed from 1 to 2
     elseif strcmp(Feature3DropDown, 'N/A')
    Feature3Tol = 0;

end
units = "mm"
if Imperial == 1
    Feature3Tol = Feature3Tol / 25.399986284; % Changed from Feature2Tol to Feature3Tol
    units = "Inches"
end

if strcmp(Feature3DropDown, 'Custom (Entered to the right)')
    Feature3Tol = app.EditField_3.Value; % Changed from Feature2Tol to Feature3Tol
end

 SDlimit1 = ((30* (Feature1Tol/100))/sigma_level)
  SDlimit2 = ((30* (Feature2Tol/100))/sigma_level)
   SDlimit3 = ((30* (Feature3Tol/100))/sigma_level)

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
     baseDirectory = fullfile([drive ':'],'Matlab','EPG');
end

% Create the folder name using the variables
folderName = sprintf('EPG-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

 % Define the folder path where the files are located

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc; sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - EPG', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - EPG');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-EPG-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


% Initialize the measurements matrices
measurements1 = NaN(10, 4);  % 10 rows and 4 columns for the first set of values
measurements2 = NaN(10, 4);  % 10 rows and 4 columns for the second set of values
measurements3 = NaN(10, 4);  % 10 rows and 4 columns for the second set of values

% Loop through each file to populate the matrices
for i = 1:4
    % Construct the file name
    fileName = sprintf('stack%02d.xls', i);
    
    % Construct the full file path
    filePath = fullfile(folderPath, fileName);
    
   try
        % Read the data from column D starting from row 11
        data = readmatrix(filePath, 'Range', 'D11:D40') % D11:D40 to account for up to 30 values
    catch
        % If readmatrix fails, data remains empty
        data = [];
   end
    % Check the number of values read (either 10 or 20)
    numValues = length(data)
    
    if numValues == 10
        % If there are 10 values, proceed as usual
        columnData1 = data;
        columnData2 = [];
        columnData3 = [];
    elseif numValues == 20
        % If there are 20 values, split the data into two sets
        columnData1 = data(1:2:end); % Every other value starting from the 1st
        columnData2 = data(2:2:end); % Every other value starting from the 2nd
        columnData3 = [];
        elseif numValues == 30
            columnData1 = data(1:3:end); % Every other value starting from the 1st
        columnData2 = data(2:3:end); % Every other value starting from the 2nd
        columnData3 = data(3:3:end); % Every 3rd value starting from the 3rd
    else
        error('Unexpected number of values in column D.');
    end
    
    % Flip the data if it's from stack02.xls or stack04.xls
    if flip == 1
    if i == 2 || i == 4
        columnData1 = flipud(columnData1);
        if ~isempty(columnData2)
            columnData2 = flipud(columnData2);
        end
        if ~isempty(columnData3)
            columnData3 = flipud(columnData3);
        end
    end
    end
    % Assign the data to the corresponding columns in the measurements matrices
    measurements1(:, i) = columnData1
    if ~isempty(columnData2)
        measurements2(:, i) = columnData2
    end
    if ~isempty(columnData3)
        measurements3(:, i) = columnData3
end
end
% Check for missing data in measurements
if any(isnan(measurements1(:)))
    measurements1 = NaN(10, 4);  % Reassign to NaNs if missing data is found
    app.ExceldatacompleteLamp.Color = 'red';
else
    app.ExceldatacompleteLamp.Color = 'green';
end

if any(isnan(measurements2(:)))
    measurements2 = NaN(10, 4);  % Reassign to NaNs if missing data is found
end
if any(isnan(measurements3(:)))
    measurements3 = NaN(10, 4);  % Reassign to NaNs if missing data is found
end

% Initialize feature names
featureName1 = '';  % Feature name for measurements1
featureName2 = '';  % Feature name for measurements2    
featureName3 = '';  % Feature name for measurements2    
fileName = 'stack01.xls';
filePath = fullfile(folderPath, fileName);

% Read the feature names from cells C11 and C12
featureName1 = readcell(filePath, 'Range', 'C11')
featureName2 = readcell(filePath, 'Range', 'C12')
featureName3 = readcell(filePath, 'Range', 'C13')

% If featureName1 and featureName2 are returned as cell arrays, extract the content
if iscell(featureName1)
    featureName1 = featureName1{1}; % Extract the string from the cell
end
if iscell(featureName2)
    featureName2 = featureName2{1}; % Extract the string from the cell
end
if iscell(featureName3)
    featureName3 = featureName3{1}; % Extract the string from the cell
end


if numValues == 10 
    loops = 1
elseif numValues == 20
    loops = 2
elseif numValues == 30
    loops =3
end

% Loop over measurements matrices
for measurementIdx = 1:loops
    % Select the appropriate measurements matrix
    if measurementIdx == 1
measurements = measurements1
        pdfno = 2
        feature = featureName1
        SDlimit = SDlimit1
    elseif measurementIdx == 2
       
        
        measurements = measurements2
        pdfno = 3
         feature = featureName2
         SDlimit = SDlimit2
          elseif measurementIdx == 3
              measurements = measurements3
        pdfno = 4
         feature = featureName3
         SDlimit = SDlimit3        
    end
    
    
% Calculate the mean of every two horizontal values
rowMeans = mean(reshape(measurements', 2, []), 1)';

% Repeat each mean twice
result = repelem(rowMeans, 2);


op1  = [measurements(:, 1); measurements(:, 2)]
op2  = [measurements(:, 3); measurements(:, 4)]
ops = [mean(op1) mean(op2)]
% Parameters
N_parts = 10;
N_operators = 2;
N_repeats = 2;  % Each operator has 2 test repeats

% Flatten the matrix and generate factor levels
data = reshape(measurements', [], 1)  % Flatten data into a column vector
parts = repmat((1:N_parts)', N_operators * N_repeats, 1)  % Part indices
operators = [repmat(1, N_parts * N_repeats, 1); repmat(2, N_parts * N_repeats, 1)];  % Operator indices

% Grand Mean
grand_mean = mean(data)


% Total Sum of Squares (SS_T)
SS_T = sum((data - grand_mean).^2);

% Mean of Each Part
mean_parts = mean(measurements, 2)  % Average across all operators for each part

% Sum of Squares for Parts (SS_P)
SS_P = N_operators * N_repeats * sum((mean_parts - grand_mean).^2);

% Mean of Each Operator

% Mean across all parts and repeats for each operator

% Sum of Squares for Operators (SS_O)
SS_O = N_parts * N_repeats * sum((ops' - grand_mean).^2);

% Compute the mean of each row
row_means = mean(measurements, 2);


% Repeatability Sum of Squares (SS_R)
SS_R = SS_T - SS_P - SS_O

% Degrees of Freedom
df_P = N_parts - 1;
df_O = N_operators - 1;
df_T = (N_repeats * N_operators * N_parts) - 1;
df_R = df_T - df_P - df_O


% Mean Squares
MS_P = SS_P / df_P;
MS_O = SS_O / df_O;
MS_R = SS_R / df_R;

% Variance Components
repeatability_var = MS_R
operator_var = (MS_O - MS_R) / (N_parts * N_repeats);
if operator_var < 0
    operator_var = 0;
end
part2part_var = (MS_P - MS_R) / (N_operators*N_repeats);
if part2part_var < 0
    part2part_var = 0;
end
total_var = repeatability_var + part2part_var + operator_var;

% Standard Deviations
repeatability_sd = sqrt(repeatability_var)
operator_sd = sqrt(operator_var);
part2part_sd = sqrt(part2part_var);
total_sd = sqrt(total_var);

% Tolerance and Study Variance

sv_repeatability = repeatability_sd * sigma_level;
sv_operator = operator_sd * sigma_level;
sv_part2part = part2part_sd * sigma_level;
sv_totalvariation = total_sd * sigma_level;


if show_ANOVA_table ==1
    

% Create ANOVA Table
table2 = table([df_P; df_O; df_R], ...
               [SS_P; SS_O; SS_R], ...
               [MS_P; MS_O; MS_R], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Operator', 'Repeatability'});

% Create Variance Component Table
table3 = table([repeatability_var; operator_var; part2part_var; total_var], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Operator', 'Part-to-Part', 'Total Variation'});
           
 anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('EPG %s - ANOVA Tables', feature))

    filename = sprintf('%d-%s ANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end
    
% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatability_sd), sprintf('%.7f', sv_repeatability);
    sprintf('%.7f', part2part_sd), sprintf('%.7f', sv_part2part); 
    sprintf('%.7f', operator_sd), sprintf('%.7f', sv_operator);
    sprintf('%.7f', total_sd), sprintf('%.7f', sv_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)'};
rowNames = {'Repeatability', 'Part-to-Part','Operator', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);


% Create a new figure
f = figure('Name', 'Measurement Analysis', 'NumberTitle', 'off', 'position' ,[200, 200, 800, 1000]);


% 1. Components of Variation Bar Chart
ax1 = subplot(4, 2, 1, 'Parent', f);
bar(ax1, [repeatability_sd, operator_sd, part2part_sd, total_sd]);
title(ax1, 'Components of Variation (SD)');
xticks(ax1, 1:4);
xticklabels(ax1, {'Repeatability', 'Operator', 'Part-to-Part', 'Total Variation'});
ylabel(ax1, 'Standard Deviation');
grid(ax1, 'on');
xtickangle(ax1,45);

% 2. Measurements per Part Scatter Plot
scatterparts = [1; 1; 1; 1; 2; 2; 2; 2; 3; 3; 3; 3; 4; 4; 4; 4; ...
                5; 5; 5; 5; 6; 6; 6; 6; 7; 7; 7; 7; 8; 8; 8; 8; ...
                9; 9; 9; 9; 10; 10; 10; 10];
ax2 = subplot(4, 2, 2, 'Parent', f);
hold(ax2, 'on');
scatter(ax2, scatterparts, data, 'b');  % Measurements
plot(ax2, 1:N_parts, mean_parts, '-r', 'LineWidth', 1.5);  % Average per part (red line)
title(ax2, 'Measurements per Part');
xlabel(ax2, 'Part');
ylabel(ax2, 'Measurement');
grid(ax2, 'on');
hold(ax2, 'off');

% 3. R Chart by Operator (Manual Range Calculation)
ax3 = subplot(4, 2, 3, 'Parent', f);
hold(ax3, 'on');
ranges_op1 = max(measurements(:, 1:2), [], 2) - min(measurements(:, 1:2), [], 2);
ranges_op2 = max(measurements(:, 3:4), [], 2) - min(measurements(:, 3:4), [], 2);

plot(ax3, 1:N_parts, ranges_op1, 'b-o');  % Operator 1
plot(ax3, N_parts+1:2*N_parts, ranges_op2, 'r-o');  % Operator 2


% Combine ranges from both operators
ranges = [ranges_op1; ranges_op2];
range_mean = mean(ranges);

% Calculate UCL and LCL for range chart (based on control chart formula for range charts)
ucl_r = range_mean + ((sigma_level / 2) * (repeatability_sd / sqrt(2)));
lcl_r = max(0, range_mean - ((sigma_level / 2) * (repeatability_sd / sqrt(2))));  % LCL can't be negative
% Plot UCL, LCL, and Range Mean lines
plot(ax3, 1:2*N_parts, repmat(ucl_r, 1, 2*N_parts), 'Color', [0 1 0], 'LineStyle', '-', 'LineWidth', 1);  % UCL line
plot(ax3, 1:2*N_parts, repmat(lcl_r, 1, 2*N_parts), 'Color', [0 1 0], 'LineStyle', '-', 'LineWidth', 1);  % LCL line
plot(ax3, 1:2*N_parts, repmat(range_mean, 1, 2*N_parts), 'Color', [1 0.5 0], 'LineStyle', '-', 'LineWidth', 1.5);  % Mean line (orange)

% Annotate UCL, LCL, and Mean
text(ax3, 2*N_parts + 0.5, ucl_r, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax3, 2*N_parts + 0.5, lcl_r, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');


title(ax3, 'R Chart by Operator');
xlabel(ax3, 'Part');
ylabel(ax3, 'Range');
xticks(ax3, 1:2*N_parts);
xticklabels(ax3, [1:N_parts 1:N_parts]);
xlim(ax3, [1 2*N_parts]);
grid(ax3, 'on');
hold(ax3, 'off');

% 4. Mean Chart by Operator
ax4 = subplot(4, 2, 4, 'Parent', f);
hold(ax4, 'on');
mean_op1 = mean(measurements(:, 1:2), 2);
mean_op2 = mean(measurements(:, 3:4), 2);

plot(ax4, 1:N_parts, mean_op1, 'b-o');  % Operator 1 Mean
plot(ax4, N_parts+1:2*N_parts, mean_op2, 'r-o');  % Operator 2 Mean

% Calculate UCL and LCL
means = [mean_op1; mean_op2];  % Combine both operator means
meanofmeans = mean(means);
ucl2 = meanofmeans + ((sigma_level / 2) * (repeatability_sd / sqrt(2)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatability_sd / sqrt(2)));


% Plot UCL and LCL across the entire x-axis (1:2*N_parts)
plot(ax4, 1:2*N_parts, repmat(ucl2, 1, 2*N_parts), 'Color', [0 1 0], 'LineStyle', '-', 'LineWidth', 1);  % UCL line
plot(ax4, 1:2*N_parts, repmat(lcl2, 1, 2*N_parts), 'Color', [0 1 0], 'LineStyle', '-', 'LineWidth', 1);  % LCL line
plot(ax4, 1:2*N_parts, repmat(meanofmeans, 1, 2*N_parts), 'Color', [1 0.5 0], 'LineStyle', '-', 'LineWidth', 1.5);  % Mean line (orange

% Annotate UCL and LCL at the end of the axis range
text(ax4, 2*N_parts + 0.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 2*N_parts + 0.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
hold(ax4, 'on');

title(ax4, 'Mean Chart by Operator');
xlabel(ax4, 'Part');
ylabel(ax4, 'Mean');
xticks(ax4, 1:2*N_parts);
xticklabels(ax4, [1:N_parts 1:N_parts]);
xlim(ax4, [1 2*N_parts]);
grid(ax4, 'on');
hold(ax4, 'off');

% 5. Average for Each Part by Operator (Overlayed)
ax5 = subplot(4, 2, 5, 'Parent', f);
hold(ax5, 'on');
plot(ax5, 1:N_parts, mean_op1, 'b-o');  % Operator 1 Average
plot(ax5, 1:N_parts, mean_op2, 'r-o');  % Operator 2 Average
title(ax5, 'Average by Operator');
xlabel(ax5, 'Part');
ylabel(ax5, 'Mean');
grid(ax5, 'on');
hold(ax5, 'off');

% 6. Custom Boxplot Graph
ax6 = subplot(4, 2, 6, 'Parent', f);
hold(ax6, 'on')
% Combine data for operators
op1 = [measurements(:, 1); measurements(:, 2)];
op2 = [measurements(:, 3); measurements(:, 4)];

% Combine data into a cell array for iteration
data = {op1, op2};
n_groups = length(data);

% Function to calculate quartiles manually
calculate_quartile = @(sorted_data, q) ...
    interp1(1:length(sorted_data), sorted_data, q * (length(sorted_data) + 1), 'linear', 'extrap');

colors = {'b', 'r'}; % Blue for Operator A, Red for Operator B
% Plot each group
for i = 1:n_groups
    % Extract data for current group
    d = data{i};
    
    % Sort the data
    sorted_d = sort(d);
    
    % Compute statistics manually
    Q1 = calculate_quartile(sorted_d, 0.25); % First quartile
    Q3 = calculate_quartile(sorted_d, 0.75); % Third quartile
    IQR = Q3 - Q1;         % Interquartile range
    med = median(sorted_d); % Median
    mn = mean(sorted_d);   % Mean
    
    % Define whiskers (assuming default 1.5*IQR)
    lower_whisker = max(min(sorted_d), Q1 - 1.5 * IQR);
    upper_whisker = min(max(sorted_d), Q3 + 1.5 * IQR);
    
    % X positions for plotting
    x = i;
    
    % Plot box (IQR)
    rectangle(ax6,'Position', [x-0.2, Q1, 0.4, IQR], 'EdgeColor', colors{i}, 'LineWidth', 1.5);
    
    % Plot median
    plot(ax6,[x-0.2, x+0.2], [med, med], 'color',  colors{i}, 'LineWidth', 2);
    
    % Plot mean
    scatter(ax6, x, mn, 40, 'MarkerFaceColor', colors{i}, 'MarkerEdgeColor', colors{i});
    
    % Plot whiskers
    plot(ax6, [x, x], [lower_whisker, Q1], 'Color', colors{i}, 'LineWidth', 1.5); % Lower whisker
    plot(ax6, [x, x], [Q3, upper_whisker], 'Color', colors{i}, 'LineWidth', 1.5); % Upper whisker
    
    % Plot whisker caps
    plot(ax6,[x-0.1, x+0.1], [lower_whisker, lower_whisker], 'Color', colors{i}, 'LineWidth', 1.5); % Lower cap
    plot(ax6,[x-0.1, x+0.1], [upper_whisker, upper_whisker], 'Color', colors{i}, 'LineWidth', 1.5); % Upper cap
    
    % Plot outliers
    outliers = sorted_d(sorted_d < lower_whisker | sorted_d > upper_whisker);
    scatter(ax6,repmat(x, size(outliers)), outliers, 80, 'y+', 'LineWidth', 2);
end
% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0, 0.8, 0.15]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {176,176});
    
 % Main annotations
annotation('textbox', [0.1, 0.22, 0.8, 0.1], ...
    'String', { ['Operator A = \color{black}']}, ...
    'Interpreter', 'tex', 'EdgeColor', 'none', ...
    'FontSize', 12, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

annotation('textbox', [0.1, 0.2, 0.8, 0.1], ...
    'String', { ['Operator B = \color{black}']}, ...
    'Interpreter', 'tex', 'EdgeColor', 'none', ...
    'FontSize', 12, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

% Separate annotation for large stars
annotation('textbox', [0.22, 0.225, 0.1, 0.1], ...
    'String', { ['\color{blue} *']}, ...
    'Interpreter', 'tex', 'EdgeColor', 'none', ...
    'FontSize', 25, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');

annotation('textbox', [0.22, 0.205, 0.1, 0.1], ...
    'String', { ['\color{red} *']}, ...
    'Interpreter', 'tex', 'EdgeColor', 'none', ...
    'FontSize', 25, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top');
% Set x-ticks and labels
set(gca, 'XTick', 1:n_groups, 'XTickLabel', {'Operator 1', 'Operator 2'});
xlabel('Operator');
ylabel('Measurement');
title(' Boxplot by Operator');
grid on;

SDlimit
titleString = sprintf('Gage R&R -  %s, SDlimit = %.8f %s', feature, SDlimit,units);

% Set the title
sgtitle(f, titleString);;


filename = sprintf('%d-EPG %s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');

if measurementIdx == 1
    repeatabilitysd1 = repeatability_sd;
    
  
elseif measurementIdx == 2
   repeatabilitysd2 = repeatability_sd;
    
   
    elseif measurementIdx == 3
    repeatabilitysd3 = repeatability_sd;
    
   
end

close all;

end
% Check dcdd_tolerance_repeatability and assign result
if repeatabilitysd1 < SDlimit1
    result1 = {'Pass '};
elseif repeatabilitysd1 >= SDlimit1
    result1 = {'Fail '};
else
    result1 = {'Fail'};
end

if isnan(repeatabilitysd1)
    result1 = {'Missing data'};
end

if loops ==2
% Check dcrd_tolerance_repeatability and assign result
if repeatabilitysd2 < SDlimit2
    result2 = {'Pass '};
elseif repeatabilitysd2 >= SDlimit2
    result2 = {'Fail '};
else
    result2 = {'Fail'};
end
if isnan(repeatabilitysd2)
    result2 = {'Missing data'};
end
end

if loops ==3
% Check dcrd_tolerance_repeatability and assign result
if repeatabilitysd3 < SDlimit3
    result3 = {'Pass '};
elseif repeatabilitysd3 >= SDlimit3
    result3 = {'Fail'};
else
    result3 = {'Fail'};
end
if isnan(repeatabilitysd3)
    result3 = {'Missing data'};
end

end


% Summary table - 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if numValues == 10
    data1 = [SDlimit1; repeatabilitysd1; result1];
    % Create the table
    summary_table = table(data1, ...
                          'VariableNames', {'feature1'}, ...
                          'RowNames', {'SD Limit', 'Repeatability SD', 'Pass/Fail'});
    app.UITable8_9.Data = summary_table;
elseif numValues == 20
    data1 = [SDlimit1; repeatabilitysd1; result1];
    data2 = [SDlimit2; repeatabilitysd2; result2];
    summary_table = table(data1, data2, ...
                          'VariableNames', {'featureName1', 'featureName2'}, ...
                          'RowNames', {'SD Limit', 'Repeatability SD', 'Pass/Fail'});
                      elseif numValues == 30
    data1 = [SDlimit1; repeatabilitysd1; result1];
    data2 = [SDlimit2; repeatabilitysd2; result2];
    data3 = [SDlimit3; repeatabilitysd3; result3];
    summary_table = table(data1, data2,data3, ...
                          'VariableNames', {'feature1', 'feature2', 'feature3'}, ...
                          'RowNames', {'SD Limit', 'Repeatability SD', 'Pass/Fail'});
end
app.UITable8_9.Data = summary_table;
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph

% Create a new figure
summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots
summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

% Bar Chart Data
if numValues == 10
    % Ensure featureName1 is a character array, then convert to a cell array
    featureNamesCellArray = {char(featureName1)};
    summary_barx = categorical(featureNamesCellArray);
    
    % Set the data for the bar chart
    summary_bary = [repeatabilitysd1];
    
elseif numValues == 20
    % Ensure both featureName1 and featureName2 are character arrays, then convert to a cell array
    featureNamesCellArray = {char(featureName1), char(featureName2)};
    summary_barx = categorical(featureNamesCellArray);
    
    % Set the data for the bar chart
    summary_bary = [repeatabilitysd1, repeatabilitysd2];
    elseif numValues == 30
    % Ensure both featureName1 and featureName2 are character arrays, then convert to a cell array
    featureNamesCellArray = {char(featureName1), char(featureName2),char(featureName3)};
    summary_barx = categorical(featureNamesCellArray);
    
    % Set the data for the bar chart
    summary_bary = [repeatabilitysd1, repeatabilitysd2,repeatabilitysd3];
end

% Plot Bar Chart
bar(summary_ax1, summary_barx, summary_bary);
title(summary_ax1, '% SD per Measurement');
ylabel(summary_ax1, 'Standard Deviation');
title(summary_ax1, 'Components of Variation');

% Create a panel for the table
summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);

if numValues == 10 %change column widths depending on results
% Create a uitable within the panel
summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {454});
elseif numValues ==20 
    % Create a uitable within the panel
summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {227,227});
                      elseif numValues ==20 
    % Create a uitable within the panel
summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {150,150,150});
end

% Print the figure to a PDF file
summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('5-EPG-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

close all;
%%combine pdfs to report
% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_EPG R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

 %closes all figure windows that have been created
        end

        % Button pushed function: RunanalysisButton_6
        function RunanalysisButton_6Pushed2(app, event)
             % Get user inputs
             % Get user inputs
            
            gname = app.GageNameSNEditField_6.Value;
         
            operator = app.OperatorEditField_6.Value;
            misc = app.CompanyEditField_6.Value;
            sigma_level = app.SigmaLevelEditField_6.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_6.Value;
            
            dcdd_tolerancepercentage = app.CanDomeDepthToleranceEditField_2.Value;
            dcrd_tolerancepercentage = app.CanMaxReformDiameterToleranceEditField.Value;
            dccv_tolerancepercentage = app.CanChuckValueToleranceEditField.Value;
            dcrh_tolerancepercentage =   app.CanReformHeightToleranceEditField.Value;
        
            graph = app.ShowResultsforDropDown_5.Value; 


          % Clear previous table data
        app.UITable2_5.Data = {};
     
         %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'DRG');


    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_6.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
      baseDirectory = fullfile([drive ':'],'Matlab','DRG');
end

% Create the folder name using the variables
folderName = sprintf('DRG Can-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc; sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - DRG Can', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - DRG Can');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-DRG Can-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


% Combine the folder path with the filename
filePath2 = fullfile(folderPath2, 'RR_Can.csv');

for i = 1:4
          

filePath2 = fullfile(folderPath2, 'RR_Can.csv');
       if i == 1
    try
        measurements = readmatrix(filePath2, 'Range', 'B2:B31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Dome Depth';
    pdfno = 2;
    tolerance_percentage = dcdd_tolerancepercentage;

elseif i == 2
    try
        measurements = readmatrix(filePath2, 'Range', 'C2:C31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Reform Diameter';
    pdfno = 3;
    tolerance_percentage = dcrd_tolerancepercentage;

elseif i == 3
    try
        measurements = readmatrix(filePath2, 'Range', 'G2:G31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Chuck Value';
    pdfno = 4;
    tolerance_percentage = dccv_tolerancepercentage;

elseif i == 4
    try
        measurements = readmatrix(filePath2, 'Range', 'D2:D31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Can Reform Height';
    pdfno = 5;
    tolerance_percentage = dcrh_tolerancepercentage;
       end

part1column = measurements(1:6);
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp;


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('DRG %s - ANOVA Tables', feature))

    filename = sprintf('%d-DRG %sANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R - DRG %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf('%s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-DRG %s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');


if i == 1
    dcdd_tolerance_repeatability = tolerance_repeatability;
    dcdd_tolerance = tolerance;
    dcdd_sv_repeatability = sv_repeatability;
elseif i == 2
    dcrd_tolerance_repeatability = tolerance_repeatability;
    dcrd_tolerance = tolerance;
    dcrd_sv_repeatability = sv_repeatability;
elseif i == 3
    dccv_tolerance_repeatability = tolerance_repeatability;
    dccv_tolerance = tolerance;
    dccv_sv_repeatability = sv_repeatability;
elseif i == 4
    dcrh_tolerance_repeatability = tolerance_repeatability;
    dcrh_tolerance = tolerance;
    dcrh_sv_repeatability = sv_repeatability;

end
end
    
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%pass/fail checks

% Check dcdd_tolerance_repeatability and assign result
if dcdd_tolerance_repeatability < 10
    dcdd_result = {'Pass (<10%)'};
elseif dcdd_tolerance_repeatability >= 30
    dcdd_result = {'Fail (>30%)'};
else
    dcdd_result = {'Pass (10%-30%)'};
end

% Check dcrd_tolerance_repeatability and assign result
if dcrd_tolerance_repeatability < 10
    dcrd_result = {'Pass (<10%)'};
elseif dcrd_tolerance_repeatability >= 30
    dcrd_result = {'Fail (>30%)'};
else
    dcrd_result = {'Pass (10%-30%)'};
end

% Check dccv_tolerance_repeatability and assign result
if dccv_tolerance_repeatability < 10
    dccv_result = {'Pass (<10%)'};
elseif dccv_tolerance_repeatability >= 30
    dccv_result = {'Fail (>30%)'};
else
    dccv_result = {'Pass (10%-30%)'};
end

% Check dcrh_tolerance_repeatability and assign result
if dcrh_tolerance_repeatability < 10
    dcrh_result = {'Pass (<10%)'};
elseif dcrh_tolerance_repeatability >= 30
    dcrh_result = {'Fail (>30%)'};
else
    dcrh_result = {'Pass (10%-30%)'};
end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %check if missing data
 
 % Check dcdd_tolerance_repeatability and assign result
if isnan(dcdd_tolerance_repeatability)
    dcdd_result = {'Missing data'};
end

% Check dcrd_tolerance_repeatability and assign result
if isnan(dcrd_tolerance_repeatability)
    dcrd_result = {'Missing data'};
end

% Check dccv_tolerance_repeatability and assign result
if isnan(dccv_tolerance_repeatability)
    dccv_result = {'Missing data'};
end

% Check dcrh_tolerance_repeatability and assign result
if isnan(dcrh_tolerance_repeatability)
    dcrh_result = {'Missing data'};
end


%summary table - 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

can_dome_depth_data = [dcdd_tolerancepercentage; dcdd_sv_repeatability; dcdd_tolerance_repeatability; dcdd_result];
can_reform_diameter_data = [dcrd_tolerancepercentage; dcrd_sv_repeatability; dcrd_tolerance_repeatability; dcrd_result];
can_chuck_value_data = [dccv_tolerancepercentage; dccv_sv_repeatability; dccv_tolerance_repeatability; dccv_result];
can_reform_height_data = [dcrh_tolerancepercentage; dcrh_sv_repeatability; dcrh_tolerance_repeatability; dcrh_result];


% Create the table
summary_table = table(can_dome_depth_data, can_reform_diameter_data, can_chuck_value_data, can_reform_height_data,  ...
                      'VariableNames', {'C DomeDepth', 'C ReformDiameter', 'C ChuckValue', 'C ReformHeight'}, ...
                      'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
              app.UITable8_5.Data = summary_table;
             

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data
   
   
    summary_barx = categorical(["Can DomeDepth", "Can ReformDiameter", "Can ChuckValue", "Can ReformHeight"]);
    summary_bary = [dcdd_tolerance_repeatability, dcrd_tolerance_repeatability, dccv_tolerance_repeatability, dcrh_tolerance_repeatability];
   
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {133,133,133,133})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('6-DRG Can-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_DRG Can R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);
% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);


close all; %closes all figure windows that have been created


        end

        % Button pushed function: RunanalysisButton_7
        function RunanalysisButton_7Pushed(app, event)
              
            % Get user inputs
            
            gname = app.GageNameSNEditField_9.Value;
            
            operator = app.OperatorEditField_9.Value;
            misc = app.CompanyEditField_7.Value;
            sigma_level = app.SigmaLevelEditField_7.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_7.Value;
           
            
            fsph_tolerancepercentage = app.SettingPieceHeightToleranceEditField_2.Value;
            fspdd_tolerancepercentage = app.SettingPieceDomeDepthToleranceEditField_3.Value;
            graph = app.ShowResultsforDropDown_7.Value; 
            
            % Clear previous plots
        cla(app.UIAxes_7, 'reset');
        cla(app.UIAxes2_7, 'reset');
        cla(app.UIAxes3_7, 'reset');
        cla(app.UIAxes4_7, 'reset');
    
          % Clear previous table data
        app.UITable2_7.Data = {};
    
          %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Frontend');

    
    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_10.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
   baseDirectory = fullfile([drive ':'],'Matlab','Frontend');
end

% Create the folder name using the variables
folderName = sprintf('Frontend SP-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc; sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - Frontend Setting Piece', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Frontend SP');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-Frontend SP-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


for i = 1:2
           
       if i == 1
    try
        filePath2 = fullfile(folderPath2, 'RR_SP.csv');
        measurements = readmatrix(filePath2, 'Range', 'C2:C31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Height';
    pdfno = 2;
    tolerance_percentage = fsph_tolerancepercentage;

elseif i == 2
    try
        filePath2 = fullfile(folderPath2, 'RR_SP.csv');
        measurements = readmatrix(filePath2, 'Range', 'E2:E31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'Setting Piece Dome Depth';
    pdfno = 3;
    tolerance_percentage = fspdd_tolerancepercentage;
       end

part1column = measurements(1:6);
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('FRONTEND SP %s - ANOVA Tables', feature))

    filename = sprintf('%d-%s-ANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R SP -  %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf(' %s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-%s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');


 if i == 1
    
     fsph_tolerance_repeatability = tolerance_repeatability;
     fsph_tolerance = tolerance
    fsph_sv_repeatability = sv_repeatability
 

elseif i == 2
    fspdd_tolerance_repeatability = tolerance_repeatability
     fspdd_tolerance = tolerance
    fspdd_sv_repeatability = sv_repeatability
 end 
end


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
%pass/fail checks


% Check fsph_tolerance_repeatability and assign result
if fsph_tolerance_repeatability < 30
    fsph_result = {'Pass (<30%)'};
else
    fsph_result = {'Fail (>=30%)'};
end

% Check fspdd_tolerance_repeatability and assign result
if fspdd_tolerance_repeatability < 30
    fspdd_result = {'Pass (<30%)'};
else
    fspdd_result = {'Fail (>=30%)'};
end


 %check if missing data
 

if isnan(fsph_tolerance_repeatability)
    fsph_result = {'Missing data'};
end

if isnan(fspdd_tolerance_repeatability)
    fspdd_result = {'Missing data'};
end

%summary table - adjusted to whether reform results are included


SPHeight_data = [fsph_tolerancepercentage; fsph_sv_repeatability; fsph_tolerance_repeatability; fsph_result];
SPDome_Depth_data = [fspdd_tolerancepercentage; fspdd_sv_repeatability; fspdd_tolerance_repeatability; fspdd_result];
% Create the table
summary_table = table( SPHeight_data, SPDome_Depth_data, ...
                      'VariableNames', {'SP Height', 'SP Dome Depth'}, ...
                      'RowNames', {'Tol(mm)', 'RR(mm)', 'RR(%)', 'Pass/Fail'});
              app.UITable8_6.Data = summary_table;
              

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data

   
    summary_barx = categorical([ "SP Height", "SP DomeD"]);
    summary_bary = [ fsph_tolerance_repeatability, fspdd_tolerance_repeatability];
    
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {267,267})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('4-Frontend SP-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Frontend SP R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all; %closes all figure windows that have been created
        end

        % Button pushed function: UpdateButton_7
        function UpdateButton_7Pushed(app, event)
              %%script for when update button is pressed - each features
             %%code begins with if statement to check if its selected in
             %%drop down, if it is it calculates results again and plots


% Get user inputs
            gname = app.GageNameSNEditField_9.Value;
           % date = app.ddEditField_9.Value;
            operator = app.OperatorEditField_9.Value;
            misc = app.CompanyEditField_7.Value;
            sigma_level = app.SigmaLevelEditField_7.Value;
          
           
            fsph_tolerancepercentage = app.SettingPieceHeightToleranceEditField_2.Value;
            fspdd_tolerancepercentage = app.SettingPieceDomeDepthToleranceEditField_3.Value;
            graph = app.ShowResultsforDropDown_7.Value; 
            
            % Clear previous plots
    cla(app.UIAxes_7, 'reset');
     cla(app.UIAxes2_7, 'reset');
      cla(app.UIAxes3_7, 'reset');
       cla(app.UIAxes4_7, 'reset');
    
    % Clear previous table data
    app.UITable2_7.Data = {};
    
    %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Frontend');


    if strcmp(graph, 'Setting Piece Height')
                filePath2 = fullfile(folderPath2, 'RR_SP.csv');
    measurements = readmatrix(filePath2, 'range', 'C2:C31');
     c_tolerance_percentage = fsph_tolerancepercentage
    elseif strcmp(graph, 'Setting Piece Dome Depth')
        filePath2 = fullfile(folderPath2, 'RR_SP.csv');
     measurements = readmatrix(filePath2, 'range', 'E2:E31');
      c_tolerance_percentage = fspdd_tolerancepercentage
           
        end
     
 c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;

c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;

c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;


c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp


c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;

% Create table1
c_table1 = table({gname}, {date}, {operator}, {c_tolerance_percentage}, {misc}, ...
    'VariableNames', {'Gage Name', 'Date of Study', 'Reported by', 'Tolerance', 'Misc'});

% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_7.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_7, 'Percentage Tolerance');
bar(app.UIAxes_7, c_barx, c_bary);
title(app.UIAxes_7, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_7, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_7, 'on');
plot(app.UIAxes2_7, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_7, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_7, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_7, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_7, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_7, 'Sample Range');
title(app.UIAxes2_7, 'R Chart');
ytickformat(app.UIAxes2_7, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_7, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_7, 'on');
plot(app.UIAxes3_7, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_7, 'Feature');
title(app.UIAxes3_7, 'Feature vs Part');
xlabel(app.UIAxes3_7, 'Part No.');

ytickformat(app.UIAxes3_7, '%.3f');

% Mean chart
plot(app.UIAxes4_7, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_7, 'on');
plot(app.UIAxes4_7, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_7, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_7, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_7, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_7, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_7, 'Sample Mean');
title(app.UIAxes4_7, 'XBar Chart');
xlabel(app.UIAxes4_7, 'Part No.');
ytickformat(app.UIAxes4_7, '%.4f');
        end

        % Button pushed function: RunanalysisButton_8
        function RunanalysisButton_8Pushed(app, event)
          % Get user inputs
            
            gname = app.GageNameSNEditField_10.Value;
            
            operator = app.OperatorEditField_10.Value;
            misc = app.CompanyEditField_8.Value;
            sigma_level = app.SigmaLevelEditField_8.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_8.Value;
            
           
            dspdd_tolerancepercentage = app.SettingPieceDomeDepthToleranceEditField_4.Value;
            dsprd_tolerancepercentage = app.SettingPieceMaxReformDiameterToleranceEditField_2.Value;
            dspcv_tolerancepercentage = app.SettingPieceChuckValueToleranceEditField_2.Value
            graph = app.ShowResultsforDropDown_8.Value; 


          % Clear previous table data
        app.UITable2_8.Data = {};
     
           %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'DRG');
    
    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_11.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
   baseDirectory = fullfile([drive ':'],'Matlab','DRG');
end

% Create the folder name using the variables
folderName = sprintf('DRG SP-R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc; sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - DRG SP', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - DRG SP');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-DRG SP-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


% Combine the folder path with the filename
      filePath2 = fullfile(folderPath2, 'RR_SP.csv');

for i = 1:3

if i == 1
    try
        measurements = readmatrix(filePath2, 'Range', 'B2:B31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'SP Dome Depth';
    pdfno = 2;
    tolerance_percentage = dspdd_tolerancepercentage;

elseif i == 2
    try
        measurements = readmatrix(filePath2, 'Range', 'C2:C31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'SP Reform Diameter';
    pdfno = 3;
    tolerance_percentage = dsprd_tolerancepercentage;

elseif i == 3
    try
        measurements = readmatrix(filePath2, 'Range', 'G2:G31');
    catch
        measurements = NaN(30, 1);
    end
    feature = 'SP Chuck Value';
    pdfno = 4;
    tolerance_percentage = dspcv_tolerancepercentage;
end

part1column = measurements(1:6);
part2column = measurements(7:12);
part3column = measurements(13:18);
part4column = measurements(19:24);
part5column = measurements(25:30);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6;
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp;


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth',{130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('DRG %s - ANOVA Tables', feature))

    filename = sprintf('%d-DRG %sANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R - DRG %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf('%s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-DRG %s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');


if i == 1
    dspdd_tolerance_repeatability = tolerance_repeatability;
    dspdd_tolerance = tolerance;
    dspdd_sv_repeatability = sv_repeatability;
elseif i == 2
    dsprd_tolerance_repeatability = tolerance_repeatability;
    dsprd_tolerance = tolerance;
    dsprd_sv_repeatability = sv_repeatability;
elseif i == 3
    dspcv_tolerance_repeatability = tolerance_repeatability;
    dspcv_tolerance = tolerance;
    dspcv_sv_repeatability = sv_repeatability;
end
end
    
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%pass/fail checks


% Check dspdd_tolerance_repeatability and assign result
if dspdd_tolerance_repeatability < 10
    dspdd_result = {'Pass (<20%)'};
elseif dspdd_tolerance_repeatability >= 30
    dspdd_result = {'Fail (>30%)'};
else
    dspdd_result = {'Pass (20%-30%)'};
end

% Check dsprd_tolerance_repeatability and assign result
if dsprd_tolerance_repeatability < 10
    dsprd_result = {'Pass (<20%)'};
elseif dsprd_tolerance_repeatability >= 30
    dsprd_result = {'Fail (>30%)'};
else
    dsprd_result = {'Pass (20%-30%)'};
end

% Check dspcv_tolerance_repeatability and assign result
if dspcv_tolerance_repeatability < 10
    dspcv_result = {'Pass (<20%)'};
elseif dspcv_tolerance_repeatability >= 30
    dspcv_result = {'Fail (>30%)'};
else
    dspcv_result = {'Pass (20%-30%)'};
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %check if missing data


% Check dspdd_tolerance_repeatability and assign result
if isnan(dspdd_tolerance_repeatability)
    dspdd_result = {'Missing data'};
end

% Check dsprd_tolerance_repeatability and assign result
if isnan(dsprd_tolerance_repeatability)
    dsprd_result = {'Missing data'};
end

% Check dspcv_tolerance_repeatability and assign result
if isnan(dspcv_tolerance_repeatability)
    dspcv_result = {'Missing data'};
end

%summary table - 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

sp_dome_depth_data = [dspdd_tolerancepercentage; dspdd_sv_repeatability; dspdd_tolerance_repeatability; dspdd_result];
sp_reform_diameter_data = [dsprd_tolerancepercentage; dsprd_sv_repeatability; dsprd_tolerance_repeatability; dsprd_result];
sp_chuck_height_data = [dspcv_tolerancepercentage; dspcv_sv_repeatability; dspcv_tolerance_repeatability; dspcv_result];

% Create the table
summary_table = table( sp_dome_depth_data , sp_reform_diameter_data, sp_chuck_height_data,  ...
                      'VariableNames', { 'SP Dome Depth', 'SP Reform Diameter', 'SP Chuck Value'}, ...
                      'RowNames', {'Tol(mm)', 'RR (mm)', 'RR (%)', 'Pass/Fail'});
              app.UITable8_7.Data = summary_table;
             

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data
   
   
    summary_barx = categorical([ "SP DomeDepth", "SP ReformDiameter", "SP ChuckValue"]);
    summary_bary = [ dspdd_tolerance_repeatability,dsprd_tolerance_repeatability,dspcv_tolerance_repeatability];
   
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {178,178,178})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('5-DRG SP-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_DRG SP R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);


close all; %closes all figure windows that have been created


        end

        % Button pushed function: UpdateButton_8
        function UpdateButton_8Pushed(app, event)
            %%script for when update button is pressed - each features
             %%code begins with if statement to check if its selected in
             %%drop down, if it is it calculates results again and plots


% Get user inputs
            gname = app.GageNameSNEditField_10.Value;
            %date = app.ddEditField_10.Value;
            operator = app.OperatorEditField_10.Value;
            misc = app.CompanyEditField_8.Value;
            sigma_level = app.SigmaLevelEditField_8.Value;
           
            dspdd_tolerancepercentage = app.SettingPieceDomeDepthToleranceEditField_4.Value
            dsprd_tolerancepercentage = app.SettingPieceMaxReformDiameterToleranceEditField_2.Value
            dspcv_tolerancepercentage = app.SettingPieceChuckValueToleranceEditField_2.Value
            graph = app.ShowResultsforDropDown_8.Value; 
            
            % Clear previous plots
    cla(app.UIAxes_8, 'reset');
     cla(app.UIAxes2_8, 'reset');
      cla(app.UIAxes3_8, 'reset');
       cla(app.UIAxes4_8, 'reset');
    
    % Clear previous table data
    app.UITable2_8.Data = {};
    
     %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'DRG');


            if  strcmp(graph, 'Setting Piece Dome Depth')
                filePath2 = fullfile(folderPath2, 'RR_SP.csv');
    measurements = readmatrix(filePath2, 'range', 'B2:B31');
     c_tolerance_percentage = dspdd_tolerancepercentage
    elseif strcmp(graph, 'Setting Piece Max Reform Diameter')
        filePath2 = fullfile(folderPath2, 'RR_SP.csv');
     measurements = readmatrix(filePath2, 'range', 'C2:C31');
      c_tolerance_percentage = dsprd_tolerancepercentage
            elseif strcmp(graph, 'Setting Piece Chuck Value')
                filePath2 = fullfile(folderPath2, 'RR_SP.csv');
     measurements = readmatrix(filePath2, 'range', 'G2:G31');
      c_tolerance_percentage = dspcv_tolerancepercentage
           
        end
     
 c_part1column = measurements(1:6);
c_part2column = measurements(7:12);
c_part3column = measurements(13:18);
c_part4column = measurements(19:24);
c_part5column = measurements(25:30);

% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;

c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;

c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;

c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end
c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp


c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;


% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_8.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_8, 'Percentage Tolerance');
bar(app.UIAxes_8, c_barx, c_bary);
title(app.UIAxes_8, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_8, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_8, 'on');
plot(app.UIAxes2_8, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_8, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_8, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_8, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_8, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_8, 'Sample Range');
title(app.UIAxes2_8, 'R Chart');
ytickformat(app.UIAxes2_8, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_8, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_8, 'on');
plot(app.UIAxes3_8, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_8, 'Feature');
title(app.UIAxes3_8, 'Feature vs Part');
xlabel(app.UIAxes3_8, 'Part No.');

ytickformat(app.UIAxes3_8, '%.3f');

% Mean chart
plot(app.UIAxes4_8, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_8, 'on');
plot(app.UIAxes4_8, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_8, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_8, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_8, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_8, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_8, 'Sample Mean');
title(app.UIAxes4_8, 'XBar Chart');
xlabel(app.UIAxes4_8, 'Part No.');
ytickformat(app.UIAxes4_8, '%.4f');

        end

        % Button pushed function: RunanalysisButton_9
        function RunanalysisButton_9Pushed(app, event)
            referenceValues  = [app.Measure1EditField_2.Value,app.Measure2EditField_2.Value,app.Measure3EditField_2.Value]    
            % Get user inputs
            linearity = app.IncludeLinearityCertificateinreportCheckBox_2.Value
            gname = app.GageNameSNEditField_11.Value;
            
            operator = app.OperatorEditField_11.Value;
            misc = app.CompanyEditField_9.Value;
            sigma_level = app.SigmaLevelEditField_9.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_9.Value;
           
            ic_tolerancepercentage = app.InternalCoatingToleranceEditField_2.Value;
            ec_tolerancepercentage = app.ExternalCoatingToleranceEditField.Value;
           
     
            graph = app.ShowResultsforDropDown_9.Value; 


                    %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Optical Filmweight');
    
    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_12.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Optical Filmweight');
end

% Create the folder name using the variables
folderName = sprintf('Optical Filmweight - R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

try
if linearity ==1 
        % Define file paths for the CSV files
filePaths = {
    'Matlab\Optical Filmweight\Nist1.csv', 
    'Matlab\Optical Filmweight\Nist2.csv', 
    'Matlab\Optical Filmweight\Nist3.csv'};
% Construct full paths
filePaths = cellfun(@(folder) fullfile([drive ':'], folder), filePaths, 'UniformOutput', false);

% Initialize an empty cell array to hold the combined data
combinedData = {};
validFileCount = 0
usedReferences = [];

% Loop through each file path and read the data
for i = 1:length(filePaths)
    if isfile(filePaths{i});
    data = readtable(filePaths{i}, 'ReadVariableNames', false, 'Format', '%s');
    validFileCount = validFileCount + 1;
    % Extract data from the single column and append to combinedData
    combinedData = [combinedData; data.Var1]; % Append column-wise
     usedReferences = [usedReferences, referenceValues(i)]
    end
end
        dataArray = combinedData
        

        % Define parameters
        numGroups = validFileCount;
        numMeasurements = 8;
% Convert usedReferences to a column vector
reference = usedReferences(:)


        linearitytolerancepositive = app.ToleranceumEditField_3.Value;
        linearitytolerancenegative = app.ToleranceumEditField_4.Value

        % Initialize variables
       groupData = cell(numGroups, 8);

        % Parsing data
        currentIndex = 1;
       for group = 1:numGroups
    if currentIndex <= length(dataArray)
        for measurement = 1:8  % 8 measurements expected
            % Ensure there's enough data for the current group and measurement
            if currentIndex + 5 <= length(dataArray)
                groupData{group, measurement} = str2double(dataArray(currentIndex + 3 : currentIndex + 5));
            else
                groupData{group, measurement} = NaN(3, 1); % Fill with NaN if not enough data
            end
            currentIndex = currentIndex + 8; % Move to the next set of data
        end
    else
        % If there's no data for this group, fill with NaNs
        groupData{group, :} = {NaN(3, 1)};  % Fill all measurements with NaN
    end
       end

        % Calculate statistics for each group
        averages = zeros(numGroups, 1);
        mins = zeros(numGroups, 1)
        maxs = zeros(numGroups, 1)
        ranges = zeros(numGroups, 1);
        biases = zeros(numGroups, numMeasurements);
        PassFail = cell(numGroups, 1)
averageBias = zeros(numGroups, 1);

        % For storing confidence intervals
        upperCI = zeros(numGroups, 1);
        lowerCI = zeros(numGroups, 1);

        for group = 1:numGroups
            allValues = [];
            % Concatenate all measurements into a single array for the group
    for measurement = 1:8  % 8 measurements expected
        if ismatrix(groupData{group, measurement}) && ~isempty(groupData{group, measurement})
            allValues = [allValues; groupData{group, measurement}];
        end
    end
    % Exclude NaN values
    allValues = allValues(~isnan(allValues))
    
            if ~isempty(allValues)
                averages(group) = mean(allValues(:));
                mins(group) = min(allValues(:));
                maxs(group) = max(allValues(:));
                ranges(group) = maxs(group) - mins(group);
                
                % Compute biases
                biases(group, :) = mean(reshape(allValues, [], 8), 1) - reference(group);
                
                % Calculate average bias
                averageBias(group) = mean(biases(group, :));
                
                % Determine pass/fail
                if any(biases(group, :) > linearitytolerancepositive | biases(group, :) < -linearitytolerancenegative)
            PassFail{group} = 'Fail';
        else
            PassFail{group} = 'Pass';
                end
                else
        % If all values are NaN, set defaults
        averages(group) = NaN;
        mins(group) = NaN;
        maxs(group) = NaN;
        ranges(group) = NaN;
        averageBias(group) = NaN;
        PassFail{group} = 'Not Applicable'; % No data to evaluate
            end
        end


        % Create table with results
        resultsTable = table(reference, averages, mins, maxs, ranges, averageBias, PassFail, ...
            'RowNames',  arrayfun(@(x) sprintf('Group %d', x), 1:numGroups, 'UniformOutput', false));

        % Create figure and panels
        intro_f = figure('Name', 'Gage R&R Study - Filmscan', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);


        % Create panels for plotting and table
        plot_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.25, 0.9, 0.65]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.01, 0.9, 0.15]);

        % Create the table
        uitable('Parent', table_panel, 'Data', [num2cell(reference), num2cell(averages), num2cell(mins), num2cell(maxs), num2cell(ranges), num2cell(averageBias), PassFail], ...
            'ColumnName', resultsTable.Properties.VariableNames, 'RowName', resultsTable.Properties.RowNames, ...
            'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {91, 91, 91, 91, 91, 91, 91});

        % Create a new axis in the plot panel
        ax = axes('Parent', plot_panel);

        % Plot biases
        hold(ax, 'on');

        % Perform linear regression using polyfit only if there are valid data points
if numGroups > 0
    % Perform linear regression
    [p, S] = polyfit(reference, averageBias, 1); % p contains the coefficients, S contains the structure with stats

    % Generate fitted values
    xfit = linspace(min(reference), max(reference), 100);
    [yfit, delta] = polyval(p, xfit, S); % yfit are the fitted values, delta gives the confidence intervals

    % Standard error of the regression (S)
    S_value = sqrt(S.normr^2 / S.df); % normr is the norm of residuals, df is degrees of freedom

    % Plot data points with legend entry
    Data = scatter(ax, reference, averageBias, 50, 'b', 'filled', 'DisplayName', 'Data');

    % Plot regression line with legend entry
    Regress = plot(ax, xfit, yfit, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Regression');

    % Plot confidence intervals with legend entry
    CIHigh = plot(ax, xfit, yfit + delta, 'g--', 'LineWidth', 1, 'DisplayName', '95% CI');
    CILow = plot(ax, xfit, yfit - delta, 'g--', 'LineWidth', 1);

    % Plot biases for each group (only for groups that have data)
    for group = 1:numGroups
        if any(~isnan(biases(group, :))) % Check if there's any valid bias data
            plot(reference(group) * ones(numMeasurements, 1), biases(group, :), 'o', 'Color', [173/255, 216/255, 230/255]);
        end
    end
end
        % Plot a dummy point to handle the legend entry for 'Avg Bias'
        AvgBias = plot(NaN, NaN, 'o', 'Color', [173/255, 216/255, 230/255]);

        % Customize plot
        xlabel(ax, 'Reference Value');
        ylabel(ax, 'Bias');
        title(ax, 'Linearity and Bias Report');

        % Add legend only if data exists
legendEntries = {};
legendLabels = {};

if exist('Data', 'var')
    legendEntries{end+1} = Data;
    legendLabels{end+1} = 'Data';
end
if exist('Regress', 'var')
    legendEntries{end+1} = Regress;
    legendLabels{end+1} = 'Regression';
end
if exist('CIHigh', 'var')
    legendEntries{end+1} = CIHigh;
    legendLabels{end+1} = '95% CI';
end
legendEntries{end+1} = AvgBias;
legendLabels{end+1} = 'Avg Bias';

if ~isempty(legendEntries)
    legend(ax, [legendEntries{:}], legendLabels, 'Location', 'northeastoutside');
end

        hold(ax, 'off');

        % Create the filename with the desired format
        filename = sprintf('2- Optical Filmweight -Linearity+Bias Report,%s,%s,%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all
end
catch
end

LP = app.IncludeLPanalyisinReportCheckBox.Value

try
if LP == 1


% File prefixes (A to G)
filePrefixes = 'A':'G';

% Initialize an empty table to store the results with the correct column types
resultsCellArray = cell(0, 7);

% Loop through each file (LPA.csv to LPG.csv)
for i = 1:length(filePrefixes)
    % Construct the full file path for the current CSV file
    fileName = ['LP', filePrefixes(i), '.csv'];
    filePath = fullfile(folderPath2, fileName);
    
    % Try to read the file, if it doesn't exist, skip it
    try
        % Read the CSV file without variable names
        if exist(filePath, 'file') == 2
            data = readtable(filePath, 'ReadVariableNames', false, 'Format', '%s');
        else
        
            continue; % Skip the file if not found
        end
        
        % Extract data from the single column
        dataArray = data.Var1;
        
        % Debug: Check if dataArray contains any data
        if isempty(dataArray)
            
            continue;
        end
        
        % Extract every 3rd value
        lpmeasurements = dataArray(3:3:end);
        
        % Convert cell array of strings to numeric values
        numericValues = cellfun(@str2double, lpmeasurements);
        
        % Remove NaN values (which were non-numeric in the original data)
        numericValues = numericValues(~isnan(numericValues));
        
        % Debug: Check if numericValues has any valid values
        if isempty(numericValues)
            
            continue;
        end
        
        % Calculate required statistics
        meanValue = mean(numericValues);
        stdevValue = std(numericValues);
        minValue = min(numericValues);
        medianValue = median(numericValues);
        maxValue = max(numericValues);
        rangeValue = max(numericValues) - min(numericValues);


          % Create a new row in cell format
        newRow = {char(fileName), num2str(meanValue), num2str(stdevValue), num2str(minValue), ...
                   num2str(medianValue), num2str(maxValue), num2str(rangeValue)};
        % Append the new row to the resultsTable
        resultsCellArray = [resultsCellArray; newRow];
        
    catch ME
        % If file is not found or there's an error, skip to the next one
       
        continue;
    end
end


%% Create Intro Table 
intro_table = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company'}, ...
                   {gname; date; operator; misc}, ...
                   'VariableNames', {'Description', 'Value'});
               
% Create figure and panels
        intro_f = figure('Name', 'Lacquer PLate Analysis', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
        
% Add a title to the figure
sgtitle('Filmscan - Lacquer Plate Analysis');
      
        % Create panels for plotting and table
        intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.6, 0.9, 0.25]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.2, 0.9, 0.35]);

        % Create the table
       uitable('Parent', intro_panel, 'Data', intro_table{:,:}, 'ColumnName', intro_table.Properties.VariableNames, 'RowName', intro_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 550});


       % Convert all numeric values in resultsCellArray to strings
resultsCellArray(:, 2:end) = cellfun(@num2str, resultsCellArray(:, 2:end), 'UniformOutput', false);

% Create the uitable with cell data

    uitable('Parent', table_panel, 'Data', resultsCellArray, ...
        'ColumnName', {'Filename', 'Mean', 'StdDev', 'Min', 'Median', 'Max', 'Range'}, ...
        'RowName', [], ... % Use default row names
        'Units', 'Normalized', ...
        'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 75, 75, 75, 75, 75, 75}); 
    
 % Create the filename with the desired format
        filename = sprintf('3-Optical Filmweight-Lacquer Plate Analysis,%s,%s,%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close(intro_f)
end
catch
end

%% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company';'Sigma'}, ...
                   {gname; date; operator; misc; sigma_level}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - Optical Filmweight', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - Optical Filmweight');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, 'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 348});

filename = sprintf('1-Optical Filmweight-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');


for i = 1:2


      if i == 1
    filePath2 = fullfile(folderPath2, 'RR_Ext.csv');
    try
        allmeasurements = readmatrix(filePath2, 'Range', 'D2:D481');
    catch
        allmeasurements = NaN(480, 1);
    end
    feature = 'External Coating';
    pdfno = 4;
    tolerance_percentage = ec_tolerancepercentage;

elseif i == 2
    filePath2 = fullfile(folderPath2, 'RR_Int.csv');
    try
        allmeasurements = readmatrix(filePath2, 'Range', 'D2:D481');
    catch
        allmeasurements = NaN(480, 1);
    end
    feature = 'Internal Coating';
    pdfno = 5;
    tolerance_percentage = ic_tolerancepercentage;
      end


%Reshape the data into a matrix with 16 rows
reshapedData = reshape(allmeasurements, 16, [])

%  Calculate the mean of each column
groupMeans = mean(reshapedData)
finalMatrix = zeros(5, 6);

% Fill each column with every 6th value from groupMeans
for j = 1:5
    finalMatrix(j, :) = groupMeans(j:5:end);
end

%transpose
finalMatrix = finalMatrix.';
measurements = finalMatrix(:);

part1column = finalMatrix(:, 1);
part2column = finalMatrix(:, 2);
part3column = finalMatrix(:, 3);
part4column = finalMatrix(:, 4);
part5column = finalMatrix(:, 5);

% Mean calculations
part1_mean = mean(part1column);
part2_mean = mean(part2column);
part3_mean = mean(part3column);
part4_mean = mean(part4column);
part5_mean = mean(part5column);
allmeans = [part1_mean, part2_mean, part3_mean, part4_mean, part5_mean];
meanofmeans = mean(allmeans);
mean_all = mean(measurements);

% ANOVA calculations
% degrees of freedom            
partsdf = 4;
repeatabilitydf = 25;
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = 6 * sum((allmeans - mean_all).^2);

% Repeatability ss - summed squared difference of each part
repeatabilityss = sum((measurements - repelem(allmeans', 6)).^2);

% Total sum of squares            
totalss = partss + repeatabilityss;

% mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / 6
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp


% standard deviation
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

% Create R&R Tables
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('Optical Filmweight %s - ANOVA Tables', feature))

    filename = sprintf('%d-%s-ANOVA,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
end

% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure 

% Create a new figure
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Create subplots

% Components of variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');
title_str = sprintf('Gage R&R -  %s; - Tolerance = %.2f (mm/inch)', feature, tolerance_percentage);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:5;
ranges = [max(part1column) - min(part1column), ...
          max(part2column) - min(part2column), ...
          max(part3column) - min(part3column), ...
          max(part4column) - min(part4column), ...
          max(part5column) - min(part5column)];
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, 5));
plot(ax2, rangex, repmat(ucl, 1, 5), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, 5), 'Color', [0 1 0]);

text(ax2, 5.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, 5.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:5, 6);
scattery = measurements;

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:5, allmeans, '-o', 'Color', [1, 0.5, 0]);
formattedLabel = sprintf(' %s', feature);
ylabel(ax3, formattedLabel);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.')
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:5, allmeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:5, repmat(meanofmeans, 1, 5));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(6)));

plot(ax4, 1:5, repmat(ucl2, 1, 5), 'Color', [0 1 0]);
plot(ax4, 1:5, repmat(lcl2, 1, 5), 'Color', [0 1 0]);

text(ax4, 5.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, 5.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.')
ytickformat(ax4, '%.4f');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

f.Position = [200, 200, 800, 600]; % increased figure size to stop title being cut off

filename = sprintf('%d-%s,%s,%s,%s.pdf', pdfno, feature, gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(f, filePath, '-dpdf');


 if i == 1
    ec_tolerance_repeatability = tolerance_repeatability;
    ec_tolerance = tolerance
    ec_sv_repeatability = sv_repeatability
elseif i == 2
    ic_tolerance_repeatability = tolerance_repeatability
     ic_tolerance = tolerance
    ic_sv_repeatability = sv_repeatability

 end 
end


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
%pass/fail checks

% Check fch_tolerance_repeatability and assign result
if ec_tolerance_repeatability < 30
    ec_result = {'Pass (<30%)'};
else
    ec_result = {'Fail (>=30%)'};
end

% Check fcdd_tolerance_repeatability and assign result
if ic_tolerance_repeatability < 30
    ic_result = {'Pass (<30%)'};
else
    ic_result = {'Fail (>=30%)'};
end


 %check if missing data
 
if isnan(ec_tolerance_repeatability)
    ec_result = {'Missing data'};
end

if isnan(ic_tolerance_repeatability)
    ic_result = {'Missing data'};
end


%summary table - 


ec_data = [ec_tolerancepercentage; ec_sv_repeatability; ec_tolerance_repeatability; ec_result];
ic_data = [ic_tolerancepercentage; ic_sv_repeatability; ic_tolerance_repeatability; ic_result];

% Create the table
summary_table = table( ec_data,ic_data, ...
                      'VariableNames', {'External Coating', 'Internal Coating'}, ...
                      'RowNames', {'Tol(mm)', 'RR(mm)', 'RR(%)', 'Pass/Fail'});
              app.UITable8_8.Data = summary_table;
              

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Figure for summary table and tolerance graph


% Create a new figure
    summary_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    % Create subplots
    summary_ax1 = subplot(2, 1, 1, 'Parent', summary_f); % Axes for the Bar Chart

    % Bar Chart Data

   
    summary_barx = categorical(["External Coating", 'Internal Coating']);
    summary_bary = [ec_tolerance_repeatability, ic_tolerance_repeatability];
    
    
    % Plot Bar Chart
    bar(summary_ax1, summary_barx, summary_bary);
    title(summary_ax1, '% Repeatability Tolerance per Measurement');
    ylabel(summary_ax1, 'Tolerance (%)');
    title(summary_ax1, 'Components of Variation');
    
    % Create a panel for the table
    summary_panel = uipanel('Parent', summary_f, 'Position', [0.1, 0.05, 0.8, 0.3]);


    % Create a uitable within the panel
    summary_uitable = uitable('Parent', summary_panel, 'Data', summary_table{:,:}, 'ColumnName', summary_table.Properties.VariableNames, ...
                          'RowName', summary_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {267,267})
    
    % Print the figure to a PDF file
    
    summary_f.Position = [200, 200, 800, 600]; 
filename = sprintf('6-Optical Filmweight-Summary,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(summary_f, filePath, '-dpdf');

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_Optical Filmweight R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all; %closes all figure windows that have been created

        end

        % Callback function
        function CloseAllButton_2Pushed(app, event)
            close all
        end

        % Button pushed function: UpdateButton_9
        function UpdateButton_9Pushed(app, event)
             %%script for when update button is pressed - each features
             %%code begins with if statement to check if its selected in
             %%drop down, if it is it calculates results again and plots


  % Get user inputs
            
            gname = app.GageNameSNEditField_11.Value;
            
            operator = app.OperatorEditField_11.Value;
            misc = app.CompanyEditField_9.Value;
            sigma_level = app.SigmaLevelEditField_9.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_9.Value;
           
            ic_tolerancepercentage = app.InternalCoatingToleranceEditField_2.Value;
            ec_tolerancepercentage = app.ExternalCoatingToleranceEditField.Value;
           
     
            graph = app.ShowResultsforDropDown_9.Value; 
          
            % Clear previous plots
    cla(app.UIAxes_9, 'reset');
     cla(app.UIAxes2_9, 'reset');
      cla(app.UIAxes3_9, 'reset');
       cla(app.UIAxes4_9, 'reset');
    
    % Clear previous table data
    app.UITable2_9.Data = {};


  %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Optical Filmweight');
    
    if strcmp(graph, 'Internal Coating')
        filePath2 = fullfile(folderPath2, 'RR_Int.csv');
    allmeasurements = readmatrix(filePath2, 'range', 'D2:D481');
    c_tolerance_percentage = ic_tolerancepercentage
    elseif strcmp(graph, 'External Coating')
    filePath2 = fullfile(folderPath2, 'RR_Ext.csv');
    allmeasurements = readmatrix(filePath2, 'range', 'D2:D481');
     c_tolerance_percentage = ec_tolerancepercentage
        end
     
        %Reshape the data into a matrix with 16 rows
reshapedData = reshape(allmeasurements, 16, [])

%  Calculate the mean of each column
groupMeans = mean(reshapedData);
finalMatrix = zeros(5, 6);

% Fill each column with every 6th value from groupMeans
for j = 1:5
    finalMatrix(j, :) = groupMeans(j:5:end);
end

%transpose
finalMatrix = finalMatrix.';
measurements = finalMatrix(:);

c_part1column = finalMatrix(:, 1);
c_part2column = finalMatrix(:, 2);
c_part3column = finalMatrix(:, 3);
c_part4column = finalMatrix(:, 4);
c_part5column = finalMatrix(:, 5);


% Mean calculations
c_part1_mean = mean(c_part1column);
c_part2_mean = mean(c_part2column);
c_part3_mean = mean(c_part3column);
c_part4_mean = mean(c_part4column);
c_part5_mean = mean(c_part5column);
c_allmeans = [c_part1_mean, c_part2_mean, c_part3_mean, c_part4_mean, c_part5_mean];
c_meanofmeans = mean(c_allmeans);
c_mean_all = mean(measurements);

% ANOVA calculations
c_partsdf = 4;
c_repeatabilitydf = 25;
c_totaldf = c_repeatabilitydf + c_partsdf;

c_partss = 6 * sum((c_allmeans - c_mean_all).^2);
c_repeatabilityss = sum((measurements - repelem(c_allmeans', 6)).^2);
c_totalss = c_partss + c_repeatabilityss;

c_partms = c_partss / c_partsdf;
c_repeatabilityms = c_repeatabilityss / c_repeatabilitydf;
c_F = c_partms / c_repeatabilityms;


c_repeatabilityvarcomp = c_repeatabilityms;
c_part2partvarcomp = (c_partms - c_repeatabilityms) / 6
if c_part2partvarcomp < 0
    c_part2partvarcomp = 0
end

c_totalvar = c_repeatabilityvarcomp + c_part2partvarcomp

c_repeatabilitysd = sqrt(c_repeatabilityvarcomp);
c_totalsd = sqrt(c_totalvar);
c_part2partsd = sqrt(c_part2partvarcomp);

% Study variance
c_sv_repeatability = c_repeatabilitysd * sigma_level;
c_sv_part2part = c_part2partsd * sigma_level;
c_sv_totalvariation = c_totalsd * sigma_level;
c_repeatability_percentSV = (c_sv_repeatability / c_sv_totalvariation) * 100;
c_part2part_percentSV = (c_sv_part2part / c_sv_totalvariation) * 100;
c_totalvariation_percentSV = 100;

% Tolerance calculations
c_tolerance = c_tolerance_percentage / 100;
c_tolerance_repeatability = c_sv_repeatability / c_tolerance;
c_tolerance_part2part = c_sv_part2part / c_tolerance;
c_tolerance_totalvariation = c_sv_totalvariation / c_tolerance;

% Create table1
c_table1 = table({gname}, {date}, {operator}, {c_tolerance_percentage}, {misc}, ...
    'VariableNames', {'Gage Name', 'Date of Study', 'Reported by', 'Tolerance', 'Misc'});

% Create table 4
c_table4 = table([c_repeatabilitysd; c_part2partsd; c_totalsd], ...
    [c_sv_repeatability; c_sv_part2part; c_sv_totalvariation], ...
    [c_repeatability_percentSV; c_part2part_percentSV; c_totalvariation_percentSV], ...
    [c_tolerance_repeatability; c_tolerance_part2part; c_tolerance_totalvariation], ...
    'VariableNames', {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'}, ...
    'RowNames', {'Gage R&R', 'Part-to-Part', 'Total Variation'});

% Display table1+4 in the UI Table
app.UITable2_9.Data = c_table4;

% Plot the data
c_barx = categorical(["Repeatability", "Part-to-Part"]);
c_bary = [c_tolerance_repeatability, c_tolerance_part2part];

ylabel(app.UIAxes_9, 'Percentage Tolerance');
bar(app.UIAxes_9, c_barx, c_bary);
title(app.UIAxes_9, 'Components of Variation');

%% Sample Range Graph
c_rangex = 1:5;
c_ranges = [max(c_part1column) - min(c_part1column), ...
            max(c_part2column) - min(c_part2column), ...
            max(c_part3column) - min(c_part3column), ...
            max(c_part4column) - min(c_part4column), ...
            max(c_part5column) - min(c_part5column)];
c_rangemean = mean(c_ranges);

c_ucl = c_rangemean + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl = c_rangemean - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes2_9, c_rangex, c_ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_9, 'on');
plot(app.UIAxes2_9, c_rangex, repmat(c_rangemean, 1, 5));
plot(app.UIAxes2_9, c_rangex, repmat(c_ucl, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes2_9, c_rangex, repmat(c_lcl, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes2_9, 5.5, c_ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes2_9, 5.5, c_lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes2_9, 'Sample Range');
title(app.UIAxes2_9, 'R Chart');
ytickformat(app.UIAxes2_9, '%.4f');

%% Scatter Graph
c_scatterx = repelem(1:5, 6);
c_scattery = measurements;
scatter(app.UIAxes3_9, c_scatterx, c_scattery, 'b');
hold(app.UIAxes3_9, 'on');
plot(app.UIAxes3_9, 1:5, c_allmeans, '-o', 'Color', [1, 0.5, 0]);

ylabel(app.UIAxes3_9, 'Feature');
title(app.UIAxes3_9, 'Feature vs Part');
xlabel(app.UIAxes3_9, 'Part No.');

ytickformat(app.UIAxes3_9, '%.3f');

% Mean chart
plot(app.UIAxes4_9, 1:5, c_allmeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_9, 'on');
plot(app.UIAxes4_9, 1:5, repmat(c_meanofmeans, 1, 5));

c_ucl2 = c_meanofmeans + ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));
c_lcl2 = c_meanofmeans - ((sigma_level / 2) * (c_repeatabilitysd / sqrt(6)));

plot(app.UIAxes4_9, 1:5, repmat(c_ucl2, 1, 5), 'Color', [0 1 0]);
plot(app.UIAxes4_9, 1:5, repmat(c_lcl2, 1, 5), 'Color', [0 1 0]);

text(app.UIAxes4_9, 5.5, c_ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(app.UIAxes4_9, 5.5, c_lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(app.UIAxes4_9, 'Sample Mean');
title(app.UIAxes4_9, 'XBar Chart');
xlabel(app.UIAxes4_9, 'Part No.');
ytickformat(app.UIAxes4_9, '%.4f');
        end

        % Button pushed function: RunanalysisButton_10
        function RunanalysisButton_10Pushed(app, event)
% Clear previous plots
    cla(app.UIAxes_10, 'reset');
     cla(app.UIAxes2_10, 'reset');
      cla(app.UIAxes3_10, 'reset');
       cla(app.UIAxes4_10, 'reset');
            
            
% Step 1: Get user inputs
feature = app.FeatureNameEditField.Value;
sigma_level = app.SigmaLevelEditField_10.Value;
tolerance_percentage = app.ToleranceEditField_2.Value;

%%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
excelFilePath = fullfile([drive ':'], 'Matlab', 'Custom','custom.xlsx');
FolderPath = fullfile([drive ':'], 'Matlab', 'Custom');
gname = app.GageNameSNEditField_12.Value;

operator = app.OperatorEditField_12.Value;
misc = app.MiscEditField.Value;
show_ANOVA_table = app.ShowAnovatablesCheckBox_10.Value;


%date = sprintf('%s-%s-%s', dd, mm, yy);

% Check if repeats are vertical or horizontal
switch1 = app.Switch.Value; 

% Step 2: Import data from Excel
data = readmatrix(excelFilePath)
[numRows, numCols] = size(data);

if strcmp(switch1, 'Repeats Vertically')
    % Data format: Repeats along columns
    numRepeats = numRows;
    numSamples = numCols;
else
    % Data format: Repeats along rows
    data = data'; % Transpose data to align with the expected format
    numRepeats = numCols;
    numSamples = numRows;
end

% Step 3: Calculate Means
sampleMeans = mean(data);
overallMean = mean(data(:));

% Step 4: ANOVA calculations
% Degrees of Freedom
partsdf = numSamples - 1;
repeatabilitydf = numSamples * (numRepeats - 1);
totaldf = repeatabilitydf + partsdf;

% Part sum of squares
partss = numRepeats * sum((sampleMeans - overallMean).^2);

% Repeatability sum of squares
repeatabilityss = sum(sum((data - repelem(sampleMeans, numRepeats, 1)).^2));

% Total sum of squares
totalss = partss + repeatabilityss;

% Mean squares
repeatabilityms = repeatabilityss / repeatabilitydf;
partms = partss / partsdf;
F = partms / repeatabilityms;

% Variance components
repeatabilityvarcomp = repeatabilityms;
part2partvarcomp = (partms - repeatabilityms) / numRepeats
if part2partvarcomp < 0
    part2partvarcomp = 0
end
totalvar = repeatabilityvarcomp + part2partvarcomp


% Standard deviations
repeatabilitysd = sqrt(repeatabilityvarcomp);
totalsd = sqrt(totalvar);
part2partsd = sqrt(part2partvarcomp);

% Study variance
sv_repeatability = repeatabilitysd * sigma_level;
sv_part2part = part2partsd * sigma_level;
sv_totalvariation = totalsd * sigma_level;

% Percent study variation
repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
totalvariation_percentSV = 100;

% Tolerance calculations
tolerance = tolerance_percentage / 100;
tolerance_repeatability = sv_repeatability / tolerance;
tolerance_part2part = sv_part2part / tolerance;
tolerance_totalvariation = sv_totalvariation / tolerance;

% Step 5: Generate and Save ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; NaN], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});

table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});

% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV), sprintf('%.2f', tolerance_repeatability);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV), sprintf('%.2f', tolerance_part2part);
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV), sprintf('%.2f', tolerance_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV', '%Tol (SV/Tol)'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

app.UITable2_10.Data = table4;

if show_ANOVA_table == 1
    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('Custom R&R - %s - ANOVA Tables', feature))

    filename = sprintf('Custom-%s-ANOVA,%s,%s,%s.pdf', feature, gname, date, operator);    

    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    filePath = fullfile(FolderPath, filename);
    print(anova_f, filePath, '-dpdf');
end

% Components of Variation Graph

barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [tolerance_repeatability, tolerance_part2part];
bar(app.UIAxes_10, barx, bary);
ylabel(app.UIAxes_10, 'Tolerance (%)');
title(app.UIAxes_10, 'Components of Variation');

% Sample Range Graph

ranges = max(data) - min(data); % Calculate ranges manually
plot(app.UIAxes2_10, 1:numSamples, ranges, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes2_10, 'on');

meanRange = mean(ranges);
ucl = meanRange + ((sigma_level / 2) * (repeatabilitysd / sqrt(numRepeats)));
lcl = meanRange - ((sigma_level / 2) * (repeatabilitysd / sqrt(numRepeats)));

plot(app.UIAxes2_10, 1:numSamples, repmat(meanRange, 1, numSamples));
plot(app.UIAxes2_10, 1:numSamples, repmat(ucl, 1, numSamples),   'Color', [0 1 0]);
plot(app.UIAxes2_10, 1:numSamples, repmat(lcl, 1, numSamples),   'Color', [0 1 0]);
ylabel(app.UIAxes2_10, 'Sample Range');
title(app.UIAxes2_10, 'R Chart');

% Scatter Plot

scatterx = repelem(1:numSamples, numRepeats);
scatter(app.UIAxes3_10, scatterx, data(:), 'MarkerFaceColor','b');
hold(app.UIAxes3_10, 'on')
plot(app.UIAxes3_10, 1:numSamples, sampleMeans, '-o', 'Color', [1, 0.5, 0]);
ylabel(app.UIAxes3_10, feature);
title(app.UIAxes3_10, sprintf('%s vs Part', feature));
xlabel(app.UIAxes3_10, 'Part No.');
% Mean Chart

plot(app.UIAxes4_10, 1:numSamples, sampleMeans, '-o', 'MarkerFaceColor', 'b');
hold(app.UIAxes4_10, 'on')
plot(app.UIAxes4_10, 1:numSamples, repmat(overallMean, 1, numSamples));

ucl2 = overallMean + ((sigma_level / 2) * (repeatabilitysd / sqrt(numRepeats)));
lcl2 = overallMean - ((sigma_level / 2) * (repeatabilitysd / sqrt(numRepeats)));

plot(app.UIAxes4_10, 1:numSamples, repmat(ucl2, 1, numSamples), 'Color', [0 1 0]);
plot(app.UIAxes4_10, 1:numSamples, repmat(lcl2, 1, numSamples), 'Color', [0 1 0]);

ylabel(app.UIAxes4_10, 'Sample Mean');
title(app.UIAxes4_10, 'XBar Chart');
xlabel(app.UIAxes4_10, 'Part No.');

%% Create a new figure 
f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

% Components of Variation Graph
ax1 = subplot(3, 2, 1, 'Parent', f);
bar(ax1, barx, bary);
ylabel(ax1, 'Tolerance (%)');
title(ax1, 'Components of Variation');

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f);
plot(ax2, 1:numSamples, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, 1:numSamples, repmat(meanRange, 1, numSamples));
plot(ax2, 1:numSamples, repmat(ucl, 1, numSamples), 'Color','g');
plot(ax2, 1:numSamples, repmat(lcl, 1, numSamples), 'Color','g');
ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');

% Scatter Plot
ax3 = subplot(3, 2, 3, 'Parent', f);
scatter(ax3, scatterx, data(:),'MarkerFaceColor', 'b');
hold(ax3, 'on');
plot(ax3, 1:numSamples, sampleMeans, '-o', 'Color', [1, 0.5, 0]);
ylabel(ax3, feature);
title(ax3, sprintf('%s vs Part', feature));

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f);
plot(ax4, 1:numSamples, sampleMeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:numSamples, repmat(overallMean, 1, numSamples));
plot(ax4, 1:numSamples, repmat(ucl2, 1, numSamples), 'Color', [0 1 0]);
plot(ax4, 1:numSamples, repmat(lcl2, 1, numSamples), 'Color', [0 1 0]);

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {116,116,116,116});

% Adjust figure size to prevent title cut-off
f.Position = [200, 200, 800, 600];

% Save the figure as a PDF
filename = sprintf('%s Custom R&R,%s,,%s,%s,%s.pdf', feature, gname, date, operator, misc);    
filePath = fullfile(FolderPath, filename);
print(f, filePath, '-dpdf');
close all;
        end

        % Button pushed function: UpdateButton_10
        function UpdateButton_10Pushed(app, event)
      cla(app.UIAxes_11, 'reset');
cla(app.UIAxes2_11, 'reset');
cla(app.UIAxes4_11, 'reset');
cla(app.UIAxes2_12, 'reset');
cla(app.UIAxes4_12, 'reset');
cla(app.UIAxes4_13, 'reset');
            flip = app.Usecodetoflipresultsinstack0204CheckBox.Value
            
            %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'EPG');

            sigma_level = app.SigmaLevelEditField_11.Value
            Imperial = app.MeaurementsinImperialCheckBox.Value
            

graph = app.ShowResultsforDropDown_10.Value
% Initialize the measurements matrices
measurements1 = zeros(10, 4);  % 10 rows and 4 columns for the first set of values
measurements2 = zeros(10, 4);  % 10 rows and 4 columns for the second set of values
measurements3 = zeros(10, 4);  % 10 rows and 4 columns for the second set of values

% Loop through each file to populate the matrices
for i = 1:4
    % Construct the file name
    fileName = sprintf('stack%02d.xls', i);
    
    % Construct the full file path
    filePath = fullfile(folderPath, fileName);
    
    % Read the data from column D starting from row 11
    data = readmatrix(filePath, 'Range', 'D11:D40'); % D11:D30 to account for up to 20 values
    
    % Check the number of values read (either 10 or 20)
    numValues = length(data)
    
    if numValues == 10
        % If there are 10 values, proceed as usual
        columnData1 = data;
        columnData2 = [];
        columnData3 = [];
    elseif numValues == 20
        % If there are 20 values, split the data into two sets
        columnData1 = data(1:2:end); % Every other value starting from the 1st
        columnData2 = data(2:2:end); % Every other value starting from the 2nd
        columnData3 = [];
        elseif numValues == 30
             columnData1 = data(1:3:end) % Every other value starting from the 1st
        columnData2 = data(2:3:end) % Every other value starting from the 2nd
        columnData3 = data(3:3:end) % Every other value starting from the 2nd
    else
        error('Unexpected number of values in column D.');
    end
    
    % Flip the data if it's from stack02.xls or stack04.xls
    
   if flip == 1
    if i == 2 || i == 4
        columnData1 = flipud(columnData1);
        if ~isempty(columnData2)
            columnData2 = flipud(columnData2);
        end
        if ~isempty(columnData3)
            columnData3 = flipud(columnData3);
        end
    end
   end
    
    % Assign the data to the corresponding columns in the measurements matrices
    measurements1(:, i) = columnData1
    if ~isempty(columnData2)
        measurements2(:, i) = columnData2
    end
    if ~isempty(columnData3)
        measurements3(:, i) = columnData3
    end
end
% Initialize feature names
featureName1 = '';  % Feature name for measurements1
featureName2 = '';  % Feature name for measurements2    
featureName3 = '';  % Feature name for measurements2   
fileName = 'stack01.xls';
filePath = fullfile(folderPath, fileName);

% Read the feature names from cells C11 and C12
featureName1 = readcell(filePath, 'Range', 'C11')
featureName2 = readcell(filePath, 'Range', 'C12')
featureName3 = readcell(filePath, 'Range', 'C13')

% If featureName1 and featureName2 are returned as cell arrays, extract the content
if iscell(featureName1)
    featureName1 = featureName1{1}; % Extract the string from the cell
end
if iscell(featureName2)
    featureName2 = featureName2{1}; % Extract the string from the cell
end
if iscell(featureName3)
    featureName3 = featureName3{1}; % Extract the string from the cell
end


    if graph == 'Feature 1'
        measurements = measurements1
        featuretol = app.Feature1DropDown.Value
        customtol = app.EditField.Value
    elseif graph == 'Feature 2'
        measurements = measurements2
         featuretol = app.Feature2DropDown.Value
         customtol = app.EditField_2.Value
          elseif graph == 'Feature 3'
        measurements = measurements3
         featuretol = app.Feature3DropDown.Value
         customtol = app.EditField_3.Value
    end
    
    if strcmp(featuretol, 'Bubble/Button Height')
    FeatureTol = 0.12;
elseif strcmp(featuretol, 'Bubble/Button Thickness')
    FeatureTol = 0.03;
elseif strcmp(featuretol, 'Button Coining')
    FeatureTol = 0.024;
elseif strcmp(featuretol, 'Bead Coining')
    FeatureTol = 0.03;
elseif strcmp(featuretol, 'Rivet Diameter')
    FeatureTol = 0.16;
elseif strcmp(featuretol, 'Rivet Residual')
    FeatureTol = 0.03;
elseif strcmp(featuretol, 'Form Stiffening')
    FeatureTol = 0.12;
elseif strcmp(featuretol, 'Stolle Step')
    FeatureTol = 0.16;
elseif strcmp(featuretol, 'Score Residual')
    FeatureTol = 0.016;
elseif strcmp(featuretol, 'Custom (Entered to the right)')
    FeatureTol = 1;
    elseif strcmp(featuretol, 'N/A')
    FeatureTol = 0;
end

if Imperial == 1
FeatureTol = FeatureTol /25.399986284
end

if strcmp(featuretol ,'Custom (Entered to the right)')
FeatureTol = customtol
end

SDlimit = ((30* (FeatureTol/100))/sigma_level)

% Calculate the mean of every two horizontal values
rowMeans = mean(reshape(measurements', 2, []), 1)';

% Repeat each mean twice
result = repelem(rowMeans, 2);


op1  = [measurements(:, 1); measurements(:, 2)]
op2  = [measurements(:, 3); measurements(:, 4)]
ops = [mean(op1) mean(op2)]
% Parameters
N_parts = 10;
N_operators = 2;
N_repeats = 2;  % Each operator has 2 test repeats

% Flatten the matrix and generate factor levels
data = reshape(measurements', [], 1)  % Flatten data into a column vector
parts = repmat((1:N_parts)', N_operators * N_repeats, 1)  % Part indices
operators = [repmat(1, N_parts * N_repeats, 1); repmat(2, N_parts * N_repeats, 1)];  % Operator indices

% Grand Mean
grand_mean = mean(data)


% Total Sum of Squares (SS_T)
SS_T = sum((data - grand_mean).^2);

% Mean of Each Part
mean_parts = mean(measurements, 2)  % Average across all operators for each part

% Sum of Squares for Parts (SS_P)
SS_P = N_operators * N_repeats * sum((mean_parts - grand_mean).^2);

% Mean of Each Operator

% Mean across all parts and repeats for each operator

% Sum of Squares for Operators (SS_O)
SS_O = N_parts * N_repeats * sum((ops' - grand_mean).^2);

% Compute the mean of each row
row_means = mean(measurements, 2);


% Repeatability Sum of Squares (SS_R)
SS_R = SS_T - SS_P - SS_O

% Degrees of Freedom
df_P = N_parts - 1;
df_O = N_operators - 1;
df_T = (N_repeats * N_operators * N_parts) - 1;
df_R = df_T - df_P - df_O

% Mean Squares
MS_P = SS_P / df_P;
MS_O = SS_O / df_O;
MS_R = SS_R / df_R;

% Variance Components
repeatability_var = MS_R;
operator_var = (MS_O - MS_R) / (N_parts * N_repeats);
if operator_var < 0
    operator_var = 0;
end
part2part_var = (MS_P - MS_R) / (N_operators*N_repeats);
if part2part_var < 0
    part2part_var = 0;
end
total_var = repeatability_var + part2part_var + operator_var;

% Standard Deviations
repeatability_sd = sqrt(repeatability_var);
operator_sd = sqrt(operator_var);
part2part_sd = sqrt(part2part_var);
total_sd = sqrt(total_var);

% Tolerance and Study Variance

sv_repeatability = repeatability_sd * sigma_level; % sigma level multiplier
sv_operator = operator_sd * sigma_level;
sv_part2part = part2part_sd * sigma_level;
sv_totalvariation = total_sd * sigma_level;


% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatability_sd), sprintf('%.7f', sv_repeatability);
    sprintf('%.7f', part2part_sd), sprintf('%.7f', sv_part2part); 
    sprintf('%.7f', operator_sd), sprintf('%.7f', sv_operator);
    sprintf('%.7f', total_sd), sprintf('%.7f', sv_totalvariation)
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)'};
rowNames = {'Repeatability', 'Part-to-Part','Operator', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

app.UITable2_11.Data = table4

% 1. Components of Variation Bar Chart
bar(app.UIAxes_11, [repeatability_sd, part2part_sd, operator_sd, total_sd]);
title(app.UIAxes_11, 'Components of Variation');
xticks(app.UIAxes_11, 1:4);
xticklabels(app.UIAxes_11, {'Repeatability', 'Operator', 'Part-to-Part', 'Total Variation'});
ylabel(app.UIAxes_11, 'SD');
grid(app.UIAxes_11, 'on');
xtickangle(app.UIAxes_11, 45);

% 2. Measurements per Part Scatter Plot
scatterparts = [1; 1; 1; 1; 2; 2; 2; 2; 3; 3; 3; 3; 4; 4; 4; 4; ...
                5; 5; 5; 5; 6; 6; 6; 6; 7; 7; 7; 7; 8; 8; 8; 8; ...
                9; 9; 9; 9; 10; 10; 10; 10];
hold(app.UIAxes2_11, 'on');
scatter(app.UIAxes2_11, scatterparts, data, 'b');  % Measurements
plot(app.UIAxes2_11, 1:N_parts, mean_parts, '-r', 'LineWidth', 1.5);  % Average per part (red line)
title(app.UIAxes2_11, 'Measurements per Part');
xlabel(app.UIAxes2_11, 'Part');
ylabel(app.UIAxes2_11, 'Measurement');
grid(app.UIAxes2_11, 'on');
hold(app.UIAxes2_11, 'off');

% 3. R Chart by Operator (Manual Range Calculation)
ranges_op1 = max(measurements(:, 1:2), [], 2) - min(measurements(:, 1:2), [], 2);
ranges_op2 = max(measurements(:, 3:4), [], 2) - min(measurements(:, 3:4), [], 2);
hold(app.UIAxes4_11, 'on');
plot(app.UIAxes4_11, 1:N_parts, ranges_op1, 'b-o');  % Operator 1
plot(app.UIAxes4_11, N_parts+1:2*N_parts, ranges_op2, 'r-o');  % Operator 2
title(app.UIAxes4_11, 'R Chart by Operator');
xlabel(app.UIAxes4_11, 'Part');
ylabel(app.UIAxes4_11, 'Range');
xticks(app.UIAxes4_11, 1:2*N_parts);
xticklabels(app.UIAxes4_11, [1:N_parts 1:N_parts]);
xlim(app.UIAxes4_11, [1 2*N_parts]);
grid(app.UIAxes4_11, 'on');
hold(app.UIAxes4_11, 'off');

% 4. Mean Chart by Operator
mean_op1 = mean(measurements(:, 1:2), 2);
mean_op2 = mean(measurements(:, 3:4), 2);
hold(app.UIAxes2_12, 'on');
plot(app.UIAxes2_12, 1:N_parts, mean_op1, 'b-o');  % Operator 1 Mean
plot(app.UIAxes2_12, N_parts+1:2*N_parts, mean_op2, 'r-o');  % Operator 2 Mean
title(app.UIAxes2_12, 'Mean Chart by Operator');
xlabel(app.UIAxes2_12, 'Part');
ylabel(app.UIAxes2_12, 'Mean');
xticks(app.UIAxes2_12, 1:2*N_parts);
xticklabels(app.UIAxes2_12, [1:N_parts 1:N_parts]);
xlim(app.UIAxes2_12, [1 2*N_parts]);
grid(app.UIAxes2_12, 'on');
hold(app.UIAxes2_12, 'off');

% 5. Average for Each Part by Operator (Overlayed)
hold(app.UIAxes4_12, 'on');
plot(app.UIAxes4_12, 1:N_parts, mean_op1, 'b-o');  % Operator 1 Average
plot(app.UIAxes4_12, 1:N_parts, mean_op2, 'r-o');  % Operator 2 Average
title(app.UIAxes4_12, 'Average by Operator');
xlabel(app.UIAxes4_12, 'Part');
ylabel(app.UIAxes4_12, 'Mean');
grid(app.UIAxes4_12, 'on');
hold(app.UIAxes4_12, 'off');

% 6. Custom Boxplot Graph
hold(app.UIAxes4_13, 'on');
% Combine data for operators
op1 = [measurements(:, 1); measurements(:, 2)];
op2 = [measurements(:, 3); measurements(:, 4)];

% Combine data into a cell array for iteration
data = {op1, op2};
n_groups = length(data);

% Function to calculate quartiles manually
calculate_quartile = @(sorted_data, q) ...
    interp1(1:length(sorted_data), sorted_data, q * (length(sorted_data) + 1), 'linear', 'extrap');

colors = {'b', 'r'}; % Blue for Operator A, Red for Operator B
% Plot each group
for i = 1:n_groups
    % Extract data for current group
    d = data{i};
    
    % Sort the data
    sorted_d = sort(d);
    
    % Compute statistics manually
    Q1 = calculate_quartile(sorted_d, 0.25); % First quartile
    Q3 = calculate_quartile(sorted_d, 0.75); % Third quartile
    IQR = Q3 - Q1;         % Interquartile range
    med = median(sorted_d); % Median
    mn = mean(sorted_d);   % Mean
    
    % Define whiskers (assuming default 1.5*IQR)
    lower_whisker = max(min(sorted_d), Q1 - 1.5 * IQR);
    upper_whisker = min(max(sorted_d), Q3 + 1.5 * IQR);
    
    % X positions for plotting
    x = i;
    
    % Plot box (IQR)
    rectangle(app.UIAxes4_13, 'Position', [x-0.2, Q1, 0.4, IQR], 'EdgeColor', colors{i}, 'LineWidth', 1.5);
    
    % Plot median
    plot(app.UIAxes4_13, [x-0.2, x+0.2], [med, med], 'color',  colors{i}, 'LineWidth', 2);
    
    % Plot mean
    scatter(app.UIAxes4_13, x, mn, 40, 'MarkerFaceColor', colors{i}, 'MarkerEdgeColor', colors{i});
    
    % Plot whiskers
    plot(app.UIAxes4_13, [x, x], [lower_whisker, Q1], 'Color', colors{i}, 'LineWidth', 1.5); % Lower whisker
    plot(app.UIAxes4_13, [x, x], [Q3, upper_whisker], 'Color', colors{i}, 'LineWidth', 1.5); % Upper whisker
    
    % Plot whisker caps
    plot(app.UIAxes4_13, [x-0.1, x+0.1], [lower_whisker, lower_whisker], 'Color', colors{i}, 'LineWidth', 1.5); % Lower cap
    plot(app.UIAxes4_13, [x-0.1, x+0.1], [upper_whisker, upper_whisker], 'Color', colors{i}, 'LineWidth', 1.5); % Upper cap
    
    % Plot outliers
    outliers = sorted_d(sorted_d < lower_whisker | sorted_d > upper_whisker);
    scatter(app.UIAxes4_13, repmat(x, size(outliers)), outliers, 80, 'y+', 'LineWidth', 2);
end


        end

        % Button pushed function: RunIndividuallyButton
        function RunIndividuallyButtonPushed(app, event)
            gname = app.GageNameSNEditField_3.Value;
           
            operator = app.OperatorEditField_3.Value;
            misc = app.CompanyEditField_3.Value;
            sasigma_level = app.SliceAverageSigmaLevelEditField.Value;
            spsigma_level = app.SinglePointSigmaLevelEditField.Value;
            show_ANOVA_table = app.ShowAnovatablesCheckBox_3.Value;
            cd_tolerancepercentage = app.DomeToleranceEditField.Value;
            ce_tolerancepercentage = app.ExternalToleranceEditField.Value;
            ci_tolerancepercentage = app.InternalWallToleranceEditField.Value;
            cr_tolerancepercentage =   app.ReverseWallToleranceEditField.Value;
            crm_tolerancepercentage = app.RimCoatToleranceEditField.Value;
            graph = app.ShowResultsforDropDown_3.Value; 
            crsp_tolerancepercentage = cr_tolerancepercentage
             cisp_tolerancepercentage = ci_tolerancepercentage
              cesp_tolerancepercentage = ce_tolerancepercentage
               cdsp_tolerancepercentage = cd_tolerancepercentage
            linearity = app.IncludeLinearityCertificateinreportCheckBox.Value
            % Clear previous plots
        cla(app.UIAxes_3, 'reset');
        cla(app.UIAxes2_3, 'reset');
        cla(app.UIAxes3_3, 'reset');
        cla(app.UIAxes4_3, 'reset');
     referenceValues  = [app.Measure1EditField.Value,app.Measure2EditField.Value,app.Measure3EditField.Value]
       
          % Clear previous table data
        app.UITable2_3.Data = {};
    
               %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'Filmscan')
    
    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_3.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Filmscan');
end

% Create the folder name using the variables
folderName = sprintf('Linearity Test,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end
          % Define file paths for the CSV files
 filePaths = {
    'Matlab\Filmscan\Nist1.csv', 
    'Matlab\Filmscan\Nist2.csv', 
    'Matlab\Filmscan\Nist3.csv'};
% Construct full paths
filePaths = cellfun(@(folder) fullfile([drive ':'], folder), filePaths, 'UniformOutput', false);

% Initialize an empty cell array to hold the combined data
combinedData = {};
validFileCount = 0
usedReferences = [];

% Loop through each file path and read the data
for i = 1:length(filePaths)
    if isfile(filePaths{i});
    data = readtable(filePaths{i}, 'ReadVariableNames', false, 'Format', '%s');
    validFileCount = validFileCount + 1;
    % Extract data from the single column and append to combinedData
    combinedData = [combinedData; data.Var1]; % Append column-wise
     usedReferences = [usedReferences, referenceValues(i)]
    end
end
        dataArray = combinedData
        

        % Define parameters
        numGroups = validFileCount;
        numMeasurements = 8;
% Convert usedReferences to a column vector
reference = usedReferences(:)


        linearitytolerancepositive = app.ToleranceumEditField.Value;
        linearitytolerancenegative = app.ToleranceumEditField_2.Value

        % Initialize variables
       groupData = cell(numGroups, 8);

        % Parsing data
        currentIndex = 1;
       for group = 1:numGroups
    if currentIndex <= length(dataArray)
        for measurement = 1:8  % 8 measurements expected
            % Ensure there's enough data for the current group and measurement
            if currentIndex + 5 <= length(dataArray)
                groupData{group, measurement} = str2double(dataArray(currentIndex + 3 : currentIndex + 5));
            else
                groupData{group, measurement} = NaN(3, 1); % Fill with NaN if not enough data
            end
            currentIndex = currentIndex + 8; % Move to the next set of data
        end
    else
        % If there's no data for this group, fill with NaNs
        groupData{group, :} = {NaN(3, 1)};  % Fill all measurements with NaN
    end
       end

        % Calculate statistics for each group
        averages = zeros(numGroups, 1);
        mins = zeros(numGroups, 1)
        maxs = zeros(numGroups, 1)
        ranges = zeros(numGroups, 1);
        biases = zeros(numGroups, numMeasurements);
        PassFail = cell(numGroups, 1)
averageBias = zeros(numGroups, 1);

        % For storing confidence intervals
        upperCI = zeros(numGroups, 1);
        lowerCI = zeros(numGroups, 1);

        for group = 1:numGroups
            allValues = [];
            % Concatenate all measurements into a single array for the group
    for measurement = 1:8  % 8 measurements expected
        if ismatrix(groupData{group, measurement}) && ~isempty(groupData{group, measurement})
            allValues = [allValues; groupData{group, measurement}];
        end
    end
    % Exclude NaN values
    allValues = allValues(~isnan(allValues))
    
            if ~isempty(allValues)
                averages(group) = mean(allValues(:));
                mins(group) = min(allValues(:));
                maxs(group) = max(allValues(:));
                ranges(group) = maxs(group) - mins(group);
                
                % Compute biases
                biases(group, :) = mean(reshape(allValues, [], 8), 1) - reference(group);
                
                % Calculate average bias
                averageBias(group) = mean(biases(group, :));
                
                % Determine pass/fail
                if any(biases(group, :) > linearitytolerancepositive | biases(group, :) < -linearitytolerancenegative)
            PassFail{group} = 'Fail';
        else
            PassFail{group} = 'Pass';
                end
                else
        % If all values are NaN, set defaults
        averages(group) = NaN;
        mins(group) = NaN;
        maxs(group) = NaN;
        ranges(group) = NaN;
        averageBias(group) = NaN;
        PassFail{group} = 'Not Applicable'; % No data to evaluate
            end
        end


        % Create table with results
        resultsTable = table(reference, averages, mins, maxs, ranges, averageBias, PassFail, ...
            'RowNames',  arrayfun(@(x) sprintf('Group %d', x), 1:numGroups, 'UniformOutput', false));

        % Create figure and panels
        intro_f = figure('Name', 'Gage R&R Study - Filmscan', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);

        % Create panels for plotting and table
        plot_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.3, 0.9, 0.65]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.05, 0.9, 0.25]);

        % Create the table
        uitable('Parent', table_panel, 'Data', [num2cell(reference), num2cell(averages), num2cell(mins), num2cell(maxs), num2cell(ranges), num2cell(averageBias), PassFail], ...
            'ColumnName', resultsTable.Properties.VariableNames, 'RowName', resultsTable.Properties.RowNames, ...
            'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {91, 91, 91, 91, 91, 91, 91});

        % Create a new axis in the plot panel
        ax = axes('Parent', plot_panel);

        % Plot biases
        hold(ax, 'on');

        % Perform linear regression using polyfit only if there are valid data points
if numGroups > 0
    % Perform linear regression
    [p, S] = polyfit(reference, averageBias, 1); % p contains the coefficients, S contains the structure with stats

    % Generate fitted values
    xfit = linspace(min(reference), max(reference), 100);
    [yfit, delta] = polyval(p, xfit, S); % yfit are the fitted values, delta gives the confidence intervals

    % Standard error of the regression (S)
    S_value = sqrt(S.normr^2 / S.df); % normr is the norm of residuals, df is degrees of freedom

    % Plot data points with legend entry
    if ~isempty(reference) && ~isempty(averageBias)
        Data = scatter(ax, reference, averageBias, 50, 'b', 'filled', 'DisplayName', 'Data');
    end

    % Plot regression line with legend entry
    Regress = plot(ax, xfit, yfit, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Regression');

    % Plot confidence intervals with legend entry
    CIHigh = plot(ax, xfit, yfit + delta, 'g--', 'LineWidth', 1, 'DisplayName', '95% CI');
    CILow = plot(ax, xfit, yfit - delta, 'g--', 'LineWidth', 1);

    % Plot biases for each group (only for groups that have data)
    for group = 1:numGroups
        if any(~isnan(biases(group, :))) % Check if there's any valid bias data
            plot(reference(group) * ones(numMeasurements, 1), biases(group, :), 'o', 'Color', [173/255, 216/255, 230/255]);
        end
    end
end
        % Plot a dummy point to handle the legend entry for 'Avg Bias'
        AvgBias = plot(NaN, NaN, 'o', 'Color', [173/255, 216/255, 230/255]);

        % Customize plot
        xlabel(ax, 'Reference Value');
        ylabel(ax, 'Bias');
        title(ax, 'Linearity and Bias Report');

     % Add legend only if data exists
legendEntries = {};
legendLabels = {};

if exist('Data', 'var')
    legendEntries{end+1} = Data;
    legendLabels{end+1} = 'Data';
end
if exist('Regress', 'var')
    legendEntries{end+1} = Regress;
    legendLabels{end+1} = 'Regression';
end
if exist('CIHigh', 'var')
    legendEntries{end+1} = CIHigh;
    legendLabels{end+1} = '95% CI';
end
legendEntries{end+1} = AvgBias;
legendLabels{end+1} = 'Avg Bias';

if ~isempty(legendEntries)
    legend(ax, [legendEntries{:}], legendLabels, 'Location', 'northeastoutside');
end

        hold(ax, 'off');

        % Create the filename with the desired format
        filename = sprintf('Filmscan-Linearity+Bias Report,%s,%s,%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all
        end

        % Callback function
        function RunallLaquerPlatesButtonPushed(app, event)
             % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_12.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
     baseDirectory = fullfile([drive ':'],'Matlab','Optical Filmweight');
end

% Create the folder name using the variables
folderName = sprintf('Optical Filmweight - R&R,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end


folderPath2 = 'C:\Matlab\Optical Filmweight';
allmeasurements = readmatrix(filePath2, 'Range', 'Aso from that 2:D481');
   
        end

        % Button pushed function: RunIndividuallyButton_2
        function RunIndividuallyButton_2Pushed(app, event)
             gname = app.GageNameSNEditField_3.Value;
           
            operator = app.OperatorEditField_3.Value;
            misc = app.CompanyEditField_3.Value;
            
              %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;

if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'Filmscan');

            % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_3.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Filmscan');
end

% Create the folder name using the variables
folderName = sprintf('Filmscan - LP Analysis,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

% File prefixes (A to G)
filePrefixes = 'A':'G';

% Initialize an empty table to store the results with the correct column types
resultsCellArray = cell(0, 7);

% Loop through each file (LPA.csv to LPG.csv)
for i = 1:length(filePrefixes)
    % Construct the full file path for the current CSV file
    fileName = ['LP', filePrefixes(i), '.csv'];
    filePath = fullfile(folderPath, fileName);
    
    % Try to read the file, if it doesn't exist, skip it
    try
        % Read the CSV file without variable names
        if exist(filePath, 'file') == 2
            data = readtable(filePath, 'ReadVariableNames', false, 'Format', '%s');
        else
        
            continue; % Skip the file if not found
        end
        
        % Extract data from the single column
        dataArray = data.Var1;
        
        % Debug: Check if dataArray contains any data
        if isempty(dataArray)
            
            continue;
        end
        
        % Extract every 3rd value
        lpmeasurements = dataArray(4:8:end);
        
        % Convert cell array of strings to numeric values
        numericValues = cellfun(@str2double, lpmeasurements);
        
        % Remove NaN values (which were non-numeric in the original data)
        numericValues = numericValues(~isnan(numericValues));
        
        % Debug: Check if numericValues has any valid values
        if isempty(numericValues)
            
            continue;
        end
        
        % Calculate required statistics
        meanValue = mean(numericValues);
        stdevValue = std(numericValues);
        minValue = min(numericValues);
        medianValue = median(numericValues);
        maxValue = max(numericValues);
        rangeValue = max(numericValues) - min(numericValues);


          % Create a new row in cell format
        newRow = {char(fileName), num2str(meanValue), num2str(stdevValue), num2str(minValue), ...
                   num2str(medianValue), num2str(maxValue), num2str(rangeValue)};
        % Append the new row to the resultsTable
        resultsCellArray = [resultsCellArray; newRow];
        
    catch ME
        % If file is not found or there's an error, skip to the next one
       
        continue;
    end
end


%% Create Intro Table 
intro_table = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company'}, ...
                   {gname; date; operator; misc}, ...
                   'VariableNames', {'Description', 'Value'});
               
% Create figure and panels
        intro_f = figure('Name', 'Lacquer PLate Analysis', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
        
% Add a title to the figure
sgtitle('Filmscan - Lacquer Plate Analysis');
      
        % Create panels for plotting and table
        intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.6, 0.9, 0.25]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.2, 0.9, 0.35]);

        % Create the table
       uitable('Parent', intro_panel, 'Data', intro_table{:,:}, 'ColumnName', intro_table.Properties.VariableNames, 'RowName', intro_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 550});


       % Convert all numeric values in resultsCellArray to strings
resultsCellArray(:, 2:end) = cellfun(@num2str, resultsCellArray(:, 2:end), 'UniformOutput', false);

% Create the uitable with cell data

    uitable('Parent', table_panel, 'Data', resultsCellArray, ...
        'ColumnName', {'Filename', 'Mean', 'StdDev', 'Min', 'Median', 'Max', 'Range'}, ...
        'RowName', [], ... % Use default row names
        'Units', 'Normalized', ...
        'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 75, 75, 75, 75, 75, 75}); 
    
 % Create the filename with the desired format
        filename = sprintf('Filmscan-Lacquer Plate Analysis_%s_%s_%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all

        end

        % Button pushed function: RunIndividuallyButton_3
        function RunIndividuallyButton_3Pushed(app, event)
              gname = app.GageNameSNEditField_11.Value;
           
            operator = app.OperatorEditField_11.Value;
            misc = app.CompanyEditField_9.Value;
            
            %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath = fullfile([drive ':'], 'Matlab', 'Optical Filmweight');

            % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_12.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Optical Filmweight');
end

% Create the folder name using the variables
folderName = sprintf('Optical Filmweight - LP Analysis,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end


% File prefixes (A to G)
filePrefixes = 'A':'G';

% Initialize an empty table to store the results with the correct column types
resultsCellArray = cell(0, 7);

% Loop through each file (LPA.csv to LPG.csv)
for i = 1:length(filePrefixes)
    % Construct the full file path for the current CSV file
    fileName = ['LP', filePrefixes(i), '.csv'];
    filePath = fullfile(folderPath, fileName);
    
    % Try to read the file, if it doesn't exist, skip it
    try
        % Read the CSV file without variable names
        if exist(filePath, 'file') == 2
            data = readtable(filePath, 'ReadVariableNames', false, 'Format', '%s');
        else
        
            continue; % Skip the file if not found
        end
        
        % Extract data from the single column
        dataArray = data.Var1;
        
        % Debug: Check if dataArray contains any data
        if isempty(dataArray)
            
            continue;
        end
        
        % Extract every 3rd value
        lpmeasurements = dataArray(3:3:end);
        
        % Convert cell array of strings to numeric values
        numericValues = cellfun(@str2double, lpmeasurements);
        
        % Remove NaN values (which were non-numeric in the original data)
        numericValues = numericValues(~isnan(numericValues));
        
        % Debug: Check if numericValues has any valid values
        if isempty(numericValues)
            
            continue;
        end
        
        % Calculate required statistics
        meanValue = mean(numericValues);
        stdevValue = std(numericValues);
        minValue = min(numericValues);
        medianValue = median(numericValues);
        maxValue = max(numericValues);
        rangeValue = max(numericValues) - min(numericValues);


          % Create a new row in cell format
        newRow = {char(fileName), num2str(meanValue), num2str(stdevValue), num2str(minValue), ...
                   num2str(medianValue), num2str(maxValue), num2str(rangeValue)};
        % Append the new row to the resultsTable
        resultsCellArray = [resultsCellArray; newRow];
        
    catch ME
        % If file is not found or there's an error, skip to the next one
       
        continue;
    end
end


%% Create Intro Table 
intro_table = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company'}, ...
                   {gname; date; operator; misc}, ...
                   'VariableNames', {'Description', 'Value'});
               
% Create figure and panels
        intro_f = figure('Name', 'Lacquer Plate Analysis', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
        
% Add a title to the figure
sgtitle('Filmscan - Lacquer Plate Analysis');
      
        % Create panels for plotting and table
        intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.6, 0.9, 0.25]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.2, 0.9, 0.35]);

        % Create the table
       uitable('Parent', intro_panel, 'Data', intro_table{:,:}, 'ColumnName', intro_table.Properties.VariableNames, 'RowName', intro_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 550});


       % Convert all numeric values in resultsCellArray to strings
resultsCellArray(:, 2:end) = cellfun(@num2str, resultsCellArray(:, 2:end), 'UniformOutput', false);

% Create the uitable with cell data

    uitable('Parent', table_panel, 'Data', resultsCellArray, ...
        'ColumnName', {'Filename', 'Mean', 'StdDev', 'Min', 'Median', 'Max', 'Range'}, ...
        'RowName', [], ... % Use default row names
        'Units', 'Normalized', ...
        'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 75, 75, 75, 75, 75, 75}); 
    
 % Create the filename with the desired format
        filename = sprintf('Optical Filmweight-Lacquer PLate Analysis_%s_%s_%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all
        end

        % Button pushed function: RunIndividuallyButton_4
        function RunIndividuallyButton_4Pushed(app, event)
             gname = app.GageNameSNEditField_3.Value;
           
            operator = app.OperatorEditField_11.Value;
            misc = app.CompanyEditField_9.Value;


            % Clear previous plots
        cla(app.UIAxes_3, 'reset');
        cla(app.UIAxes2_3, 'reset');
        cla(app.UIAxes3_3, 'reset');
        cla(app.UIAxes4_3, 'reset');
     referenceValues  = [app.Measure1EditField_2.Value,app.Measure2EditField_2.Value,app.Measure3EditField_2.Value]


             %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'Optical Filmweight');

    %create folder 
    
    % Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_12.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','Optical Filmweight');
end

% Create the folder name using the variables
folderName = sprintf('Optical Filmweight Linearity Test,%s,%s,%s,%s', gname, date, operator,misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end
          % Define file paths for the CSV files
   filePaths = {
    'Matlab\Optical Filmweight\Nist1.csv', 
    'Matlab\Optical Filmweight\Nist2.csv', 
    'Matlab\Optical Filmweight\Nist3.csv'};
% Construct full paths
filePaths = cellfun(@(folder) fullfile([drive ':'], folder), filePaths, 'UniformOutput', false);

% Initialize an empty cell array to hold the combined data
combinedData = {};
validFileCount = 0
usedReferences = [];

% Loop through each file path and read the data
for i = 1:length(filePaths)
    if isfile(filePaths{i});
    data = readtable(filePaths{i}, 'ReadVariableNames', false, 'Format', '%s');
    validFileCount = validFileCount + 1;
    % Extract data from the single column and append to combinedData
    combinedData = [combinedData; data.Var1]; % Append column-wise
     usedReferences = [usedReferences, referenceValues(i)]
    end
end
        dataArray = combinedData
        

        % Define parameters
        numGroups = validFileCount;
        numMeasurements = 8;
% Convert usedReferences to a column vector
reference = usedReferences(:)


        linearitytolerancepositive = app.ToleranceumEditField_3.Value;
        linearitytolerancenegative = app.ToleranceumEditField_4.Value

        % Initialize variables
       groupData = cell(numGroups, 8);

        % Parsing data
        currentIndex = 1;
       for group = 1:numGroups
    if currentIndex <= length(dataArray)
        for measurement = 1:8  % 8 measurements expected
            % Ensure there's enough data for the current group and measurement
            if currentIndex + 5 <= length(dataArray)
                groupData{group, measurement} = str2double(dataArray(currentIndex + 3 : currentIndex + 5));
            else
                groupData{group, measurement} = NaN(3, 1); % Fill with NaN if not enough data
            end
            currentIndex = currentIndex + 8; % Move to the next set of data
        end
    else
        % If there's no data for this group, fill with NaNs
        groupData{group, :} = {NaN(3, 1)};  % Fill all measurements with NaN
    end
       end

        % Calculate statistics for each group
        averages = zeros(numGroups, 1);
        mins = zeros(numGroups, 1)
        maxs = zeros(numGroups, 1)
        ranges = zeros(numGroups, 1);
        biases = zeros(numGroups, numMeasurements);
        PassFail = cell(numGroups, 1)
averageBias = zeros(numGroups, 1);

        % For storing confidence intervals
        upperCI = zeros(numGroups, 1);
        lowerCI = zeros(numGroups, 1);

        for group = 1:numGroups
            allValues = [];
            % Concatenate all measurements into a single array for the group
    for measurement = 1:8  % 8 measurements expected
        if ismatrix(groupData{group, measurement}) && ~isempty(groupData{group, measurement})
            allValues = [allValues; groupData{group, measurement}];
        end
    end
    % Exclude NaN values
    allValues = allValues(~isnan(allValues))
    
            if ~isempty(allValues)
                averages(group) = mean(allValues(:));
                mins(group) = min(allValues(:));
                maxs(group) = max(allValues(:));
                ranges(group) = maxs(group) - mins(group);
                
                % Compute biases
                biases(group, :) = mean(reshape(allValues, [], 8), 1) - reference(group);
                
                % Calculate average bias
                averageBias(group) = mean(biases(group, :));
                
                % Determine pass/fail
                if any(biases(group, :) > linearitytolerancepositive | biases(group, :) < -linearitytolerancenegative)
            PassFail{group} = 'Fail';
        else
            PassFail{group} = 'Pass';
                end
                else
        % If all values are NaN, set defaults
        averages(group) = NaN;
        mins(group) = NaN;
        maxs(group) = NaN;
        ranges(group) = NaN;
        averageBias(group) = NaN;
        PassFail{group} = 'Not Applicable'; % No data to evaluate
            end
        end


        % Create table with results
        resultsTable = table(reference, averages, mins, maxs, ranges, averageBias, PassFail, ...
            'RowNames',  arrayfun(@(x) sprintf('Group %d', x), 1:numGroups, 'UniformOutput', false));

        % Create figure and panels
        intro_f = figure('Name', 'Gage R&R Study - Filmscan', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
%% Create Intro Table 
intro_table = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company'}, ...
                   {gname; date; operator; misc}, ...
                   'VariableNames', {'Description', 'Value'});
               
% Create figure and panels
        intro_f = figure('Name', 'Linearity', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);


        % Create panels for plotting and table
        intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.82, 0.9, 0.18]);
    

        % Create the table
       uitable('Parent', intro_panel, 'Data', intro_table{:,:}, 'ColumnName', intro_table.Properties.VariableNames, 'RowName', intro_table.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {150, 550});
        % Create panels for plotting and table
        plot_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.17, 0.9, 0.65]);
        table_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.02, 0.9, 0.15]);

        % Create the table
        uitable('Parent', table_panel, 'Data', [num2cell(reference), num2cell(averages), num2cell(mins), num2cell(maxs), num2cell(ranges), num2cell(averageBias), PassFail], ...
            'ColumnName', resultsTable.Properties.VariableNames, 'RowName', resultsTable.Properties.RowNames, ...
            'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {91, 91, 91, 91, 91, 91, 91});

        % Create a new axis in the plot panel
        ax = axes('Parent', plot_panel);

        % Plot biases
        hold(ax, 'on');

        % Perform linear regression using polyfit only if there are valid data points
if numGroups > 0
    % Perform linear regression
    [p, S] = polyfit(reference, averageBias, 1); % p contains the coefficients, S contains the structure with stats

    % Generate fitted values
    xfit = linspace(min(reference), max(reference), 100);
    [yfit, delta] = polyval(p, xfit, S); % yfit are the fitted values, delta gives the confidence intervals

    % Standard error of the regression (S)
    S_value = sqrt(S.normr^2 / S.df); % normr is the norm of residuals, df is degrees of freedom

    % Plot data points with legend entry
   if ~isempty(reference) && ~isempty(averageBias)
        Data = scatter(ax, reference, averageBias, 50, 'b', 'filled', 'DisplayName', 'Data');
   end

    % Plot regression line with legend entry
    Regress = plot(ax, xfit, yfit, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Regression');

    % Plot confidence intervals with legend entry
    CIHigh = plot(ax, xfit, yfit + delta, 'g--', 'LineWidth', 1, 'DisplayName', '95% CI');
    CILow = plot(ax, xfit, yfit - delta, 'g--', 'LineWidth', 1);

    % Plot biases for each group (only for groups that have data)
    for group = 1:numGroups
        if any(~isnan(biases(group, :))) % Check if there's any valid bias data
            plot(reference(group) * ones(numMeasurements, 1), biases(group, :), 'o', 'Color', [173/255, 216/255, 230/255]);
        end
    end
end
        % Plot a dummy point to handle the legend entry for 'Avg Bias'
        AvgBias = plot(NaN, NaN, 'o', 'Color', [173/255, 216/255, 230/255]);

        % Customize plot
        xlabel(ax, 'Reference Value');
        ylabel(ax, 'Bias');
        title(ax, 'Linearity and Bias Report');

        % Add legend only if data exists
legendEntries = {};
legendLabels = {};

if exist('Data', 'var')
    legendEntries{end+1} = Data;
    legendLabels{end+1} = 'Data';
end
if exist('Regress', 'var')
    legendEntries{end+1} = Regress;
    legendLabels{end+1} = 'Regression';
end
if exist('CIHigh', 'var')
    legendEntries{end+1} = CIHigh;
    legendLabels{end+1} = '95% CI';
end
legendEntries{end+1} = AvgBias;
legendLabels{end+1} = 'Avg Bias';

if ~isempty(legendEntries)
    legend(ax, [legendEntries{:}], legendLabels, 'Location', 'northeastoutside');
end
        hold(ax, 'off');

        % Create the filename with the desired format
        filename = sprintf('Optical Filmweight -Linearity+Bias Report,%s,%s,%s.pdf', gname, date, operator);

        % Define the full path to save the PDF
        filePath = fullfile(fullFolderPath, filename);

        % Save the figure as a PDF
        print(intro_f, filePath, '-dpdf');
        close all
        end

        % Button pushed function: RunAnalysisButton_3
        function RunAnalysisButton_3Pushed(app, event)
   
sigma_level = 6;
gname = app.GageNameSNEditField_13.Value;
Imperial = app.MeaurementsinImperialCheckBox_3.Value

filename = app.SpecifyCSVFileEditField_2.Value;
filenameshortened = erase(filename, '.csv');
thresholds = struct();
thresholds.diameter = 0.01;
thresholds.height = 0.008;
thresholds.inside_curl = 0.01;
thresholds.material = 0.0005;
thresholds.panel_height = 0.006;
thresholds.countersink_depth = 0.008;
thresholds.curl_height = 0.006;
thresholds.curl_opening = 0.01;
thresholds.curl_conc = 0.013;
thresholds.lip_height = 0.008;
thresholds.off_draw = 0.01;
thresholds.uncurled_dia = 0.0125;
thresholds.panel_coin = 0.002;
thresholds.bubble_height = 0.004;
thresholds.bubble_top_thickness = 0.0008;
thresholds.bubble_wall_thickness = 0.001;
thresholds.cent_bead = 0.004;
thresholds.shadow_bead = 0.004;
thresholds.anti_missile = 0.002;
thresholds.rivet_diameter = 0.004;
thresholds.rivet_residual = 0.001;
thresholds.inner_bead = 0.002;
thresholds.vent_bead = 0.002;
thresholds.button_coin = 0.001;
thresholds.bubble_thickness = 0.0008;
thresholds.deboss_panel = 0.005;
thresholds.finger_panel = 0.007;
thresholds.smile_bead = 0.004;
thresholds.wall_bead = 0.004;
thresholds.reduction = 0.004;
thresholds.score = 0.004;
thresholds.outer_bead = 0.002;
thresholds.tear_panel = 0.004;
thresholds.stiffening_panel = 0.004;
thresholds.button_height = 0.004;
thresholds.Rivet_form_thickness = 0.0008
thresholds.anti_rotation = 0.004
% Adjust thresholds based on Knob value
Knob_value = app.Knob_2.Value;
if Knob_value == 2
    thresholds.countersink_depth = 0.01; 
    thresholds.height = 0.01;            
end


% Initialize passFail as 'N/A'
passFail = 'N/A';


operator = app.OperatorEditField_13.Value;
misc = app.CompanyMiscEditField_3.Value;

 %%%%%% specify folder to pull excel values from 
drive = app.DrivelettertoPullStoredataEditField.Value;
if isempty(drive)
    drive = 'C';
end
% Construct the full folder path
folderPath2 = fullfile([drive ':'], 'Matlab', 'ES8000');
folderPath = fullfile([drive ':'], 'Matlab', 'ES8000');
% Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_14.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
    baseDirectory = fullfile([drive ':'],'Matlab','ES8000');
end

% Create the folder name using the variables
folderName = sprintf('ES8000-R&R -%s,%s,%s,%s', filenameshortened, gname, date, misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end

secondFolderName = 'Feature Results';  % You can modify the name as per your requirements

% Construct the full path for the second folder inside the first folder
fullFolderPath2 = fullfile(fullFolderPath, secondFolderName);

% Create the second folder if it doesn't exist
if ~exist(fullFolderPath2, 'dir')
    mkdir(fullFolderPath2);
end
% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company/Misc';'Filename'}, ...
                   {gname; date; operator; misc;filename}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - ES8000', 'NumberTitle', 'off');
sgtitle('Gage R&R Study - ES8000');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, ...
        'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 348});

filename = sprintf('1-Es8000-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');
close(intro_f)
%%%%%%%%%%%%%%%%

filename = app.SpecifyCSVFileEditField_2.Value;

if isempty(filename)
    filename = "RR_ES8000.csv";
end

filePath = fullfile(folderPath, filename);

if isfile(filePath)
    app.CSVFoundLamp_2.Color = 'green';
    
    % Read data from the file
    data = readtable(filePath);
    data1 = readcell(filePath, 'Range', 'AC:AC');

    data2 = readmatrix(filePath, 'Range', 'AH:AH');
    
    combinedData = table(data1, data2, 'VariableNames', {'Feature', 'Value'});
else
    app.CSVFoundLamp_2.Color = 'red';
end

features = combinedData.Feature;
values = combinedData.Value;

% Find unique features
uniqueFeatures = unique(features, 'stable');

% Extract the number of parts (x) and repeats (v) from the Excel data
v = data.Var22(end);  % Assuming 'Var22' is the variable name for column V  -  - repeats
x = data.Var24(end);  % Assuming 'Var24' is the variable name for column X  -   number of parts

% Create a single figure for all plots
f = figure( 'Position', [200, 200, 800, 600]);
% For each unique feature, extract corresponding values and create a variable
for i = 1:length(uniqueFeatures)
    
    % Sanitize the feature name to make it a valid MATLAB variable name
    sanitizedFeatureName = matlab.lang.makeValidName(uniqueFeatures{i});
    
    % Find the indices where the feature matches
    featureIndices = strcmp(features, uniqueFeatures{i});
    
    % Extract values for the current feature
    featureValues = values(featureIndices);
    
    % Assign these values to a variable with the sanitized feature name
    assignin('base', sanitizedFeatureName, featureValues);
end


resultsTable = table('Size', [0 5], ...
                     'VariableTypes', {'string', 'double', 'double', 'string', 'double'}, ...
                     'VariableNames', {'Feature', 'RepeatabilitySD', 'Repeatability6SD', 'PassFail', 'Threshold'})

                 
for i = 1:length(uniqueFeatures)
    feature = uniqueFeatures{i};
    featureIndices = strcmp(features, feature);
    measurements = values(featureIndices);
    measurements2 = [];
    
    % Dynamically generate part columns based on the value of x
    partMeans = zeros(1, x);
    for j = 1:x
        partColumn = measurements(j:x:end);
        measurements2 = [measurements2; partColumn];
        partMeans(j) = mean(partColumn);
    end
    
    meanofmeans = mean(partMeans);
    mean_all = mean(measurements);
    
    % ANOVA calculations
    partsdf = x - 1;
    repeatabilitydf = x * (v - 1);
    totaldf = repeatabilitydf + partsdf;
    
    % Part sum of squares
    partss = v * sum((partMeans - mean_all).^2);
    
    % Repeatability ss - summed squared difference of each part
    repeatabilityss = sum((measurements2 - repelem(partMeans', v)).^2);
    
    % Total sum of squares            
    totalss = partss + repeatabilityss;
    
    % Mean squares
    repeatabilityms = repeatabilityss / repeatabilitydf;
    partms = partss / partsdf;
    F = partms / repeatabilityms;
    
    % Variance components
    repeatabilityvarcomp = repeatabilityms;
    part2partvarcomp = (partms - repeatabilityms) / v;
    if part2partvarcomp < 0
        part2partvarcomp = 0;
    end
    totalvar = repeatabilityvarcomp + part2partvarcomp;
    
    % Standard deviation
    repeatabilitysd = sqrt(repeatabilityvarcomp);
    totalsd = sqrt(totalvar);
    part2partsd = sqrt(part2partvarcomp);
    repeatability6sd = 6 * repeatabilitysd;
    
    % Study variance
    sv_repeatability = repeatabilitysd * sigma_level;
    sv_part2part = part2partsd * sigma_level;
    sv_totalvariation = totalsd * sigma_level;
    repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
    part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
    totalvariation_percentSV = 100;
    
    % Tolerance calculations
  
    
  % Determine the threshold based on the feature name and set passFail
if startsWith(feature, 'diameter', 'IgnoreCase', true)
    threshold = thresholds.diameter;
elseif startsWith(feature, 'height', 'IgnoreCase', true)
    threshold = thresholds.height;
elseif startsWith(feature, 'inside_curl', 'IgnoreCase', true)
    threshold = thresholds.inside_curl;
elseif startsWith(feature, 'material', 'IgnoreCase', true)
    threshold = thresholds.material;
elseif startsWith(feature, 'panel_height', 'IgnoreCase', true)
    threshold = thresholds.panel_height;
elseif startsWith(feature, 'countersink_depth', 'IgnoreCase', true)
    threshold = thresholds.countersink_depth;
elseif startsWith(feature, 'curl_height', 'IgnoreCase', true)
    threshold = thresholds.curl_height;
elseif startsWith(feature, 'curl_opening', 'IgnoreCase', true)
    threshold = thresholds.curl_opening;
elseif startsWith(feature, 'curl_conc', 'IgnoreCase', true)
    threshold = thresholds.curl_conc;
elseif startsWith(feature, 'lip_height', 'IgnoreCase', true)
    threshold = thresholds.lip_height;
elseif startsWith(feature, 'off_draw', 'IgnoreCase', true)
    threshold = thresholds.off_draw;
elseif startsWith(feature, 'uncurled_dia', 'IgnoreCase', true)
    threshold = thresholds.uncurled_dia;
elseif startsWith(feature, 'panel_coin', 'IgnoreCase', true)
    threshold = thresholds.panel_coin;
elseif startsWith(feature, 'bubble_height', 'IgnoreCase', true)
    threshold = thresholds.bubble_height;
elseif startsWith(feature, 'bubble_top_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_top_thickness;
elseif startsWith(feature, 'bubble_wall_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_wall_thickness;
elseif startsWith(feature, 'cent_bead', 'IgnoreCase', true)
    threshold = thresholds.cent_bead;
elseif startsWith(feature, 'shadow_bead', 'IgnoreCase', true)
    threshold = thresholds.shadow_bead;
elseif startsWith(feature, 'anti_missile', 'IgnoreCase', true)
    threshold = thresholds.anti_missile;
elseif startsWith(feature, 'rivet_diameter', 'IgnoreCase', true)
    threshold = thresholds.rivet_diameter;
elseif startsWith(feature, 'rivet_residual', 'IgnoreCase', true)
    threshold = thresholds.rivet_residual;
elseif startsWith(feature, 'inner_bead', 'IgnoreCase', true)
    threshold = thresholds.inner_bead;
elseif startsWith(feature, 'vent_bead', 'IgnoreCase', true)
    threshold = thresholds.vent_bead;
elseif contains(feature, 'button_coin', 'IgnoreCase', true)
    threshold = thresholds.button_coin;
elseif contains(feature, 'bubble_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_thickness;
elseif contains(feature, 'deboss_panel', 'IgnoreCase', true)
    threshold = thresholds.deboss_panel;
elseif startsWith(feature, 'finger_panel', 'IgnoreCase', true)
    threshold = thresholds.finger_panel;
elseif contains(feature, 'Smile', 'IgnoreCase', true)
    threshold = thresholds.smile_bead;
elseif contains(feature, 'wall_bead', 'IgnoreCase', true)
    threshold = thresholds.wall_bead;
elseif contains(feature, 'Reduction', 'IgnoreCase', true)
    threshold = thresholds.reduction;
elseif contains(feature, 'Score', 'IgnoreCase', true)
    threshold = thresholds.score;
elseif contains(feature, 'outer_bead', 'IgnoreCase', true)
    threshold = thresholds.outer_bead;
elseif contains(feature, 'tear_panel', 'IgnoreCase', true)
    threshold = thresholds.tear_panel;
elseif contains(feature, 'stiffening_panel', 'IgnoreCase', true)
    threshold = thresholds.stiffening_panel;
elseif endsWith(feature, 'button_height', 'IgnoreCase', true)
    threshold = thresholds.button_height;
elseif startsWith(feature, 'fingerwell', 'IgnoreCase', true)
   threshold = thresholds.finger_panel;
elseif startsWith(feature, 'button_thickness', 'IgnoreCase', true)
   threshold = thresholds.Rivet_form_thickness;
elseif endsWith(feature, 'button_thickness', 'IgnoreCase', true)
   threshold = thresholds.Rivet_form_thickness;
elseif startsWith(feature, 'panel_form', 'IgnoreCase', true)
   threshold = thresholds.deboss_panel;
elseif startsWith(feature, 'anti_turn', 'IgnoreCase', true)
   threshold = thresholds.anti_rotation;
   elseif startsWith(feature, 'anti turn', 'IgnoreCase', true)
   threshold = thresholds.anti_rotation;
else
    threshold = []; % No specific threshold, set to empty to trigger 'N/A'
end

   % Determine Pass/Fail based on repeatabilitysd, only if threshold is defined
if isempty(threshold)
    passFail = 'N/A'; % or keep it as is if you want no change
else
    % Determine Pass/Fail based on repeatabilitysd, only if threshold is defined
    if Imperial == 1 
        threshold = threshold/25.399986284
    end
    if repeatabilitysd < threshold
        passFail = 'Pass';
    else
        passFail = 'Fail';
    end
end

  % Ensure 'feature' and 'passFail' are strings to match the table definition
feature = string(feature)
passFail = string(passFail);

    % Handle threshold, converting to NaN if it's not numeric
if isempty(threshold)
    threshold = NaN;  % Use NaN for non-numeric thresholds
else
    threshold = double(threshold); % Ensure it’s of type double
end
       % Create a new row to add to the results table, ensuring consistent data types
newRow = {feature, repeatabilitysd, repeatability6sd, passFail, threshold};

% Convert newRow to a table to ensure data types match
newRowTable = cell2table(newRow, 'VariableNames', resultsTable.Properties.VariableNames);

% Add the new row to the results table
resultsTable = [resultsTable; newRowTable];
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


feature = strrep(feature, ':', '_'); % Rename as ':' invalidates file names
feature = strrep(feature, '_', ' ');

% Create the title string
title_str = sprintf('ES8000 - R&R - %s, 1sd threshold = %.8f', feature,threshold);
% Create subplots
 clf(f);
% Components of Variation graph
ax1 = subplot(3, 2, 1, 'Parent', f); % Axes for the Components of Variation graph
barx = categorical(["Repeatability", "Part-to-Part"]);
bary = [repeatabilitysd, part2partsd];
bar(ax1, barx, bary);
ylabel(ax1, 'SD');
title(ax1, 'Components of Variation');
title_str = sprintf('ES8000- R&R - %s;', feature);
sgtitle(title_str);

% Sample Range Graph
ax2 = subplot(3, 2, 2, 'Parent', f); % Axes for the Sample Range graph
rangex = 1:x;

% Compute ranges dynamically based on x
ranges = zeros(1, x);
for j = 1:x
    partColumn = measurements(j:x:end);
    ranges(j) = max(partColumn) - min(partColumn);
end
rangemean = mean(ranges);

ucl = rangemean + ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));
lcl = rangemean - ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));

plot(ax2, rangex, ranges, '-o', 'MarkerFaceColor', 'b');
hold(ax2, 'on');
plot(ax2, rangex, repmat(rangemean, 1, x));
plot(ax2, rangex, repmat(ucl, 1, x), 'Color', [0 1 0]);
plot(ax2, rangex, repmat(lcl, 1, x), 'Color', [0 1 0]);

text(ax2, x-0.5, ucl, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax2, x-0.5, lcl, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax2, 'Sample Range');
title(ax2, 'R Chart');
ytickformat(ax2, '%.4f');

% Scatter Graph
ax3 = subplot(3, 2, 3, 'Parent', f); % Axes for the Scatter Graph
scatterx = repelem(1:x, v)
scattery = measurements2

scatter(ax3, scatterx, scattery, 'b');
hold(ax3, 'on');
plot(ax3, 1:x, partMeans, '-o', 'Color', [1, 0.5, 0]);
formattedTitle = sprintf('%s vs Part', feature);
title(ax3, formattedTitle);
xlabel(ax3, 'Part No.');
ytickformat(ax3, '%.3f');

% Mean Chart
ax4 = subplot(3, 2, 4, 'Parent', f); % Axes for the Mean Chart
plot(ax4, 1:x, partMeans, '-o', 'MarkerFaceColor', 'b');
hold(ax4, 'on');
plot(ax4, 1:x, repmat(meanofmeans, 1, x));

ucl2 = meanofmeans + ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));
lcl2 = meanofmeans - ((sigma_level / 2) * (repeatabilitysd / sqrt(v)));

plot(ax4, 1:x, repmat(ucl2, 1, x), 'Color', [0 1 0]);
plot(ax4, 1:x, repmat(lcl2, 1, x), 'Color', [0 1 0]);

text(ax4, x-0.5, ucl2, 'UCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');
text(ax4, x-0.5, lcl2, 'LCL', 'Color', 'k', 'FontSize', 8, 'HorizontalAlignment', 'center');

ylabel(ax4, 'Sample Mean');
title(ax4, 'XBar Chart');
xlabel(ax4, 'Part No.');
ytickformat(ax4, '%.4f');

% Create table 4
% Convert data to cell array of formatted strings
formattedData = {
    sprintf('%.7f', repeatabilitysd), sprintf('%.7f', sv_repeatability), sprintf('%.7f', repeatability_percentSV);
    sprintf('%.7f', part2partsd), sprintf('%.7f', sv_part2part), sprintf('%.7f', part2part_percentSV) ;
    sprintf('%.7f', totalsd), sprintf('%.7f', sv_totalvariation), sprintf('%.7f', totalvariation_percentSV) 
};

% Define column names and row names
columnNames = {'StdDev', 'SV (σ x SD)', '%SV'};
rowNames = {'Gage R&R', 'Part-to-Part', 'Total Variation'};

% Create the table
table4 = cell2table(formattedData, 'VariableNames', columnNames, 'RowNames', rowNames);

% Create a panel for the table
panel = uipanel('Parent', f, 'Position', [0.1, 0.05, 0.8, 0.2]);

% Create a uitable within the panel
uitable('Parent', panel, 'Data', table4{:,:}, 'ColumnName', table4.Properties.VariableNames, ...
        'RowName', table4.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth',  {154,154,154,154});
f.Position = [200, 200, 800, 600]; % Increased figure size to stop title being cut off

filename = sprintf('%s,%s,%s,%s.pdf', feature, gname, date, operator);    
filePath = fullfile(fullFolderPath2, filename);
print(f, filePath, '-dpdf');

show_ANOVA_table = app.ShowAnovaTablesCheckBox_2.Value;
if show_ANOVA_table ==1;
% Create ANOVA Table
table2 = table([partsdf; repeatabilitydf; totaldf], ...
               [partss; repeatabilityss; totalss], ...
               [partms; repeatabilityms; 0], ...
               'VariableNames', {'DF', 'SS', 'MS'}, ...
               'RowNames', {'Part No.', 'Repeatability', 'Total'});
table3 = table([repeatabilityvarcomp; part2partvarcomp; totalvar], ...
               'VariableNames', {'Variance Component'}, ...
               'RowNames', {'Repeatability', 'Part-to-Part', 'Total Variation'});


    anova_f = figure('Name', 'Combined Figure', 'NumberTitle', 'off');

    panel1 = uipanel('Parent', anova_f, 'Position', [0.05, 0.55, 0.9, 0.13]);
    panel2 = uipanel('Parent', anova_f, 'Position', [0.05, 0.05, 0.9, 0.13]);

    uitable('Parent', panel1, 'Data', table2{:,:}, 'ColumnName', table2.Properties.VariableNames, ...
            'RowName', table2.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0.05, 1, 0.95], 'ColumnWidth', {130,130,130});

    uitable('Parent', panel2, 'Data', table3{:,:}, 'ColumnName', table3.Properties.VariableNames, ...
            'RowName', table3.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], 'ColumnWidth', {367.9});
    sgtitle(sprintf('%s - ANOVA Tables', feature))

    filename = sprintf('%s-ANOVA,%s,%s,%s.pdf',  feature, gname, date, operator);    
    filePath = fullfile(fullFolderPath2, filename);
    anova_f.Position = [200, 200, 600, 800]; % increased figure size to stop title being cut off
    print(anova_f, filePath, '-dpdf');
    close(anova_f)
end


end

filename2 = sprintf('__Results Table -%s,%s,%s,%s.xlsx', filenameshortened, gname, date, misc);
fullFilePath3 = fullfile(fullFolderPath, filename2);
% Initialize a counter for duplicates
counter = 1;

% Check if the file already exists
while isfile(fullFilePath3)
    % Create a new filename with an incremented counter
    filename2 = sprintf('__Results Table -%s,%s,%s,%s (%d).xlsx', filenameshortened, gname, date, misc, counter);
    fullFilePath3 = fullfile(fullFolderPath, filename2);
    counter = counter + 1; % Increment the counter
end

writetable(resultsTable, fullFilePath3);
numTables = 3;

% Get the total number of rows in the results table
totalRows = height(resultsTable);

% Calculate the number of rows for each table
rowsPerTable = floor(totalRows / numTables);
remainderRows = mod(totalRows, numTables);

% Initialize the starting index for each table
startRow = 1;

% Loop to create each of the split tables
for i = 1:numTables
    % Determine the end row for the current table
    if i <= remainderRows
        endRow = startRow + rowsPerTable;
    else
        endRow = startRow + rowsPerTable - 1;
    end
    
    % Extract rows for the current table
    currentTable = resultsTable(startRow:endRow, :);
    
    % Assign the current table to the appropriate variable
    eval(sprintf('resultsTable%d = currentTable;', i));
    
    % Update the starting row for the next table
    startRow = endRow + 1;
end

    app.UITable9_4.Data = resultsTable1;
     app.UITable9_5.Data = resultsTable2;
        app.UITable9_6.Data = resultsTable3;
     
feature_names = uniqueFeatures;

totalRows = height(resultsTable);

% Define the maximum number of rows per table
maxRowsPerTable = 36;

% Split the resultsTable based on the number of rows
if totalRows > maxRowsPerTable
    % First table and figure (rows 1 to 36)
    resultsTable_1 = resultsTable(1:maxRowsPerTable, :);
    feature_names1 = feature_names(1:maxRowsPerTable);
    
    % Convert the first half of the table data to cell array and format it
    dataCellArray1 = table2cell(resultsTable_1);
    dataCellArray1 = dataCellArray1(:, 2:end);
    dataCellArray1 = cellfun(@num2str, dataCellArray1, 'UniformOutput', false);

    % Create the first figure and table
    f1 = figure('Name', 'Figure 1', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
    uitable('Data', dataCellArray1, "RowName", feature_names1, ...
            'ColumnName', {'1 SD', '6 SD', 'P/F', 'Threshold(1SD)'}, ...
            'Position', [10, 90, 780, 800], 'ColumnWidth', {65, 65, 38, 140});
    sgtitle('ES8000 Feature Summary 1', 'Interpreter', 'none');

    % Save the first figure
    filename = sprintf('Z- Es8000-Summary-1');
    filePath = fullfile(fullFolderPath, filename);
    print(f1, filePath, '-dpdf');

    % Second table and figure (rows 37 onwards, if applicable)
    resultsTable_2 = resultsTable(maxRowsPerTable+1:end, :);
    feature_names2 = feature_names(maxRowsPerTable+1:end);
    
    % Convert the second half of the table data to cell array and format it
    dataCellArray2 = table2cell(resultsTable_2);
    dataCellArray2 = dataCellArray2(:, 2:end);
    dataCellArray2 = cellfun(@num2str, dataCellArray2, 'UniformOutput', false);

    % Create the second figure and table
    f2 = figure('Name', 'Figure 2', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
    uitable('Data', dataCellArray2, "RowName", feature_names2, ...
            'ColumnName', {'1 SD', '6 SD', 'P/F', 'Threshold(1SD)'}, ...
            'Position', [10, 90, 780, 800], 'ColumnWidth', {65, 65, 38, 140});
    sgtitle('ES8000 Feature Summary 2');

    % Save the second figure
    filename = sprintf('Z- Es8000-Summary-2');
    filePath = fullfile(fullFolderPath, filename);
    print(f2, filePath, '-dpdf');
else
    % If the total number of rows is 36 or less, only create the first table and figure
    resultsTable_1 = resultsTable;
    feature_names1 = feature_names;
    
    % Convert the table data to cell array and format it
    dataCellArray1 = table2cell(resultsTable_1);
    dataCellArray1 = dataCellArray1(:, 2:end);
    dataCellArray1 = cellfun(@num2str, dataCellArray1, 'UniformOutput', false);

    % Create the first figure and table
    f1 = figure('Name', 'Figure 1', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
    uitable('Data', dataCellArray1, "RowName", feature_names1, ...
            'ColumnName', {'1 SD', '6 SD', 'P/F', 'Threshold(1SD)'}, ...
            'Position', [10, 90, 780, 800], 'ColumnWidth', {65, 65, 38, 140});
    sgtitle('ES8000 Feature Summary 1', 'Interpreter', 'none');

    % Save the first figure
    filename = sprintf('Z- Es8000-Summary-1');
    filePath = fullfile(fullFolderPath, filename);
    print(f1, filePath, '-dpdf');
end


% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_ES8000 R&R Report_%s_%s_%s_%s_%s.pdf', filenameshortened,gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);

close all
        end

        % Button pushed function: CloseAllButton_2
        function CloseAllButton_2Pushed2(app, event)
            Close all
        end

        % Button pushed function: RunAnalysisButton_4
        function RunAnalysisButton_4Pushed(app, event)
             introannotation = app.NotesatstartofreportEditField.Value
sigma_level = 6;
gname = app.GageNameSNEditField_14.Value;
Imperial = app.MeaurementsinImperialCheckBox_4.Value


thresholds = struct();
thresholds.diameter = 0.01;
thresholds.height = 0.008;
thresholds.inside_curl = 0.01;
thresholds.material = 0.0005;
thresholds.panel_height = 0.006;
thresholds.countersink_depth = 0.008;
thresholds.curl_height = 0.006;
thresholds.curl_opening = 0.01;
thresholds.curl_conc = 0.013;
thresholds.lip_height = 0.008;
thresholds.off_draw = 0.01;
thresholds.uncurled_dia = 0.0125;
thresholds.panel_coin = 0.002;
thresholds.bubble_height = 0.004;
thresholds.bubble_top_thickness = 0.0008;
thresholds.bubble_wall_thickness = 0.001;
thresholds.cent_bead = 0.004;
thresholds.shadow_bead = 0.004;
thresholds.anti_missile = 0.002;
thresholds.rivet_diameter = 0.004;
thresholds.rivet_residual = 0.001;
thresholds.inner_bead = 0.002;
thresholds.vent_bead = 0.002;
thresholds.button_coin = 0.001;
thresholds.bubble_thickness = 0.0008;
thresholds.deboss_panel = 0.005;
thresholds.finger_panel = 0.007;
thresholds.smile_bead = 0.004;
thresholds.wall_bead = 0.004;
thresholds.reduction = 0.004;
thresholds.score = 0.004;
thresholds.outer_bead = 0.002;
thresholds.tear_panel = 0.004;
thresholds.stiffening_panel = 0.004;
thresholds.button_height = 0.004;
thresholds.Rivet_form_thickness = 0.0008
thresholds.anti_rotation = 0.004
% Adjust thresholds based on Knob value

   % thresholds.countersink_depth = 0.01; 
   % thresholds.height = 0.01;            


% Initialize passFail as 'N/A'
passFail = 'N/A';


operator = app.OperatorEditField_14.Value;
misc = app.CompanyMiscEditField_4.Value;

% Create date
%date = sprintf('%s-%s-%s', dd, mm, yy);

% Get the base directory from the edit field in your app
baseDirectory = app.DirectoryEditField_15.Value; 

% Check if the base directory is empty and set it to the current directory if it is
if isempty(baseDirectory)
     baseDirectory = fullfile([drive ':'],'Matlab','ES8000');
end

% Create the folder name using the variables
folderName = sprintf('ES8000-Combined Report -%s,%s,%s', gname, date, misc);

% Construct the full path for the new folder
fullFolderPath = fullfile(baseDirectory, folderName);

% Create the folder if it doesn't exist
if ~exist(fullFolderPath, 'dir')
    mkdir(fullFolderPath);
end


% Create Intro Table and figure
intro_table1 = table({'Gage Name + SN'; 'Date of Study'; 'Reported by'; 'Company/Misc'}, ...
                   {gname; date; operator; misc}, ...
                   'VariableNames', {'Description', 'Value'});

intro_f = figure('Name', 'Gage R&R Study - ES8000', 'NumberTitle', 'off');
sgtitle('Gage R&R Report - ES8000');
intro_panel = uipanel('Parent', intro_f, 'Position', [0.05, 0.45, 0.9, 0.4]);

uitable('Parent', intro_panel, 'Data', intro_table1{:,:}, 'ColumnName', intro_table1.Properties.VariableNames, ...
        'RowName', intro_table1.Properties.RowNames, 'Units', 'Normalized', 'Position', [0, 0, 1, 1], ...
        'ColumnWidth', {150, 348});
    
% Add annotation using the introannotation variable
annotation('textbox', [0.1, 0.05, 0.8, 0.1], 'String', introannotation, 'EdgeColor', 'none', ...
           'FontSize', 10, 'HorizontalAlignment', 'center');
       
filename = sprintf('1-Es8000 Report-Intro,%s,%s,%s.pdf', gname, date, operator);    
filePath = fullfile(fullFolderPath, filename);
print(intro_f, filePath, '-dpdf');
close(intro_f)
%%%%%%%%%%%%%%%%
folderPath = app.SpecifyCSVFileEditField_3.Value; % Assume you have a field to input folder path

if isempty(folderPath)
    folderPath = fullfile([drive ':'], 'Matlab','ES8000','Report Generator');
end

% Get all Excel files in the folder (e.g., .xlsx or .xls)
fileList = dir(fullfile(folderPath, '*.csv'));

if isempty(fileList)
    app.CSVFoundLamp_3.Color = 'red'; % Indicate that no files were found
  
else
    app.CSVFoundLamp_3.Color = 'green'; % Indicate files were found

    for i = 1:length(fileList)
        filename = fileList(i).name;
        filePath = fullfile(folderPath, filename);

        % Get the name of the file without the '.csv' extension
        [~, currentfile, ~] = fileparts(filename);  % 'currentfile' will hold the name without extension
        % Read data from the file
      currentfile
        try
            data = readtable(filePath); % Read table data from the file
            data1 = readcell(filePath, 'Range', 'AC:AC'); % Read specific cell range
            data2 = readmatrix(filePath, 'Range', 'AH:AH'); % Read specific matrix range
pdfno = i+1
            combinedData = table(data1, data2, 'VariableNames', {'Feature', 'Value'});
            features = combinedData.Feature;
values = combinedData.Value;

% Find unique features
uniqueFeatures = unique(features, 'stable');

% Extract the number of parts (x) and repeats (v) from the Excel data
v = data.Var22(end);  % Assuming 'Var22' is the variable name for column V  -  - repeats
x = data.Var24(end);  % Assuming 'Var24' is the variable name for column X  -   number of parts

% Create a single figure for all plots
f = figure( 'Position', [200, 200, 800, 600]);
% For each unique feature, extract corresponding values and create a variable
for i2 = 1:length(uniqueFeatures)
    
    % Sanitize the feature name to make it a valid MATLAB variable name
    sanitizedFeatureName = matlab.lang.makeValidName(uniqueFeatures{i2});
    
    % Find the indices where the feature matches
    featureIndices = strcmp(features, uniqueFeatures{i2});
    
    % Extract values for the current feature
    featureValues = values(featureIndices);
    
    % Assign these values to a variable with the sanitized feature name
    assignin('base', sanitizedFeatureName, featureValues);
end


resultsTable = table('Size', [0 5], ...
                     'VariableTypes', {'string', 'double', 'double', 'string', 'double'}, ...
                     'VariableNames', {'Feature', 'RepeatabilitySD', 'Repeatability6SD', 'PassFail', 'Threshold'})

                 
for i3 = 1:length(uniqueFeatures)
    feature = uniqueFeatures{i3};
    featureIndices = strcmp(features, feature);
    measurements = values(featureIndices);
    measurements2 = [];
    
    % Dynamically generate part columns based on the value of x
    partMeans = zeros(1, x);
    for j = 1:x
        partColumn = measurements(j:x:end);
        measurements2 = [measurements2; partColumn];
        partMeans(j) = mean(partColumn);
    end
    
    meanofmeans = mean(partMeans);
    mean_all = mean(measurements);
    
    % ANOVA calculations
    partsdf = x - 1;
    repeatabilitydf = x * (v - 1);
    totaldf = repeatabilitydf + partsdf;
    
    % Part sum of squares
    partss = v * sum((partMeans - mean_all).^2);
    
    % Repeatability ss - summed squared difference of each part
    repeatabilityss = sum((measurements2 - repelem(partMeans', v)).^2);
    
    % Total sum of squares            
    totalss = partss + repeatabilityss;
    
    % Mean squares
    repeatabilityms = repeatabilityss / repeatabilitydf;
    partms = partss / partsdf;
    F = partms / repeatabilityms;
    
    % Variance components
    repeatabilityvarcomp = repeatabilityms;
    part2partvarcomp = (partms - repeatabilityms) / v;
    if part2partvarcomp < 0
        part2partvarcomp = 0;
    end
    totalvar = repeatabilityvarcomp + part2partvarcomp;
    
    % Standard deviation
    repeatabilitysd = sqrt(repeatabilityvarcomp);
    totalsd = sqrt(totalvar);
    part2partsd = sqrt(part2partvarcomp);
    repeatability6sd = 6 * repeatabilitysd;
    
    % Study variance
    sv_repeatability = repeatabilitysd * sigma_level;
    sv_part2part = part2partsd * sigma_level;
    sv_totalvariation = totalsd * sigma_level;
    repeatability_percentSV = (sv_repeatability / sv_totalvariation) * 100;
    part2part_percentSV = (sv_part2part / sv_totalvariation) * 100;
    totalvariation_percentSV = 100;
    
    % Tolerance calculations
  
    
  % Determine the threshold based on the feature name and set passFail
if startsWith(feature, 'diameter', 'IgnoreCase', true)
    threshold = thresholds.diameter;
elseif startsWith(feature, 'height', 'IgnoreCase', true)
    threshold = thresholds.height;
elseif startsWith(feature, 'inside_curl', 'IgnoreCase', true)
    threshold = thresholds.inside_curl;
elseif startsWith(feature, 'material', 'IgnoreCase', true)
    threshold = thresholds.material;
elseif startsWith(feature, 'panel_height', 'IgnoreCase', true)
    threshold = thresholds.panel_height;
elseif startsWith(feature, 'countersink_depth', 'IgnoreCase', true)
    threshold = thresholds.countersink_depth;
elseif startsWith(feature, 'curl_height', 'IgnoreCase', true)
    threshold = thresholds.curl_height;
elseif startsWith(feature, 'curl_opening', 'IgnoreCase', true)
    threshold = thresholds.curl_opening;
elseif startsWith(feature, 'curl_conc', 'IgnoreCase', true)
    threshold = thresholds.curl_conc;
elseif startsWith(feature, 'lip_height', 'IgnoreCase', true)
    threshold = thresholds.lip_height;
elseif startsWith(feature, 'off_draw', 'IgnoreCase', true)
    threshold = thresholds.off_draw;
elseif startsWith(feature, 'uncurled_dia', 'IgnoreCase', true)
    threshold = thresholds.uncurled_dia;
elseif startsWith(feature, 'panel_coin', 'IgnoreCase', true)
    threshold = thresholds.panel_coin;
elseif startsWith(feature, 'bubble_height', 'IgnoreCase', true)
    threshold = thresholds.bubble_height;
elseif startsWith(feature, 'bubble_top_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_top_thickness;
elseif startsWith(feature, 'bubble_wall_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_wall_thickness;
elseif startsWith(feature, 'cent_bead', 'IgnoreCase', true)
    threshold = thresholds.cent_bead;
elseif startsWith(feature, 'shadow_bead', 'IgnoreCase', true)
    threshold = thresholds.shadow_bead;
elseif startsWith(feature, 'anti_missile', 'IgnoreCase', true)
    threshold = thresholds.anti_missile;
elseif startsWith(feature, 'rivet_diameter', 'IgnoreCase', true)
    threshold = thresholds.rivet_diameter;
elseif startsWith(feature, 'rivet_residual', 'IgnoreCase', true)
    threshold = thresholds.rivet_residual;
elseif startsWith(feature, 'inner_bead', 'IgnoreCase', true)
    threshold = thresholds.inner_bead;
elseif startsWith(feature, 'vent_bead', 'IgnoreCase', true)
    threshold = thresholds.vent_bead;
elseif contains(feature, 'button_coin', 'IgnoreCase', true)
    threshold = thresholds.button_coin;
elseif contains(feature, 'bubble_thickness', 'IgnoreCase', true)
    threshold = thresholds.bubble_thickness;
elseif contains(feature, 'deboss_panel', 'IgnoreCase', true)
    threshold = thresholds.deboss_panel;
elseif startsWith(feature, 'finger_panel', 'IgnoreCase', true)
    threshold = thresholds.finger_panel;
elseif contains(feature, 'Smile', 'IgnoreCase', true)
    threshold = thresholds.smile_bead;
elseif contains(feature, 'wall_bead', 'IgnoreCase', true)
    threshold = thresholds.wall_bead;
elseif contains(feature, 'Reduction', 'IgnoreCase', true)
    threshold = thresholds.reduction;
elseif contains(feature, 'Score', 'IgnoreCase', true)
    threshold = thresholds.score;
elseif contains(feature, 'outer_bead', 'IgnoreCase', true)
    threshold = thresholds.outer_bead;
elseif contains(feature, 'tear_panel', 'IgnoreCase', true)
    threshold = thresholds.tear_panel;
elseif contains(feature, 'stiffening_panel', 'IgnoreCase', true)
    threshold = thresholds.stiffening_panel;
elseif endsWith(feature, 'button_height', 'IgnoreCase', true)
    threshold = thresholds.button_height;
elseif startsWith(feature, 'fingerwell', 'IgnoreCase', true)
   threshold = thresholds.finger_panel;
elseif startsWith(feature, 'button_thickness', 'IgnoreCase', true)
   threshold = thresholds.Rivet_form_thickness;
elseif endsWith(feature, 'button_thickness', 'IgnoreCase', true)
   threshold = thresholds.Rivet_form_thickness;
elseif startsWith(feature, 'panel_form', 'IgnoreCase', true)
   threshold = thresholds.deboss_panel;
elseif startsWith(feature, 'anti_turn', 'IgnoreCase', true)
   threshold = thresholds.anti_rotation;
   elseif startsWith(feature, 'anti turn', 'IgnoreCase', true)
   threshold = thresholds.anti_rotation;
else
    threshold = []; % No specific threshold, set to empty to trigger 'N/A'
end

   % Determine Pass/Fail based on repeatabilitysd, only if threshold is defined
if isempty(threshold)
    passFail = 'N/A'; % or keep it as is if you want no change
else
    % Determine Pass/Fail based on repeatabilitysd, only if threshold is defined
    if Imperial == 1 
        threshold = threshold/25.399986284
    end
    if repeatabilitysd < threshold
        passFail = 'Pass';
    else
        passFail = 'Fail';
    end
end

  % Ensure 'feature' and 'passFail' are strings to match the table definition
feature = string(feature);
passFail = string(passFail);

    % Handle threshold, converting to NaN if it's not numeric
if isempty(threshold)
    threshold = NaN;  % Use NaN for non-numeric thresholds
else
    threshold = double(threshold); % Ensure it’s of type double
end
       % Create a new row to add to the results table, ensuring consistent data types
newRow = {feature, repeatabilitysd, repeatability6sd, passFail, threshold};

% Convert newRow to a table to ensure data types match
newRowTable = cell2table(newRow, 'VariableNames', resultsTable.Properties.VariableNames);

% Add the new row to the results table
resultsTable = [resultsTable; newRowTable];
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


feature = strrep(feature, ':', '_'); % Rename as ':' invalidates file names
feature = strrep(feature, '_', ' ');


end


numTables = 3;

% Get the total number of rows in the results table
totalRows = height(resultsTable);

% Calculate the number of rows for each table
rowsPerTable = floor(totalRows / numTables);
remainderRows = mod(totalRows, numTables);

% Initialize the starting index for each table
startRow = 1;

% Loop to create each of the split tables
for i4 = 1:numTables
    % Determine the end row for the current table
    if i4 <= remainderRows
        endRow = startRow + rowsPerTable;
    else
        endRow = startRow + rowsPerTable - 1;
    end
    
    % Extract rows for the current table
    currentTable = resultsTable(startRow:endRow, :);
    
    % Assign the current table to the appropriate variable
    eval(sprintf('resultsTable%d = currentTable;', i4));
    
    % Update the starting row for the next table
    startRow = endRow + 1;
end

    app.UITable9_4.Data = resultsTable1;
     app.UITable9_5.Data = resultsTable2;
        app.UITable9_6.Data = resultsTable3;
     
feature_names = uniqueFeatures;

totalRows = height(resultsTable);

% Define the maximum number of rows per table
maxRowsPerTable = 36;

% Split the resultsTable based on the number of rows
if totalRows > maxRowsPerTable
    % First table and figure (rows 1 to 36)
    resultsTable_1 = resultsTable(1:maxRowsPerTable, :);
    feature_names1 = feature_names(1:maxRowsPerTable);
    
    % Convert the first half of the table data to cell array and format it
    dataCellArray1 = table2cell(resultsTable_1);
    dataCellArray1 = dataCellArray1(:, 2:end);
    dataCellArray1 = cellfun(@num2str, dataCellArray1, 'UniformOutput', false);

    % Create the first figure and table
    f1 = figure('Name', 'Figure 1', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
    uitable('Data', dataCellArray1, "RowName", feature_names1, ...
            'ColumnName', {'1 SD', '6 SD', 'P/F', 'Threshold(1SD)'}, ...
            'Position', [10, 90, 780, 800], 'ColumnWidth', {65, 65, 38, 140});
    sgtitle(sprintf('ES8000 %s Summary 1', currentfile), 'Interpreter', 'none');

    % Add annotation with the note
annotation(f1, 'textbox', [0.1, 0.02, 0.8, 0.05], 'String', ...
           'For Progressions, Height and Countersink Depth 1SD threshold increases to 0.01 mm / 0.0004 inches, so if it displays fails, recheck with correct threshold', ...
           'EdgeColor', 'none', 'FontSize', 8, 'HorizontalAlignment', 'center');
       
    % Save the first figure
    filename = sprintf('%d- Es8000 Report-Summary-1', pdfno)
    filePath = fullfile(fullFolderPath, filename);
    print(f1, filePath, '-dpdf');
    

    % Second table and figure (rows 37 onwards, if applicable)
    resultsTable_2 = resultsTable(maxRowsPerTable+1:end, :);
    feature_names2 = feature_names(maxRowsPerTable+1:end);
    
    % Convert the second half of the table data to cell array and format it
    dataCellArray2 = table2cell(resultsTable_2);
    dataCellArray2 = dataCellArray2(:, 2:end);
    dataCellArray2 = cellfun(@num2str, dataCellArray2, 'UniformOutput', false);

    % Create the second figure and table
    f2 = figure('Name', 'Figure 2', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
    uitable('Data', dataCellArray2, "RowName", feature_names2, ...
            'ColumnName', {'1 SD', '6 SD', 'P/F', 'Threshold(1SD)'}, ...
            'Position', [10, 90, 780, 800], 'ColumnWidth', {65, 65, 38, 140});
    sgtitle(sprintf('ES8000 %s Summary 2', currentfile), 'Interpreter', 'none');
annotation(f2, 'textbox', [0.1, 0.02, 0.8, 0.05], 'String', ...
           'For Progressions, Height and Countersink Depth 1SD threshold increases to 0.01 mm / 0.0004 inches, so if it displays fails, recheck with correct threshold', ...
           'EdgeColor', 'none', 'FontSize', 8, 'HorizontalAlignment', 'center');
    % Save the second figure
    filename = sprintf('%d- Es8000-Summary-2',pdfno);
    filePath = fullfile(fullFolderPath, filename);
    print(f2, filePath, '-dpdf');
    close all
else
    % If the total number of rows is 36 or less, only create the first table and figure
    resultsTable_1 = resultsTable;
    feature_names1 = feature_names;
    
    % Convert the table data to cell array and format it
    dataCellArray1 = table2cell(resultsTable_1);
    dataCellArray1 = dataCellArray1(:, 2:end);
    dataCellArray1 = cellfun(@num2str, dataCellArray1, 'UniformOutput', false);

    % Create the first figure and table
    f1 = figure('Name', 'Figure 1', 'NumberTitle', 'off', 'Position', [200, 200, 800, 1000]);
    uitable('Data', dataCellArray1, "RowName", feature_names1, ...
            'ColumnName', {'1 SD', '6 SD', 'P/F', 'Threshold(1SD)'}, ...
            'Position', [10, 90, 780, 800], 'ColumnWidth', {65, 65, 38, 140});
    sgtitle(sprintf('ES8000 %s Summary 1', currentfile), 'Interpreter', 'none');
annotation(f1, 'textbox', [0.1, 0.02, 0.8, 0.05], 'String', ...
           'For Progressions, Height and Countersink Depth 1SD threshold increases to 0.01 mm / 0.0004 inches, so if it displays fails, recheck with correct threshold', ...
           'EdgeColor', 'none', 'FontSize', 8, 'HorizontalAlignment', 'center');
    % Save the first figure
    filename = sprintf('%d- Es8000-Summary-1',pdfno);
    filePath = fullfile(fullFolderPath, filename);
    print(f1, filePath, '-dpdf');
    close all
end
        catch ME
            
        end
    end
end


% Step 1: Specify the directory containing the PDF files
directoryPath = fullFolderPath; % Replace with your directory path

% Step 2: Get a list of all PDF files in the directory
cd(directoryPath); % Change to the directory with the PDF files
pdffiles = dir('*.pdf'); % Get all PDF files in the directory

% Step 3: Extract filenames into a cell array
pdf_filenames = {pdffiles.name};

% Step 4: Specify the output filename
outputFileName = sprintf('_ES8000 R&R Report_%s_%s_%s_%s.pdf', gname, date, operator, misc);

% Step 5: Merge the PDFs using the mergePdfs function
mergePdfs(pdf_filenames, outputFileName);


        end

        % Close request function: Gage_RR_App
        function Gage_RR_AppCloseRequest(app, event)
            
            % Create a structure with the values from edit fields
    data.EditFieldValue1 = app.OperatorEditField.Value
    data.EditFieldValue2 = app.OperatorEditField_2.Value
    data.EditFieldValue3 = app.OperatorEditField_5.Value;
    data.EditFieldValue4 = app.OperatorEditField_9.Value;
    data.EditFieldValue5 = app.OperatorEditField_6.Value;
    data.EditFieldValue6 = app.OperatorEditField_10.Value;
    data.EditFieldValue7 = app.OperatorEditField_3.Value;
    data.EditFieldValue8 = app.OperatorEditField_13.Value;
    data.EditFieldValue9 = app.OperatorEditField_14.Value;
    data.EditFieldValue10 = app.OperatorEditField_12.Value;
    data.EditFieldValue11 = app.OperatorEditField_11.Value;
    data.EditFieldValue12 = app.OperatorEditField_4.Value;
    data.EditFieldValue13 = app.OperatorEditField_8.Value;
    data.EditFieldValue14 = app.DirectoryEditField.Value;
    
data.EditFieldValue15 = app.DirectoryEditField.Value;
data.EditFieldValue16 = app.DirectoryEditField_2.Value;
data.EditFieldValue17 = app.DirectoryEditField_5.Value;
data.EditFieldValue18 = app.DirectoryEditField_10.Value;
data.EditFieldValue19 = app.DirectoryEditField_6.Value;
data.EditFieldValue20 = app.DirectoryEditField_11.Value;
data.EditFieldValue21 = app.DirectoryEditField_3.Value;
data.EditFieldValue22 = app.DirectoryEditField_14.Value;
data.EditFieldValue23 = app.DirectoryEditField_15.Value;
data.EditFieldValue24 = app.DirectoryEditField_13.Value;
data.EditFieldValue25 = app.DirectoryEditField_12.Value;
data.EditFieldValue26 = app.DirectoryEditField_4.Value;
data.EditFieldValue27 = app.DirectoryEditField_9.Value;
data.EditFieldValue28 = app.DrivelettertoPullStoredataEditField.Value 
    
    
      % Get the path of the app's folder
    appPath = fileparts(mfilename('fullpath'))  % Get the current app directory
    fullPath = fullfile(appPath, 'appData.mat')  % Create full path to appData.mat

    % Save the data to a .mat file in the app's directory
    save(fullPath, '-struct', 'data');  % Save using the full path
    delete(app);  % Close the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Gage_RR_App = uifigure('Visible', 'off');
            app.Gage_RR_App.Name = 'Gage RR App';
            app.Gage_RR_App.CloseRequestFcn = createCallbackFcn(app, @Gage_RR_AppCloseRequest, true);
            app.TabGroup = uitabgroup(app.Gage_RR_App);
            app.BackendCanTab = uitab(app.TabGroup);
            app.BackendCanTab.Title = 'Backend - Can';
            app.UITable2 = uitable(app.BackendCanTab);
            app.UITable2.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2.ColumnEditable = true;
            app.UIAxes = uiaxes(app.BackendCanTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.BackendCanTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.BackendCanTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.BackendCanTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, 'Y')
            app.UITable8 = uitable(app.BackendCanTab);
            app.UITable8.ColumnName = {'Height'; 'Flange Width'; 'Plug Diameter'; 'Dome Depth'; 'Reform Diameter'};
            app.UITable8.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel = uilabel(app.BackendCanTab);
            app.SummaryTableLabel.Text = 'Summary Table';
            app.UpdateButton = uibutton(app.BackendCanTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Panel = uipanel(app.BackendCanTab);
            app.GageNameSNEditFieldLabel = uilabel(app.Panel);
            app.GageNameSNEditFieldLabel.Text = 'Gage Name + SN';
            app.GageNameSNEditField = uieditfield(app.Panel, 'text');
            app.CompanyEditFieldLabel = uilabel(app.Panel);
            app.CompanyEditFieldLabel.Text = 'Company';
            app.CompanyEditField = uieditfield(app.Panel, 'text');
            app.OperatorEditFieldLabel = uilabel(app.Panel);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.OperatorEditField = uieditfield(app.Panel, 'text');
            app.FilenameInfoLabel = uilabel(app.Panel);
            app.FilenameInfoLabel.Text = 'Filename Info: ';
            app.DirectoryEditFieldLabel = uilabel(app.Panel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.BackendCanTab);
            app.HeightToleranceEditFieldLabel = uilabel(app.Panel_2);
            app.HeightToleranceEditFieldLabel.Text = 'Height Tolerance';
            app.HeightToleranceEditField = uieditfield(app.Panel_2, 'numeric');
            app.HeightToleranceEditField.Value = 0.4;
            app.FlangeWidthToleranceEditFieldLabel = uilabel(app.Panel_2);
            app.FlangeWidthToleranceEditFieldLabel.Text = 'Flange Width Tolerance';
            app.FlangeWidthToleranceEditField = uieditfield(app.Panel_2, 'numeric');
            app.FlangeWidthToleranceEditField.Value = 0.4;
            app.PlugDiameterToleranceEditFieldLabel = uilabel(app.Panel_2);
            app.PlugDiameterToleranceEditFieldLabel.Text = 'Plug Diameter Tolerance';
            app.PlugDiameterToleranceEditField = uieditfield(app.Panel_2, 'numeric');
            app.PlugDiameterToleranceEditField.Value = 0.6;
            app.MaxReformDiameterToleranceEditFieldLabel = uilabel(app.Panel_2);
            app.MaxReformDiameterToleranceEditFieldLabel.Text = 'Max Reform Diameter Tolerance';
            app.MaxReformDiameterToleranceEditField = uieditfield(app.Panel_2, 'numeric');
            app.MaxReformDiameterToleranceEditField.Value = 0.6;
            app.DomeDepthToleranceEditFieldLabel = uilabel(app.Panel_2);
            app.DomeDepthToleranceEditFieldLabel.Text = 'Dome Depth Tolerance';
            app.DomeDepthToleranceEditField = uieditfield(app.Panel_2, 'numeric');
            app.DomeDepthToleranceEditField.Value = 0.3;
            app.Panel_3 = uipanel(app.BackendCanTab);
            app.SigmaLevelEditFieldLabel = uilabel(app.Panel_3);
            app.SigmaLevelEditFieldLabel.Text = 'Sigma Level';
            app.SigmaLevelEditField = uieditfield(app.Panel_3, 'numeric');
            app.SigmaLevelEditField.Value = 5.15;
            app.Panel_4 = uipanel(app.BackendCanTab);
            app.ShowReformResultsCheckBox = uicheckbox(app.Panel_4);
            app.ShowReformResultsCheckBox.Text = 'Show Reform Results';
            app.ShowAnovatablesCheckBox = uicheckbox(app.Panel_4);
            app.ShowAnovatablesCheckBox.Text = 'Show Anova tables';
            app.Panel_5 = uipanel(app.BackendCanTab);
            app.RunanalysisButton = uibutton(app.Panel_5, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.ShowResultsforDropDownLabel = uilabel(app.BackendCanTab);
            app.ShowResultsforDropDownLabel.Text = 'Show Results for';
            app.ShowResultsforDropDown = uidropdown(app.BackendCanTab);
            app.ShowResultsforDropDown.Items = {'Can Height', 'Can Flange Width', 'Can Plug Diameter', 'Can Reform Diameter', 'Can Dome Depth'};
            app.ShowResultsforDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowResultsforDropDownValueChanged, true);
            app.ShowResultsforDropDown.Value = 'Can Height';
            app.BackendSPTab = uitab(app.TabGroup);
            app.BackendSPTab.Title = 'Backend - SP';
            app.UITable2_2 = uitable(app.BackendSPTab);
            app.UITable2_2.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_2.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_2.ColumnEditable = true;
            app.UIAxes_2 = uiaxes(app.BackendSPTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes2_2 = uiaxes(app.BackendSPTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes3_2 = uiaxes(app.BackendSPTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes4_2 = uiaxes(app.BackendSPTab);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, 'Y')
            app.UITable8_2 = uitable(app.BackendSPTab);
            app.UITable8_2.ColumnName = {'Height'; 'Diameter 1'; 'Diameter 2'; 'Dome Depth'; 'Reform Diameter'};
            app.UITable8_2.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_2 = uilabel(app.BackendSPTab);
            app.SummaryTableLabel_2.Text = 'Summary Table';
            app.UpdateButton_2 = uibutton(app.BackendSPTab, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.Panel_6 = uipanel(app.BackendSPTab);
            app.GageNameSNEditField_2Label = uilabel(app.Panel_6);
            app.GageNameSNEditField_2Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_2 = uieditfield(app.Panel_6, 'text');
            app.CompanyEditField_2Label = uilabel(app.Panel_6);
            app.CompanyEditField_2Label.Text = 'Company';
            app.CompanyEditField_2 = uieditfield(app.Panel_6, 'text');
            app.OperatorEditField_2Label = uilabel(app.Panel_6);
            app.OperatorEditField_2Label.Text = 'Operator';
            app.OperatorEditField_2 = uieditfield(app.Panel_6, 'text');
            app.FilenameInfoLabel_2 = uilabel(app.Panel_6);
            app.FilenameInfoLabel_2.Text = 'Filename Info: ';
            app.DirectoryEditField_2Label = uilabel(app.Panel_6);
            app.DirectoryEditField_2Label.Text = 'Directory';
            app.DirectoryEditField_2 = uieditfield(app.Panel_6, 'text');
            app.Panel_7 = uipanel(app.BackendSPTab);
            app.HeightToleranceEditField_2Label = uilabel(app.Panel_7);
            app.HeightToleranceEditField_2Label.Text = 'Height Tolerance';
            app.HeightToleranceEditField_2 = uieditfield(app.Panel_7, 'numeric');
            app.HeightToleranceEditField_2.Value = 0.12;
            app.ReformDiameterToleranceEditFieldLabel = uilabel(app.Panel_7);
            app.ReformDiameterToleranceEditFieldLabel.Text = 'Reform Diameter Tolerance';
            app.ReformDiameterToleranceEditField = uieditfield(app.Panel_7, 'numeric');
            app.ReformDiameterToleranceEditField.Value = 0.12;
            app.DomeDepthToleranceEditField_3Label = uilabel(app.Panel_7);
            app.DomeDepthToleranceEditField_3Label.Text = 'Dome Depth Tolerance';
            app.DomeDepthToleranceEditField_3 = uieditfield(app.Panel_7, 'numeric');
            app.DomeDepthToleranceEditField_3.Value = 0.12;
            app.Diameter1ToleranceEditFieldLabel = uilabel(app.Panel_7);
            app.Diameter1ToleranceEditFieldLabel.Text = 'Diameter 1 Tolerance';
            app.Diameter1ToleranceEditField = uieditfield(app.Panel_7, 'numeric');
            app.Diameter1ToleranceEditField.Value = 0.12;
            app.Diameter2ToleranceLabel = uilabel(app.Panel_7);
            app.Diameter2ToleranceLabel.Text = 'Diameter 2 Tolerance';
            app.Diameter2ToleranceEditField = uieditfield(app.Panel_7, 'numeric');
            app.Diameter2ToleranceEditField.Value = 0.12;
            app.Panel_8 = uipanel(app.BackendSPTab);
            app.SigmaLevelEditField_2Label = uilabel(app.Panel_8);
            app.SigmaLevelEditField_2Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.SigmaLevelEditField_2.Value = 5.15;
            app.Panel_9 = uipanel(app.BackendSPTab);
            app.ShowReformResultsCheckBox_2 = uicheckbox(app.Panel_9);
            app.ShowReformResultsCheckBox_2.Text = 'Show Reform Results';
            app.ShowAnovatablesCheckBox_2 = uicheckbox(app.Panel_9);
            app.ShowAnovatablesCheckBox_2.Text = 'Show Anova tables';
            app.Panel_10 = uipanel(app.BackendSPTab);
            app.RunanalysisButton_2 = uibutton(app.Panel_10, 'push');
            app.RunanalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_2Pushed, true);
            app.RunanalysisButton_2.Text = 'Run analysis';
            app.ShowResultsforDropDown_2Label = uilabel(app.BackendSPTab);
            app.ShowResultsforDropDown_2Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_2 = uidropdown(app.BackendSPTab);
            app.ShowResultsforDropDown_2.Items = {'Setting Piece Height', 'Setting Piece Reform Diameter', 'Setting Piece Dome Depth', 'Setting Piece Diameter 1', 'Setting Piece Diameter 2'};
            app.ShowResultsforDropDown_2.Value = 'Setting Piece Height';
            app.FrontendCanTab = uitab(app.TabGroup);
            app.FrontendCanTab.Title = 'Frontend - Can';
            app.UITable2_5 = uitable(app.FrontendCanTab);
            app.UITable2_5.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_5.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_5.ColumnEditable = true;
            app.UIAxes_5 = uiaxes(app.FrontendCanTab);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes2_5 = uiaxes(app.FrontendCanTab);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, '')
            ylabel(app.UIAxes2_5, 'Y')
            app.UIAxes3_5 = uiaxes(app.FrontendCanTab);
            title(app.UIAxes3_5, 'Title')
            xlabel(app.UIAxes3_5, '')
            ylabel(app.UIAxes3_5, 'Y')
            app.UIAxes4_5 = uiaxes(app.FrontendCanTab);
            title(app.UIAxes4_5, 'Title')
            xlabel(app.UIAxes4_5, '')
            ylabel(app.UIAxes4_5, 'Y')
            app.UITable8_4 = uitable(app.FrontendCanTab);
            app.UITable8_4.ColumnName = {'Can Height'; 'Can Dome Depth'; 'Can Top Wall'; 'Can Mid Wall'};
            app.UITable8_4.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_4 = uilabel(app.FrontendCanTab);
            app.SummaryTableLabel_4.Text = 'Summary Table';
            app.UpdateButton_4 = uibutton(app.FrontendCanTab, 'push');
            app.UpdateButton_4.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_4Pushed, true);
            app.UpdateButton_4.Text = 'Update';
            app.Panel_22 = uipanel(app.FrontendCanTab);
            app.GageNameSNEditField_5Label = uilabel(app.Panel_22);
            app.GageNameSNEditField_5Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_5 = uieditfield(app.Panel_22, 'text');
            app.CompanyEditField_5Label = uilabel(app.Panel_22);
            app.CompanyEditField_5Label.Text = 'Company';
            app.CompanyEditField_5 = uieditfield(app.Panel_22, 'text');
            app.OperatorEditField_5Label = uilabel(app.Panel_22);
            app.OperatorEditField_5Label.Text = 'Operator';
            app.OperatorEditField_5 = uieditfield(app.Panel_22, 'text');
            app.FilenameInfoLabel_5 = uilabel(app.Panel_22);
            app.FilenameInfoLabel_5.Text = 'Filename Info: ';
            app.DirectoryEditField_5Label = uilabel(app.Panel_22);
            app.DirectoryEditField_5Label.Text = 'Directory';
            app.DirectoryEditField_5 = uieditfield(app.Panel_22, 'text');
            app.Panel_23 = uipanel(app.FrontendCanTab);
            app.CanHeightToleranceEditFieldLabel = uilabel(app.Panel_23);
            app.CanHeightToleranceEditFieldLabel.Text = 'Can Height Tolerance';
            app.CanHeightToleranceEditField = uieditfield(app.Panel_23, 'numeric');
            app.CanHeightToleranceEditField.Value = 0.2;
            app.CanDomeDepthToleranceEditFieldLabel = uilabel(app.Panel_23);
            app.CanDomeDepthToleranceEditFieldLabel.Text = 'Can Dome Depth Tolerance';
            app.CanDomeDepthToleranceEditField = uieditfield(app.Panel_23, 'numeric');
            app.CanDomeDepthToleranceEditField.Value = 0.2;
            app.CanTopWallToleranceEditFieldLabel = uilabel(app.Panel_23);
            app.CanTopWallToleranceEditFieldLabel.Text = 'Can Top Wall Tolerance';
            app.CanTopWallToleranceEditField = uieditfield(app.Panel_23, 'numeric');
            app.CanTopWallToleranceEditField.Value = 0.01;
            app.CanMidWallToleranceEditFieldLabel = uilabel(app.Panel_23);
            app.CanMidWallToleranceEditFieldLabel.Text = 'Can Mid Wall Tolerance';
            app.CanMidWallToleranceEditField = uieditfield(app.Panel_23, 'numeric');
            app.CanMidWallToleranceEditField.Value = 0.01;
            app.Panel_24 = uipanel(app.FrontendCanTab);
            app.SigmaLevelEditField_5Label = uilabel(app.Panel_24);
            app.SigmaLevelEditField_5Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_5 = uieditfield(app.Panel_24, 'numeric');
            app.SigmaLevelEditField_5.Value = 5.15;
            app.Panel_25 = uipanel(app.FrontendCanTab);
            app.ShowAnovatablesCheckBox_5 = uicheckbox(app.Panel_25);
            app.ShowAnovatablesCheckBox_5.Text = 'Show Anova tables';
            app.Panel_26 = uipanel(app.FrontendCanTab);
            app.RunanalysisButton_5 = uibutton(app.Panel_26, 'push');
            app.RunanalysisButton_5.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_5Pushed, true);
            app.RunanalysisButton_5.Text = 'Run analysis';
            app.ShowResultsforDropDown_4Label = uilabel(app.FrontendCanTab);
            app.ShowResultsforDropDown_4Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_4 = uidropdown(app.FrontendCanTab);
            app.ShowResultsforDropDown_4.Items = {'Can Height', 'Can Dome Depth', 'Can Top Wall', 'Can Mid Wall'};
            app.ShowResultsforDropDown_4.Value = 'Can Height';
            app.FrontendSPTab = uitab(app.TabGroup);
            app.FrontendSPTab.Title = 'Frontend - SP';
            app.UITable2_7 = uitable(app.FrontendSPTab);
            app.UITable2_7.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_7.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_7.ColumnEditable = true;
            app.UIAxes_7 = uiaxes(app.FrontendSPTab);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes2_7 = uiaxes(app.FrontendSPTab);
            title(app.UIAxes2_7, 'Title')
            xlabel(app.UIAxes2_7, '')
            ylabel(app.UIAxes2_7, 'Y')
            app.UIAxes3_7 = uiaxes(app.FrontendSPTab);
            title(app.UIAxes3_7, 'Title')
            xlabel(app.UIAxes3_7, '')
            ylabel(app.UIAxes3_7, 'Y')
            app.UIAxes4_7 = uiaxes(app.FrontendSPTab);
            title(app.UIAxes4_7, 'Title')
            xlabel(app.UIAxes4_7, '')
            ylabel(app.UIAxes4_7, 'Y')
            app.UITable8_6 = uitable(app.FrontendSPTab);
            app.UITable8_6.ColumnName = {'Setting Piece Height'; 'Setting Piece Dome Depth'};
            app.UITable8_6.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_6 = uilabel(app.FrontendSPTab);
            app.SummaryTableLabel_6.Text = 'Summary Table';
            app.UpdateButton_7 = uibutton(app.FrontendSPTab, 'push');
            app.UpdateButton_7.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_7Pushed, true);
            app.UpdateButton_7.Text = 'Update';
            app.Panel_34 = uipanel(app.FrontendSPTab);
            app.GageNameSNEditField_9Label = uilabel(app.Panel_34);
            app.GageNameSNEditField_9Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_9 = uieditfield(app.Panel_34, 'text');
            app.CompanyEditField_7Label = uilabel(app.Panel_34);
            app.CompanyEditField_7Label.Text = 'Company';
            app.CompanyEditField_7 = uieditfield(app.Panel_34, 'text');
            app.OperatorEditField_9Label = uilabel(app.Panel_34);
            app.OperatorEditField_9Label.Text = 'Operator';
            app.OperatorEditField_9 = uieditfield(app.Panel_34, 'text');
            app.FilenameInfoLabel_9 = uilabel(app.Panel_34);
            app.FilenameInfoLabel_9.Text = 'Filename Info: ';
            app.DirectoryEditField_10Label = uilabel(app.Panel_34);
            app.DirectoryEditField_10Label.Text = 'Directory';
            app.DirectoryEditField_10 = uieditfield(app.Panel_34, 'text');
            app.Panel_35 = uipanel(app.FrontendSPTab);
            app.SettingPieceHeightToleranceEditField_2Label = uilabel(app.Panel_35);
            app.SettingPieceHeightToleranceEditField_2Label.Text = 'Setting Piece Height Tolerance';
            app.SettingPieceHeightToleranceEditField_2 = uieditfield(app.Panel_35, 'numeric');
            app.SettingPieceHeightToleranceEditField_2.Value = 0.02;
            app.SettingPieceDomeDepthToleranceEditField_3Label = uilabel(app.Panel_35);
            app.SettingPieceDomeDepthToleranceEditField_3Label.Text = 'Setting Piece Dome Depth Tolerance';
            app.SettingPieceDomeDepthToleranceEditField_3 = uieditfield(app.Panel_35, 'numeric');
            app.SettingPieceDomeDepthToleranceEditField_3.Value = 0.02;
            app.Panel_36 = uipanel(app.FrontendSPTab);
            app.SigmaLevelEditField_7Label = uilabel(app.Panel_36);
            app.SigmaLevelEditField_7Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_7 = uieditfield(app.Panel_36, 'numeric');
            app.SigmaLevelEditField_7.Value = 5.15;
            app.Panel_37 = uipanel(app.FrontendSPTab);
            app.ShowAnovatablesCheckBox_7 = uicheckbox(app.Panel_37);
            app.ShowAnovatablesCheckBox_7.Text = 'Show Anova tables';
            app.Panel_38 = uipanel(app.FrontendSPTab);
            app.RunanalysisButton_7 = uibutton(app.Panel_38, 'push');
            app.RunanalysisButton_7.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_7Pushed, true);
            app.RunanalysisButton_7.Text = 'Run analysis';
            app.ShowResultsforDropDown_7Label = uilabel(app.FrontendSPTab);
            app.ShowResultsforDropDown_7Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_7 = uidropdown(app.FrontendSPTab);
            app.ShowResultsforDropDown_7.Items = {'Setting Piece Height', 'Setting Piece Dome Depth'};
            app.ShowResultsforDropDown_7.Value = 'Setting Piece Height';
            app.DRGCanTab = uitab(app.TabGroup);
            app.DRGCanTab.Title = 'DRG - Can';
            app.UITable2_6 = uitable(app.DRGCanTab);
            app.UITable2_6.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_6.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_6.ColumnEditable = true;
            app.UIAxes_6 = uiaxes(app.DRGCanTab);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes2_6 = uiaxes(app.DRGCanTab);
            title(app.UIAxes2_6, 'Title')
            xlabel(app.UIAxes2_6, '')
            ylabel(app.UIAxes2_6, 'Y')
            app.UIAxes3_6 = uiaxes(app.DRGCanTab);
            title(app.UIAxes3_6, 'Title')
            xlabel(app.UIAxes3_6, '')
            ylabel(app.UIAxes3_6, 'Y')
            app.UIAxes4_6 = uiaxes(app.DRGCanTab);
            title(app.UIAxes4_6, 'Title')
            xlabel(app.UIAxes4_6, '')
            ylabel(app.UIAxes4_6, 'Y')
            app.UITable8_5 = uitable(app.DRGCanTab);
            app.UITable8_5.ColumnName = {'Can Dome Depth'; 'Can ReformDiameter'; 'Can Chuck Value'; 'Can Reform Height'};
            app.UITable8_5.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_5 = uilabel(app.DRGCanTab);
            app.SummaryTableLabel_5.Text = 'Summary Table';
            app.UpdateButton_5 = uibutton(app.DRGCanTab, 'push');
            app.UpdateButton_5.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_5Pushed, true);
            app.UpdateButton_5.Text = 'Update';
            app.Panel_27 = uipanel(app.DRGCanTab);
            app.GageNameSNEditField_6Label = uilabel(app.Panel_27);
            app.GageNameSNEditField_6Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_6 = uieditfield(app.Panel_27, 'text');
            app.CompanyEditField_6Label = uilabel(app.Panel_27);
            app.CompanyEditField_6Label.Text = 'Company';
            app.CompanyEditField_6 = uieditfield(app.Panel_27, 'text');
            app.OperatorEditField_6Label = uilabel(app.Panel_27);
            app.OperatorEditField_6Label.Text = 'Operator';
            app.OperatorEditField_6 = uieditfield(app.Panel_27, 'text');
            app.FilenameInfoLabel_6 = uilabel(app.Panel_27);
            app.FilenameInfoLabel_6.Text = 'Filename Info: ';
            app.DirectoryEditField_6Label = uilabel(app.Panel_27);
            app.DirectoryEditField_6Label.Text = 'Directory';
            app.DirectoryEditField_6 = uieditfield(app.Panel_27, 'text');
            app.Panel_28 = uipanel(app.DRGCanTab);
            app.CanDomeDepthToleranceEditField_2Label = uilabel(app.Panel_28);
            app.CanDomeDepthToleranceEditField_2Label.Text = 'Can Dome Depth Tolerance';
            app.CanDomeDepthToleranceEditField_2 = uieditfield(app.Panel_28, 'numeric');
            app.CanDomeDepthToleranceEditField_2.Value = 0.3;
            app.CanMaxReformDiameterToleranceEditFieldLabel = uilabel(app.Panel_28);
            app.CanMaxReformDiameterToleranceEditFieldLabel.Text = 'Can Max Reform Diameter Tolerance';
            app.CanMaxReformDiameterToleranceEditField = uieditfield(app.Panel_28, 'numeric');
            app.CanMaxReformDiameterToleranceEditField.Value = 0.6;
            app.CanChuckValueToleranceEditFieldLabel = uilabel(app.Panel_28);
            app.CanChuckValueToleranceEditFieldLabel.Text = 'Can Chuck Value Tolerance';
            app.CanChuckValueToleranceEditField = uieditfield(app.Panel_28, 'numeric');
            app.CanChuckValueToleranceEditField.Value = 0.02;
            app.CanReformHeightToleranceEditFieldLabel = uilabel(app.Panel_28);
            app.CanReformHeightToleranceEditFieldLabel.Text = 'Can Reform Height Tolerance';
            app.CanReformHeightToleranceEditField = uieditfield(app.Panel_28, 'numeric');
            app.CanReformHeightToleranceEditField.Value = 0.3;
            app.Panel_29 = uipanel(app.DRGCanTab);
            app.SigmaLevelEditField_6Label = uilabel(app.Panel_29);
            app.SigmaLevelEditField_6Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_6 = uieditfield(app.Panel_29, 'numeric');
            app.SigmaLevelEditField_6.Value = 5.15;
            app.Panel_30 = uipanel(app.DRGCanTab);
            app.ShowAnovatablesCheckBox_6 = uicheckbox(app.Panel_30);
            app.ShowAnovatablesCheckBox_6.Text = 'Show Anova tables';
            app.Panel_31 = uipanel(app.DRGCanTab);
            app.RunanalysisButton_6 = uibutton(app.Panel_31, 'push');
            app.RunanalysisButton_6.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_6Pushed2, true);
            app.RunanalysisButton_6.Text = 'Run analysis';
            app.ShowResultsforDropDown_5Label = uilabel(app.DRGCanTab);
            app.ShowResultsforDropDown_5Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_5 = uidropdown(app.DRGCanTab);
            app.ShowResultsforDropDown_5.Items = {'Can Dome Depth', 'Can Max Reform Diameter', 'Can Chuck Value', 'Can Reform Height'};
            app.ShowResultsforDropDown_5.Value = 'Can Dome Depth';
            app.DRGSPTab = uitab(app.TabGroup);
            app.DRGSPTab.Title = 'DRG - SP';
            app.UITable2_8 = uitable(app.DRGSPTab);
            app.UITable2_8.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_8.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_8.ColumnEditable = true;
            app.UIAxes_8 = uiaxes(app.DRGSPTab);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, 'Y')
            app.UIAxes2_8 = uiaxes(app.DRGSPTab);
            title(app.UIAxes2_8, 'Title')
            xlabel(app.UIAxes2_8, '')
            ylabel(app.UIAxes2_8, 'Y')
            app.UIAxes3_8 = uiaxes(app.DRGSPTab);
            title(app.UIAxes3_8, 'Title')
            xlabel(app.UIAxes3_8, '')
            ylabel(app.UIAxes3_8, 'Y')
            app.UIAxes4_8 = uiaxes(app.DRGSPTab);
            title(app.UIAxes4_8, 'Title')
            xlabel(app.UIAxes4_8, '')
            ylabel(app.UIAxes4_8, 'Y')
            app.UITable8_7 = uitable(app.DRGSPTab);
            app.UITable8_7.ColumnName = {'SP Dome Depth'; 'SP Reform DIameter'; 'SP Chuck Value'};
            app.UITable8_7.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_7 = uilabel(app.DRGSPTab);
            app.SummaryTableLabel_7.Text = 'Summary Table';
            app.UpdateButton_8 = uibutton(app.DRGSPTab, 'push');
            app.UpdateButton_8.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_8Pushed, true);
            app.UpdateButton_8.Text = 'Update';
            app.Panel_39 = uipanel(app.DRGSPTab);
            app.GageNameSNEditField_10Label = uilabel(app.Panel_39);
            app.GageNameSNEditField_10Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_10 = uieditfield(app.Panel_39, 'text');
            app.CompanyEditField_8Label = uilabel(app.Panel_39);
            app.CompanyEditField_8Label.Text = 'Company';
            app.CompanyEditField_8 = uieditfield(app.Panel_39, 'text');
            app.OperatorEditField_10Label = uilabel(app.Panel_39);
            app.OperatorEditField_10Label.Text = 'Operator';
            app.OperatorEditField_10 = uieditfield(app.Panel_39, 'text');
            app.FilenameInfoLabel_10 = uilabel(app.Panel_39);
            app.FilenameInfoLabel_10.Text = 'Filename Info: ';
            app.DirectoryEditField_11Label = uilabel(app.Panel_39);
            app.DirectoryEditField_11Label.Text = 'Directory';
            app.DirectoryEditField_11 = uieditfield(app.Panel_39, 'text');
            app.Panel_40 = uipanel(app.DRGSPTab);
            app.SettingPieceDomeDepthToleranceEditField_4Label = uilabel(app.Panel_40);
            app.SettingPieceDomeDepthToleranceEditField_4Label.Text = 'Setting Piece Dome Depth Tolerance';
            app.SettingPieceDomeDepthToleranceEditField_4 = uieditfield(app.Panel_40, 'numeric');
            app.SettingPieceDomeDepthToleranceEditField_4.Value = 0.12;
            app.SettingPieceMaxReformDiameterToleranceEditField_2Label = uilabel(app.Panel_40);
            app.SettingPieceMaxReformDiameterToleranceEditField_2Label.Text = 'Setting Piece Max Reform Diameter Tolerance';
            app.SettingPieceMaxReformDiameterToleranceEditField_2 = uieditfield(app.Panel_40, 'numeric');
            app.SettingPieceMaxReformDiameterToleranceEditField_2.Value = 0.12;
            app.SettingPieceChuckValueToleranceEditField_2Label = uilabel(app.Panel_40);
            app.SettingPieceChuckValueToleranceEditField_2Label.Text = 'Setting Piece Chuck Value Tolerance';
            app.SettingPieceChuckValueToleranceEditField_2 = uieditfield(app.Panel_40, 'numeric');
            app.SettingPieceChuckValueToleranceEditField_2.Value = 0.02;
            app.ShowResultsforDropDown_8Label = uilabel(app.DRGSPTab);
            app.ShowResultsforDropDown_8Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_8 = uidropdown(app.DRGSPTab);
            app.ShowResultsforDropDown_8.Items = {'Setting Piece Dome Depth', 'Setting Piece Max Reform Diameter', 'Setting Piece Chuck Value'};
            app.ShowResultsforDropDown_8.Value = 'Setting Piece Dome Depth';
            app.Panel_41 = uipanel(app.DRGSPTab);
            app.SigmaLevelEditField_8Label = uilabel(app.Panel_41);
            app.SigmaLevelEditField_8Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_8 = uieditfield(app.Panel_41, 'numeric');
            app.SigmaLevelEditField_8.Value = 5.15;
            app.Panel_42 = uipanel(app.DRGSPTab);
            app.ShowAnovatablesCheckBox_8 = uicheckbox(app.Panel_42);
            app.ShowAnovatablesCheckBox_8.Text = 'Show Anova tables';
            app.Panel_43 = uipanel(app.DRGSPTab);
            app.RunanalysisButton_8 = uibutton(app.Panel_43, 'push');
            app.RunanalysisButton_8.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_8Pushed, true);
            app.RunanalysisButton_8.Text = 'Run analysis';
            app.FilmscanTab = uitab(app.TabGroup);
            app.FilmscanTab.Title = 'Filmscan';
            app.UITable2_3 = uitable(app.FilmscanTab);
            app.UITable2_3.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_3.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_3.ColumnEditable = true;
            app.UIAxes_3 = uiaxes(app.FilmscanTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes2_3 = uiaxes(app.FilmscanTab);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, 'Y')
            app.UIAxes3_3 = uiaxes(app.FilmscanTab);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, 'Y')
            app.UIAxes4_3 = uiaxes(app.FilmscanTab);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, '')
            ylabel(app.UIAxes4_3, 'Y')
            app.UITable8_3 = uitable(app.FilmscanTab);
            app.UITable8_3.ColumnName = {'Dome Avg'; 'External Avg'; 'Internal Wall Avg'; 'Reverse Wall Avg'; 'Rim Coat Avg'; 'Dome SP'; 'Rev SP'; 'Ext SP'; 'Int SP'};
            app.UITable8_3.RowName = {'Tolerance (um)'; 'RR (mm)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_3 = uilabel(app.FilmscanTab);
            app.SummaryTableLabel_3.Text = 'Summary Table';
            app.Panel_11 = uipanel(app.FilmscanTab);
            app.GageNameSNEditField_3Label = uilabel(app.Panel_11);
            app.GageNameSNEditField_3Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_3 = uieditfield(app.Panel_11, 'text');
            app.CompanyEditField_3Label = uilabel(app.Panel_11);
            app.CompanyEditField_3Label.Text = 'Company';
            app.CompanyEditField_3 = uieditfield(app.Panel_11, 'text');
            app.OperatorEditField_3Label = uilabel(app.Panel_11);
            app.OperatorEditField_3Label.Text = 'Operator';
            app.OperatorEditField_3 = uieditfield(app.Panel_11, 'text');
            app.DirectoryEditField_3Label = uilabel(app.Panel_11);
            app.DirectoryEditField_3Label.Text = 'Directory';
            app.DirectoryEditField_3 = uieditfield(app.Panel_11, 'text');
            app.Panel_12 = uipanel(app.FilmscanTab);
            app.DomeToleranceEditFieldLabel = uilabel(app.Panel_12);
            app.DomeToleranceEditFieldLabel.Text = 'Dome Tolerance';
            app.DomeToleranceEditField = uieditfield(app.Panel_12, 'numeric');
            app.DomeToleranceEditField.Value = 2;
            app.ExternalToleranceEditFieldLabel = uilabel(app.Panel_12);
            app.ExternalToleranceEditFieldLabel.Text = 'External Tolerance';
            app.ExternalToleranceEditField = uieditfield(app.Panel_12, 'numeric');
            app.ExternalToleranceEditField.Value = 2;
            app.InternalWallToleranceEditFieldLabel = uilabel(app.Panel_12);
            app.InternalWallToleranceEditFieldLabel.Text = 'Internal Wall Tolerance';
            app.InternalWallToleranceEditField = uieditfield(app.Panel_12, 'numeric');
            app.InternalWallToleranceEditField.Value = 2;
            app.ReverseWallToleranceEditFieldLabel = uilabel(app.Panel_12);
            app.ReverseWallToleranceEditFieldLabel.Text = 'Reverse Wall Tolerance';
            app.ReverseWallToleranceEditField = uieditfield(app.Panel_12, 'numeric');
            app.ReverseWallToleranceEditField.Value = 2;
            app.RimCoatToleranceEditFieldLabel = uilabel(app.Panel_12);
            app.RimCoatToleranceEditFieldLabel.Text = 'Rim Coat Tolerance';
            app.RimCoatToleranceEditField = uieditfield(app.Panel_12, 'numeric');
            app.RimCoatToleranceEditField.Value = 2;
            app.Panel_13 = uipanel(app.FilmscanTab);
            app.SliceAverageSigmaLevelEditFieldLabel = uilabel(app.Panel_13);
            app.SliceAverageSigmaLevelEditFieldLabel.Text = 'Slice Average Sigma Level';
            app.SliceAverageSigmaLevelEditField = uieditfield(app.Panel_13, 'numeric');
            app.SliceAverageSigmaLevelEditField.Value = 6;
            app.SinglePointSigmaLevelEditFieldLabel = uilabel(app.Panel_13);
            app.SinglePointSigmaLevelEditFieldLabel.Text = 'Single Point Sigma Level';
            app.SinglePointSigmaLevelEditField = uieditfield(app.Panel_13, 'numeric');
            app.SinglePointSigmaLevelEditField.Value = 6;
            app.Panel_14 = uipanel(app.FilmscanTab);
            app.ShowAnovatablesCheckBox_3 = uicheckbox(app.Panel_14);
            app.ShowAnovatablesCheckBox_3.Text = 'Show Anova tables';
            app.Panel_15 = uipanel(app.FilmscanTab);
            app.RunanalysisButton_3 = uibutton(app.Panel_15, 'push');
            app.RunanalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_3Pushed, true);
            app.RunanalysisButton_3.Text = 'Run analysis';
            app.ShowResultsforDropDown_3Label = uilabel(app.FilmscanTab);
            app.ShowResultsforDropDown_3Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_3 = uidropdown(app.FilmscanTab);
            app.ShowResultsforDropDown_3.Items = {'Can Dome', 'Can External', 'Can Internal Wall', 'Can Reverse Wall', 'Can Rim Coat', 'External Single Point', 'Internal Single Point', 'Dome Single Point', 'Reverse Single Point'};
            app.ShowResultsforDropDown_3.Value = 'Can Dome';
            app.UpdateButton_3 = uibutton(app.FilmscanTab, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed2, true);
            app.UpdateButton_3.Text = 'Update';
            app.Panel_54 = uipanel(app.FilmscanTab);
            app.Measure1EditFieldLabel = uilabel(app.Panel_54);
            app.Measure1EditFieldLabel.Text = 'Measure 1';
            app.Measure1EditField = uieditfield(app.Panel_54, 'numeric');
            app.Measure1EditField.Value = 1.531;
            app.Measure2EditFieldLabel = uilabel(app.Panel_54);
            app.Measure2EditFieldLabel.Text = 'Measure 2';
            app.Measure2EditField = uieditfield(app.Panel_54, 'numeric');
            app.Measure2EditField.Value = 5.049;
            app.Measure3EditFieldLabel = uilabel(app.Panel_54);
            app.Measure3EditFieldLabel.Text = 'Measure 3';
            app.Measure3EditField = uieditfield(app.Panel_54, 'numeric');
            app.Measure3EditField.Value = 7.788;
            app.ReferenceValuesLabel = uilabel(app.Panel_54);
            app.ReferenceValuesLabel.Text = 'Reference Values:';
            app.IncludeLinearityCertificateinreportCheckBox = uicheckbox(app.Panel_54);
            app.IncludeLinearityCertificateinreportCheckBox.Text = ' Include Linearity Certificate in report';
            app.ToleranceumEditFieldLabel = uilabel(app.Panel_54);
            app.ToleranceumEditFieldLabel.Text = 'Tolerance (um):';
            app.ToleranceumEditField = uieditfield(app.Panel_54, 'numeric');
            app.ToleranceumEditField.Value = 0.1;
            app.RunIndividuallyButton = uibutton(app.Panel_54, 'push');
            app.RunIndividuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RunIndividuallyButtonPushed, true);
            app.RunIndividuallyButton.Text = 'Run Individually';
            app.Label_5 = uilabel(app.Panel_54);
            app.Label_5.Text = '+';
            app.ToleranceumEditField_2 = uieditfield(app.Panel_54, 'numeric');
            app.ToleranceumEditField_2.Value = 0.05;
            app.Label_6 = uilabel(app.Panel_54);
            app.Label_6.Text = '-';
            app.Label_7 = uilabel(app.Panel_54);
            app.Label_7.Text = '/';
            app.Panel_61 = uipanel(app.FilmscanTab);
            app.LacquerPlateAnalysisLabel = uilabel(app.Panel_61);
            app.LacquerPlateAnalysisLabel.Text = 'Lacquer Plate Analysis';
            app.IncludeLPanalyisinfilmscanreportCheckBox = uicheckbox(app.Panel_61);
            app.IncludeLPanalyisinfilmscanreportCheckBox.Text = 'Include LP analyis in filmscan report';
            app.RunIndividuallyButton_2 = uibutton(app.Panel_61, 'push');
            app.RunIndividuallyButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunIndividuallyButton_2Pushed, true);
            app.RunIndividuallyButton_2.Text = 'Run Individually';
            app.ES8000Tab = uitab(app.TabGroup);
            app.ES8000Tab.Title = 'ES8000';
            app.TabGroup2 = uitabgroup(app.ES8000Tab);
            app.IndividualProccessingTab = uitab(app.TabGroup2);
            app.IndividualProccessingTab.Title = 'Individual Proccessing';
            app.UITable9_4 = uitable(app.IndividualProccessingTab);
            app.UITable9_4.ColumnName = {'Feature'; '1 SD'; '6 SD'; 'Pass/Fail'; 'Threshold (1SD)'};
            app.UITable9_4.RowName = {};
            app.UITable9_5 = uitable(app.IndividualProccessingTab);
            app.UITable9_5.ColumnName = {'Feature'; '1 SD'; '6 SD'; 'Pass/Fail'; 'Threshold (1SD)'};
            app.UITable9_5.RowName = {};
            app.Panel_64 = uipanel(app.IndividualProccessingTab);
            app.GageNameSNEditField_7Label_2 = uilabel(app.Panel_64);
            app.GageNameSNEditField_7Label_2.Text = 'Gage Name + SN';
            app.GageNameSNEditField_13 = uieditfield(app.Panel_64, 'text');
            app.CompanyMiscEditFieldLabel_2 = uilabel(app.Panel_64);
            app.CompanyMiscEditFieldLabel_2.Text = 'Company/Misc';
            app.CompanyMiscEditField_3 = uieditfield(app.Panel_64, 'text');
            app.OperatorEditField_7Label_2 = uilabel(app.Panel_64);
            app.OperatorEditField_7Label_2.Text = 'Operator';
            app.OperatorEditField_13 = uieditfield(app.Panel_64, 'text');
            app.FilenameInfoLabel_13 = uilabel(app.Panel_64);
            app.FilenameInfoLabel_13.Text = 'Filename Info: ';
            app.DirectoryEditField_8Label_2 = uilabel(app.Panel_64);
            app.DirectoryEditField_8Label_2.Text = 'Directory';
            app.DirectoryEditField_14 = uieditfield(app.Panel_64, 'text');
            app.RunAnalysisButton_3 = uibutton(app.Panel_64, 'push');
            app.RunAnalysisButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_3Pushed, true);
            app.RunAnalysisButton_3.Text = 'Run Analysis';
            app.CloseAllButton_2 = uibutton(app.Panel_64, 'push');
            app.CloseAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButton_2Pushed2, true);
            app.CloseAllButton_2.Text = 'Close All';
            app.SpecifyCSVFileEditFieldLabel_2 = uilabel(app.Panel_64);
            app.SpecifyCSVFileEditFieldLabel_2.Text = 'Specify CSV File';
            app.SpecifyCSVFileEditField_2 = uieditfield(app.Panel_64, 'text');
            app.CSVFoundLampLabel_2 = uilabel(app.Panel_64);
            app.CSVFoundLampLabel_2.Text = 'CSV Found?';
            app.CSVFoundLamp_2 = uilamp(app.Panel_64);
            app.ShowAnovaTablesCheckBox_2 = uicheckbox(app.Panel_64);
            app.ShowAnovaTablesCheckBox_2.Text = 'Show Anova Tables';
            app.Label_11 = uilabel(app.Panel_64);
            app.Label_11.Text = {''; ''};
            app.Knob_2 = uiknob(app.Panel_64, 'discrete');
            app.Knob_2.Items = {'Curled shell', 'Progressions', 'Precurled shell'};
            app.Knob_2.ItemsData = {'0', '1', '2'};
            app.Knob_2.Value = '0';
            app.MeaurementsinImperialCheckBox_3 = uicheckbox(app.Panel_64);
            app.MeaurementsinImperialCheckBox_3.Text = 'Meaurements in Imperial';
            app.UITable9_6 = uitable(app.IndividualProccessingTab);
            app.UITable9_6.ColumnName = {'Feature'; '1 SD'; '6 SD'; 'Pass/Fail'; 'Threshold (1SD)'};
            app.UITable9_6.RowName = {};
            app.ReportGeneratorTab = uitab(app.TabGroup2);
            app.ReportGeneratorTab.Title = 'Report Generator';
            app.Panel_65 = uipanel(app.ReportGeneratorTab);
            app.GageNameSNEditField_7Label_3 = uilabel(app.Panel_65);
            app.GageNameSNEditField_7Label_3.Text = 'Gage Name + SN';
            app.GageNameSNEditField_14 = uieditfield(app.Panel_65, 'text');
            app.CompanyMiscEditFieldLabel_3 = uilabel(app.Panel_65);
            app.CompanyMiscEditFieldLabel_3.Text = 'Company/Misc';
            app.CompanyMiscEditField_4 = uieditfield(app.Panel_65, 'text');
            app.OperatorEditField_7Label_3 = uilabel(app.Panel_65);
            app.OperatorEditField_7Label_3.Text = 'Operator';
            app.OperatorEditField_14 = uieditfield(app.Panel_65, 'text');
            app.DirectoryEditField_8Label_3 = uilabel(app.Panel_65);
            app.DirectoryEditField_8Label_3.Text = 'Directory';
            app.DirectoryEditField_15 = uieditfield(app.Panel_65, 'text');
            app.RunAnalysisButton_4 = uibutton(app.Panel_65, 'push');
            app.RunAnalysisButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_4Pushed, true);
            app.RunAnalysisButton_4.Text = 'Run Analysis';
            app.MeaurementsinImperialCheckBox_4 = uicheckbox(app.Panel_65);
            app.MeaurementsinImperialCheckBox_4.Text = 'Meaurements in Imperial';
            app.CSVFoundLampLabel_3 = uilabel(app.Panel_65);
            app.CSVFoundLampLabel_3.Text = 'CSV Found?';
            app.CSVFoundLamp_3 = uilamp(app.Panel_65);
            app.Label_12 = uilabel(app.Panel_65);
            app.Label_12.Text = {''; ''};
            app.Panel_67 = uipanel(app.ReportGeneratorTab);
            app.PathofFolderwithExcelresultsinLabel = uilabel(app.Panel_67);
            app.PathofFolderwithExcelresultsinLabel.Text = 'Path of Folder with Excel results in:';
            app.SpecifyCSVFileEditField_3 = uieditfield(app.Panel_67, 'text');
            app.Panel_66 = uipanel(app.ReportGeneratorTab);
            app.NotesatstartofreportEditFieldLabel = uilabel(app.Panel_66);
            app.NotesatstartofreportEditFieldLabel.Text = 'Notes at start of report';
            app.NotesatstartofreportEditField = uieditfield(app.Panel_66, 'text');
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Title = 'Custom';
            app.UITable2_10 = uitable(app.CustomTab);
            app.UITable2_10.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_10.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_10.ColumnEditable = true;
            app.UIAxes_10 = uiaxes(app.CustomTab);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, '')
            ylabel(app.UIAxes_10, 'Y')
            app.UIAxes2_10 = uiaxes(app.CustomTab);
            title(app.UIAxes2_10, 'Title')
            xlabel(app.UIAxes2_10, '')
            ylabel(app.UIAxes2_10, 'Y')
            app.UIAxes3_10 = uiaxes(app.CustomTab);
            title(app.UIAxes3_10, 'Title')
            xlabel(app.UIAxes3_10, '')
            ylabel(app.UIAxes3_10, 'Y')
            app.UIAxes4_10 = uiaxes(app.CustomTab);
            title(app.UIAxes4_10, 'Title')
            xlabel(app.UIAxes4_10, '')
            ylabel(app.UIAxes4_10, 'Y')
            app.SummaryTableLabel_9 = uilabel(app.CustomTab);
            app.SummaryTableLabel_9.Text = 'Summary Table';
            app.Panel_49 = uipanel(app.CustomTab);
            app.GageNameSNEditField_12Label = uilabel(app.Panel_49);
            app.GageNameSNEditField_12Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_12 = uieditfield(app.Panel_49, 'text');
            app.MiscEditFieldLabel = uilabel(app.Panel_49);
            app.MiscEditFieldLabel.Text = 'Misc';
            app.MiscEditField = uieditfield(app.Panel_49, 'text');
            app.OperatorEditField_12Label = uilabel(app.Panel_49);
            app.OperatorEditField_12Label.Text = 'Operator';
            app.OperatorEditField_12 = uieditfield(app.Panel_49, 'text');
            app.FilenameInfoLabel_12 = uilabel(app.Panel_49);
            app.FilenameInfoLabel_12.Text = 'Filename Info: ';
            app.DirectoryEditField_13Label = uilabel(app.Panel_49);
            app.DirectoryEditField_13Label.Text = 'Directory';
            app.DirectoryEditField_13 = uieditfield(app.Panel_49, 'text');
            app.Panel_50 = uipanel(app.CustomTab);
            app.FeatureNameEditFieldLabel = uilabel(app.Panel_50);
            app.FeatureNameEditFieldLabel.Text = 'Feature Name';
            app.FeatureNameEditField = uieditfield(app.Panel_50, 'text');
            app.Panel_51 = uipanel(app.CustomTab);
            app.SigmaLevelEditField_10Label = uilabel(app.Panel_51);
            app.SigmaLevelEditField_10Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_10 = uieditfield(app.Panel_51, 'numeric');
            app.SigmaLevelEditField_10.Value = 5.15;
            app.ShowAnovatablesCheckBox_10 = uicheckbox(app.Panel_51);
            app.ShowAnovatablesCheckBox_10.Text = 'Show Anova tables';
            app.Panel_53 = uipanel(app.CustomTab);
            app.RunanalysisButton_10 = uibutton(app.Panel_53, 'push');
            app.RunanalysisButton_10.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_10Pushed, true);
            app.RunanalysisButton_10.Text = 'Run analysis';
            app.Panel_55 = uipanel(app.CustomTab);
            app.ToleranceEditField_2Label = uilabel(app.Panel_55);
            app.ToleranceEditField_2Label.Text = 'Tolerance';
            app.ToleranceEditField_2 = uieditfield(app.Panel_55, 'numeric');
            app.ToleranceEditField_2.Value = 0.2;
            app.Panel_56 = uipanel(app.CustomTab);
            app.Switch = uiswitch(app.Panel_56, 'slider');
            app.Switch.Items = {'Repeats Horizontally', 'Repeats Vertically'};
            app.Switch.Value = 'Repeats Horizontally';
            app.FilmweightTab = uitab(app.TabGroup);
            app.FilmweightTab.Title = 'Filmweight';
            app.UITable2_9 = uitable(app.FilmweightTab);
            app.UITable2_9.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Study variance %'; 'Tolerance %'};
            app.UITable2_9.RowName = {'Repeatability'; 'Part to Part'; 'Total Variation'; ''};
            app.UITable2_9.ColumnEditable = true;
            app.UIAxes_9 = uiaxes(app.FilmweightTab);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, 'Y')
            app.UIAxes2_9 = uiaxes(app.FilmweightTab);
            title(app.UIAxes2_9, 'Title')
            xlabel(app.UIAxes2_9, '')
            ylabel(app.UIAxes2_9, 'Y')
            app.UIAxes3_9 = uiaxes(app.FilmweightTab);
            title(app.UIAxes3_9, 'Title')
            xlabel(app.UIAxes3_9, '')
            ylabel(app.UIAxes3_9, 'Y')
            app.UIAxes4_9 = uiaxes(app.FilmweightTab);
            title(app.UIAxes4_9, 'Title')
            xlabel(app.UIAxes4_9, '')
            ylabel(app.UIAxes4_9, 'Y')
            app.UITable8_8 = uitable(app.FilmweightTab);
            app.UITable8_8.ColumnName = {'External Coating'; 'Internal Coating'};
            app.UITable8_8.RowName = {'Tolerance (mm/inch)'; 'RR (mm/inch)'; 'RR (%) '; 'Pass/Fail'};
            app.SummaryTableLabel_8 = uilabel(app.FilmweightTab);
            app.SummaryTableLabel_8.Text = 'Summary Table';
            app.UpdateButton_9 = uibutton(app.FilmweightTab, 'push');
            app.UpdateButton_9.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_9Pushed, true);
            app.UpdateButton_9.Text = 'Update';
            app.Panel_44 = uipanel(app.FilmweightTab);
            app.GageNameSNEditField_11Label = uilabel(app.Panel_44);
            app.GageNameSNEditField_11Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_11 = uieditfield(app.Panel_44, 'text');
            app.CompanyEditField_9Label = uilabel(app.Panel_44);
            app.CompanyEditField_9Label.Text = 'Company';
            app.CompanyEditField_9 = uieditfield(app.Panel_44, 'text');
            app.OperatorEditField_11Label = uilabel(app.Panel_44);
            app.OperatorEditField_11Label.Text = 'Operator';
            app.OperatorEditField_11 = uieditfield(app.Panel_44, 'text');
            app.FilenameInfoLabel_11 = uilabel(app.Panel_44);
            app.FilenameInfoLabel_11.Text = 'Filename Info: ';
            app.DirectoryEditField_12Label = uilabel(app.Panel_44);
            app.DirectoryEditField_12Label.Text = 'Directory';
            app.DirectoryEditField_12 = uieditfield(app.Panel_44, 'text');
            app.Panel_45 = uipanel(app.FilmweightTab);
            app.ExternalCoatingToleranceEditFieldLabel = uilabel(app.Panel_45);
            app.ExternalCoatingToleranceEditFieldLabel.Text = 'External Coating Tolerance ';
            app.ExternalCoatingToleranceEditField = uieditfield(app.Panel_45, 'numeric');
            app.ExternalCoatingToleranceEditField.Value = 1;
            app.InternalCoatingToleranceEditField_2Label = uilabel(app.Panel_45);
            app.InternalCoatingToleranceEditField_2Label.Text = 'Internal Coating Tolerance ';
            app.InternalCoatingToleranceEditField_2 = uieditfield(app.Panel_45, 'numeric');
            app.InternalCoatingToleranceEditField_2.Value = 1;
            app.Panel_46 = uipanel(app.FilmweightTab);
            app.SigmaLevelEditField_9Label = uilabel(app.Panel_46);
            app.SigmaLevelEditField_9Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_9 = uieditfield(app.Panel_46, 'numeric');
            app.SigmaLevelEditField_9.Value = 6;
            app.Panel_48 = uipanel(app.FilmweightTab);
            app.RunanalysisButton_9 = uibutton(app.Panel_48, 'push');
            app.RunanalysisButton_9.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_9Pushed, true);
            app.RunanalysisButton_9.Text = 'Run analysis';
            app.ShowAnovatablesCheckBox_9 = uicheckbox(app.Panel_48);
            app.ShowAnovatablesCheckBox_9.Text = 'Show Anova tables';
            app.ShowResultsforDropDown_9Label = uilabel(app.FilmweightTab);
            app.ShowResultsforDropDown_9Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_9 = uidropdown(app.FilmweightTab);
            app.ShowResultsforDropDown_9.Items = {'Internal Coating', 'External Coating'};
            app.ShowResultsforDropDown_9.Value = 'Internal Coating';
            app.Panel_62 = uipanel(app.FilmweightTab);
            app.LacquerPlateAnalysisLabel_2 = uilabel(app.Panel_62);
            app.LacquerPlateAnalysisLabel_2.Text = 'Lacquer Plate Analysis';
            app.IncludeLPanalyisinReportCheckBox = uicheckbox(app.Panel_62);
            app.IncludeLPanalyisinReportCheckBox.Text = 'Include LP analyis in Report';
            app.RunIndividuallyButton_3 = uibutton(app.Panel_62, 'push');
            app.RunIndividuallyButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunIndividuallyButton_3Pushed, true);
            app.RunIndividuallyButton_3.Text = 'Run Individually';
            app.Panel_63 = uipanel(app.FilmweightTab);
            app.Measure1EditFieldLabel_2 = uilabel(app.Panel_63);
            app.Measure1EditFieldLabel_2.Text = 'Measure 1';
            app.Measure1EditField_2 = uieditfield(app.Panel_63, 'numeric');
            app.Measure1EditField_2.Value = 1.531;
            app.Measure2EditFieldLabel_2 = uilabel(app.Panel_63);
            app.Measure2EditFieldLabel_2.Text = 'Measure 2';
            app.Measure2EditField_2 = uieditfield(app.Panel_63, 'numeric');
            app.Measure2EditField_2.Value = 5.049;
            app.Measure3EditFieldLabel_2 = uilabel(app.Panel_63);
            app.Measure3EditFieldLabel_2.Text = 'Measure 3';
            app.Measure3EditField_2 = uieditfield(app.Panel_63, 'numeric');
            app.Measure3EditField_2.Value = 7.788;
            app.ReferenceValuesLabel_2 = uilabel(app.Panel_63);
            app.ReferenceValuesLabel_2.Text = 'Reference Values:';
            app.IncludeLinearityCertificateinreportCheckBox_2 = uicheckbox(app.Panel_63);
            app.IncludeLinearityCertificateinreportCheckBox_2.Text = ' Include Linearity Certificate in report';
            app.ToleranceumEditFieldLabel_2 = uilabel(app.Panel_63);
            app.ToleranceumEditFieldLabel_2.Text = 'Tolerance (um):';
            app.ToleranceumEditField_3 = uieditfield(app.Panel_63, 'numeric');
            app.ToleranceumEditField_3.Value = 0.1;
            app.RunIndividuallyButton_4 = uibutton(app.Panel_63, 'push');
            app.RunIndividuallyButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunIndividuallyButton_4Pushed, true);
            app.RunIndividuallyButton_4.Text = 'Run Individually';
            app.Label_8 = uilabel(app.Panel_63);
            app.Label_8.Text = '+';
            app.ToleranceumEditField_4 = uieditfield(app.Panel_63, 'numeric');
            app.ToleranceumEditField_4.Value = 0.05;
            app.Label_9 = uilabel(app.Panel_63);
            app.Label_9.Text = '-';
            app.Label_10 = uilabel(app.Panel_63);
            app.Label_10.Text = '/';
            app.FilmweightSPTab = uitab(app.TabGroup);
            app.FilmweightSPTab.Title = 'Filmweight - SP';
            app.UITable2_4 = uitable(app.FilmweightSPTab);
            app.UITable2_4.ColumnName = {'External'; 'Internal'};
            app.UITable2_4.RowName = {'Tolerance'; 'Sigma'; 'SV = sig x SD'; 'Pass/Fail'; ''; ''};
            app.UITable2_4.ColumnEditable = true;
            app.UIAxes_4 = uiaxes(app.FilmweightSPTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes2_4 = uiaxes(app.FilmweightSPTab);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, 'Y')
            app.UIAxes3_4 = uiaxes(app.FilmweightSPTab);
            title(app.UIAxes3_4, 'Title')
            xlabel(app.UIAxes3_4, '')
            ylabel(app.UIAxes3_4, 'Y')
            app.UIAxes4_4 = uiaxes(app.FilmweightSPTab);
            title(app.UIAxes4_4, 'Title')
            xlabel(app.UIAxes4_4, '')
            ylabel(app.UIAxes4_4, 'Y')
            app.EvaluationTableLabel = uilabel(app.FilmweightSPTab);
            app.EvaluationTableLabel.Text = 'Evaluation Table';
            app.Panel_16 = uipanel(app.FilmweightSPTab);
            app.GageNameSNEditField_4Label = uilabel(app.Panel_16);
            app.GageNameSNEditField_4Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_4 = uieditfield(app.Panel_16, 'text');
            app.CompanyEditField_4Label = uilabel(app.Panel_16);
            app.CompanyEditField_4Label.Text = 'Company';
            app.CompanyEditField_4 = uieditfield(app.Panel_16, 'text');
            app.OperatorEditField_4Label = uilabel(app.Panel_16);
            app.OperatorEditField_4Label.Text = 'Operator';
            app.OperatorEditField_4 = uieditfield(app.Panel_16, 'text');
            app.FilenameInfoLabel_4 = uilabel(app.Panel_16);
            app.FilenameInfoLabel_4.Text = 'Filename Info: ';
            app.DirectoryEditField_4Label = uilabel(app.Panel_16);
            app.DirectoryEditField_4Label.Text = 'Directory';
            app.DirectoryEditField_4 = uieditfield(app.Panel_16, 'text');
            app.Panel_17 = uipanel(app.FilmweightSPTab);
            app.ToleranceEditFieldLabel = uilabel(app.Panel_17);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.Panel_17, 'numeric');
            app.ToleranceEditField.Value = 2;
            app.Panel_18 = uipanel(app.FilmweightSPTab);
            app.SigmaLevelEditField_4Label = uilabel(app.Panel_18);
            app.SigmaLevelEditField_4Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_4 = uieditfield(app.Panel_18, 'numeric');
            app.SigmaLevelEditField_4.Value = 4;
            app.Panel_19 = uipanel(app.FilmweightSPTab);
            app.ShowAnovatablesCheckBox_4 = uicheckbox(app.Panel_19);
            app.ShowAnovatablesCheckBox_4.Text = 'Show Anova tables';
            app.Panel_20 = uipanel(app.FilmweightSPTab);
            app.RunanalysisButton_4 = uibutton(app.Panel_20, 'push');
            app.RunanalysisButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButton_4Pushed, true);
            app.RunanalysisButton_4.Text = 'Run analysis';
            app.Panel_21 = uipanel(app.FilmweightSPTab);
            app.LightwillilluminateredifnovaliddatasetcanbeselectedLampLabel = uilabel(app.Panel_21);
            app.LightwillilluminateredifnovaliddatasetcanbeselectedLampLabel.Text = 'Light will illuminate red if no valid data set can be selected :';
            app.LightwillilluminateredifnovaliddatasetcanbeselectedLamp = uilamp(app.Panel_21);
            app.UpdateButton_6 = uibutton(app.FilmweightSPTab, 'push');
            app.UpdateButton_6.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_6Pushed, true);
            app.UpdateButton_6.Text = 'Update';
            app.ShowResultsforDropDown_6Label = uilabel(app.FilmweightSPTab);
            app.ShowResultsforDropDown_6Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_6 = uidropdown(app.FilmweightSPTab);
            app.ShowResultsforDropDown_6.Items = {'External', 'Internal'};
            app.ShowResultsforDropDown_6.Value = 'External';
            app.EPGTab = uitab(app.TabGroup);
            app.EPGTab.Title = 'EPG';
            app.Panel_33 = uipanel(app.EPGTab);
            app.GageNameSNEditField_8Label = uilabel(app.Panel_33);
            app.GageNameSNEditField_8Label.Text = 'Gage Name + SN';
            app.GageNameSNEditField_8 = uieditfield(app.Panel_33, 'text');
            app.CompanyMiscEditField_2Label = uilabel(app.Panel_33);
            app.CompanyMiscEditField_2Label.Text = 'Company/Misc';
            app.CompanyMiscEditField_2 = uieditfield(app.Panel_33, 'text');
            app.OperatorEditField_8Label = uilabel(app.Panel_33);
            app.OperatorEditField_8Label.Text = 'Operator';
            app.OperatorEditField_8 = uieditfield(app.Panel_33, 'text');
            app.DirectoryEditField_9Label = uilabel(app.Panel_33);
            app.DirectoryEditField_9Label.Text = 'Directory';
            app.DirectoryEditField_9 = uieditfield(app.Panel_33, 'text');
            app.UITable2_11 = uitable(app.EPGTab);
            app.UITable2_11.ColumnName = {'Standard Devitation'; 'Study Variance'; 'Tolerance %'};
            app.UITable2_11.RowName = {'Repeatability'; 'Part to Part'; 'Operator'; 'Total Variation'; ''};
            app.UITable2_11.ColumnEditable = true;
            app.UITable8_9 = uitable(app.EPGTab);
            app.UITable8_9.ColumnName = {'Feature 1'; 'Feature 2'; 'Feature 3'};
            app.UITable8_9.RowName = {'SD limit'; 'Repeatability SD'; 'Pass/Fail'};
            app.SummaryTableLabel_10 = uilabel(app.EPGTab);
            app.SummaryTableLabel_10.Text = 'Summary Table';
            app.Panel_57 = uipanel(app.EPGTab);
            app.SigmaLevelEditField_11Label = uilabel(app.Panel_57);
            app.SigmaLevelEditField_11Label.Text = 'Sigma Level';
            app.SigmaLevelEditField_11 = uieditfield(app.Panel_57, 'numeric');
            app.SigmaLevelEditField_11.Value = 5.15;
            app.ShowAnovatablesCheckBox_11 = uicheckbox(app.Panel_57);
            app.ShowAnovatablesCheckBox_11.Text = 'Show Anova tables';
            app.UIAxes_11 = uiaxes(app.EPGTab);
            title(app.UIAxes_11, 'Title')
            xlabel(app.UIAxes_11, '')
            ylabel(app.UIAxes_11, 'Y')
            app.UIAxes2_11 = uiaxes(app.EPGTab);
            title(app.UIAxes2_11, 'Title')
            xlabel(app.UIAxes2_11, '')
            ylabel(app.UIAxes2_11, 'Y')
            app.UIAxes4_11 = uiaxes(app.EPGTab);
            title(app.UIAxes4_11, 'Title')
            xlabel(app.UIAxes4_11, '')
            ylabel(app.UIAxes4_11, 'Y')
            app.UIAxes2_12 = uiaxes(app.EPGTab);
            title(app.UIAxes2_12, 'Title')
            xlabel(app.UIAxes2_12, '')
            ylabel(app.UIAxes2_12, 'Y')
            app.UIAxes4_12 = uiaxes(app.EPGTab);
            title(app.UIAxes4_12, 'Title')
            xlabel(app.UIAxes4_12, '')
            ylabel(app.UIAxes4_12, 'Y')
            app.UIAxes4_13 = uiaxes(app.EPGTab);
            title(app.UIAxes4_13, 'Title')
            xlabel(app.UIAxes4_13, '')
            ylabel(app.UIAxes4_13, 'Y')
            app.UpdateButton_10 = uibutton(app.EPGTab, 'push');
            app.UpdateButton_10.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_10Pushed, true);
            app.UpdateButton_10.Text = 'Update';
            app.ShowResultsforDropDown_10Label = uilabel(app.EPGTab);
            app.ShowResultsforDropDown_10Label.Text = 'Show Results for';
            app.ShowResultsforDropDown_10 = uidropdown(app.EPGTab);
            app.ShowResultsforDropDown_10.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.ShowResultsforDropDown_10.Value = 'Feature 1';
            app.Panel_58 = uipanel(app.EPGTab);
            app.ExceldatacompleteLampLabel = uilabel(app.Panel_58);
            app.ExceldatacompleteLampLabel.Text = 'Excel data complete?';
            app.ExceldatacompleteLamp = uilamp(app.Panel_58);
            app.Panel_59 = uipanel(app.EPGTab);
            app.Usecodetoflipresultsinstack0204CheckBox = uicheckbox(app.Panel_59);
            app.Usecodetoflipresultsinstack0204CheckBox.Text = 'Use code to flip results in stack 02 + 04';
            app.RunAnalysisButton_2 = uibutton(app.Panel_59, 'push');
            app.RunAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_2Pushed, true);
            app.RunAnalysisButton_2.Text = 'Run Analysis';
            app.MeaurementsinImperialCheckBox = uicheckbox(app.Panel_59);
            app.MeaurementsinImperialCheckBox.Text = 'Meaurements in Imperial';
            app.OperatorALabel = uilabel(app.EPGTab);
            app.OperatorALabel.Text = 'Operator A - ';
            app.Label_3 = uilabel(app.EPGTab);
            app.Label_3.Text = '*';
            app.OperatorBLabel = uilabel(app.EPGTab);
            app.OperatorBLabel.Text = 'Operator B - ';
            app.Label_4 = uilabel(app.EPGTab);
            app.Label_4.Text = '*';
            app.Panel_60 = uipanel(app.EPGTab);
            app.Feature1DropDownLabel = uilabel(app.Panel_60);
            app.Feature1DropDownLabel.Text = 'Feature 1';
            app.Feature1DropDown = uidropdown(app.Panel_60);
            app.Feature1DropDown.Items = {'Bubble/Button Height', 'Bubble/Button Thickness', 'Button Coining', 'Bead Coining', 'Rivet Diameter', 'Rivet Residual', 'Form Stiffening', 'Stolle Step', 'Score Residual', 'Custom (Entered to the right)'};
            app.Feature1DropDown.Value = 'Bubble/Button Height';
            app.Feature2DropDownLabel = uilabel(app.Panel_60);
            app.Feature2DropDownLabel.Text = 'Feature 2';
            app.Feature2DropDown = uidropdown(app.Panel_60);
            app.Feature2DropDown.Items = {'N/A', 'Bubble/Button Height', 'Bubble/Button Thickness', 'Button Coining', 'Bead Coining', 'Rivet Diameter', 'Rivet Residual', 'Form Stiffening', 'Stolle Step', 'Score Residual', 'Custom (Entered to the right)'};
            app.Feature2DropDown.Value = 'Bubble/Button Height';
            app.Feature3DropDownLabel = uilabel(app.Panel_60);
            app.Feature3DropDownLabel.Text = 'Feature 3';
            app.Feature3DropDown = uidropdown(app.Panel_60);
            app.Feature3DropDown.Items = {'N/A', 'Bubble/Button Height', 'Bubble/Button Thickness', 'Button Coining', 'Bead Coining', 'Rivet Diameter', 'Rivet Residual', 'Form Stiffening', 'Stolle Step', 'Score Residual', 'Custom (Entered to the right)'};
            app.Feature3DropDown.Value = 'Bubble/Button Height';
            app.EditField = uieditfield(app.Panel_60, 'numeric');
            app.EditField_2 = uieditfield(app.Panel_60, 'numeric');
            app.EditField_3 = uieditfield(app.Panel_60, 'numeric');
            app.ToleranceLabel = uilabel(app.Panel_60);
            app.ToleranceLabel.Text = 'Tolerance';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.BackendCanLabel = uilabel(app.HelpTab);
            app.BackendCanLabel.Text = 'Backend Can';
            app.BackendSettingPieceLabel = uilabel(app.HelpTab);
            app.BackendSettingPieceLabel.Text = 'Backend Setting Piece';
            app.CMatlabBackendRR_CancsvLabel = uilabel(app.HelpTab);
            app.CMatlabBackendRR_CancsvLabel.Text = 'C:\Matlab\Backend\RR_Can.csv';
            app.CMatlabBackendRR_SPcsvLabel = uilabel(app.HelpTab);
            app.CMatlabBackendRR_SPcsvLabel.Text = 'C:\Matlab\Backend\RR_SP.csv';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel.Text = 'Height: B2:B31    Flange Width: F2:F31    Plug Diameter: J2:J31    Dome Depth: R2:R31    Max Reform Diameter: N2:N31';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_2 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_2.Text = 'Height: B2:B31    Dome Depth: R2:R31    Diameter 1: S2:S31    Diameter 2: T2:T31    Reform Diameter: P2:P31';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_3 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_3.Text = 'Can Height: C2:C31    Can Dome Depth: E2:E31    Can Top Wall: F2:F31    Can Mid Wall: H2:H31';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_4 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_4.Text = 'Setting Piece Height: C2:C31    Setting Piece Dome Depth: E2:E31';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_5 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_5.Text = 'Can Dome Depth: B2:B31    Can Reform Diameter: C2:C31    Can Chuck Value: G2:G31    Can Reform Height: D2:D31';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_6 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_6.Text = 'SP Dome Depth: B2:B31    SP Reform Diameter: C2:C31    SP Chuck Value: G2:G31';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_7 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_7.Text = 'Dome: D2:D31    Dome Single Point: E2:E31    External: D2:D31    External Single Point: E2:E31   ';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_8 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_8.Text = 'External Coating: D2:D481    Internal Coating: D2:D481';
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_9 = uilabel(app.HelpTab);
            app.HeightB2B31FlangeWIdthF2F31PlugDiamLabel_9.Text = 'Single Point External: D2:D151    Single Point Internal: D2:D151';
            app.FrontendCanLabel = uilabel(app.HelpTab);
            app.FrontendCanLabel.Text = 'Frontend Can';
            app.FrontendSettingPieceLabel = uilabel(app.HelpTab);
            app.FrontendSettingPieceLabel.Text = 'Frontend Setting Piece';
            app.DRGCanLabel = uilabel(app.HelpTab);
            app.DRGCanLabel.Text = 'DRG Can';
            app.DRGSettingPieceLabel = uilabel(app.HelpTab);
            app.DRGSettingPieceLabel.Text = 'DRG Setting Piece';
            app.Label_2 = uilabel(app.HelpTab);
            app.Label_2.Text = ' Internal Wall: D2:D31    Internal Single Point: E2:E31    Reverse Wall: D2:D31    Reverse Single Point: E2:E31    Rim Coat: D2:D31';
            app.FilmscanLabel = uilabel(app.HelpTab);
            app.FilmscanLabel.Text = 'Filmscan';
            app.OpticalFilmweightLabel = uilabel(app.HelpTab);
            app.OpticalFilmweightLabel.Text = 'Optical Filmweight';
            app.OpticalFIlmweightArdaghSPLabel = uilabel(app.HelpTab);
            app.OpticalFIlmweightArdaghSPLabel.Text = 'Optical FIlmweight - Ardagh SP';
            app.CMatlabFrontendRR_CancsvLabel = uilabel(app.HelpTab);
            app.CMatlabFrontendRR_CancsvLabel.Text = 'C:\Matlab\Frontend\RR_Can.csv';
            app.CMatlabFrontendRR_SPcsvLabel = uilabel(app.HelpTab);
            app.CMatlabFrontendRR_SPcsvLabel.Text = 'C:\Matlab\Frontend\RR_SP.csv';
            app.CMatlabDRGRR_CANcsvLabel = uilabel(app.HelpTab);
            app.CMatlabDRGRR_CANcsvLabel.Text = 'C:\Matlab\DRG\RR_CAN.csv';
            app.CMatlabDRGRR_SPcsvLabel = uilabel(app.HelpTab);
            app.CMatlabDRGRR_SPcsvLabel.Text = 'C:\Matlab\DRG\RR_SP.csv';
            app.CMatlabFilmscanLabel = uilabel(app.HelpTab);
            app.CMatlabFilmscanLabel.Text = 'C:\Matlab\Filmscan';
            app.CMatlabOpticalFilmweightRR_EXTcsvLabel = uilabel(app.HelpTab);
            app.CMatlabOpticalFilmweightRR_EXTcsvLabel.Text = 'C:\Matlab\Optical Filmweight\RR_EXT.csv';
            app.RR_DomecsvLabel = uilabel(app.HelpTab);
            app.RR_DomecsvLabel.Text = 'RR_Dome.csv';
            app.RR_RimcsvLabel = uilabel(app.HelpTab);
            app.RR_RimcsvLabel.Text = 'RR_Rim.csv';
            app.RR_IntcsvLabel = uilabel(app.HelpTab);
            app.RR_IntcsvLabel.Text = 'RR_Int.csv';
            app.RR_ExtcsvLabel = uilabel(app.HelpTab);
            app.RR_ExtcsvLabel.Text = 'RR_Ext.csv';
            app.RR_RevcsvLabel = uilabel(app.HelpTab);
            app.RR_RevcsvLabel.Text = 'RR_Rev.csv';
            app.CMatlabOpticalFilmweightRR_INTcsvLabel = uilabel(app.HelpTab);
            app.CMatlabOpticalFilmweightRR_INTcsvLabel.Text = 'C:\Matlab\Optical Filmweight\RR_INT.csv';
            app.CMatlabOpticalFilmweightSPRR_EXTcsvLabel = uilabel(app.HelpTab);
            app.CMatlabOpticalFilmweightSPRR_EXTcsvLabel.Text = 'C:\Matlab\Optical Filmweight\SPRR_EXT.csv';
            app.CMatlabOpticalFilmweightSPRR_INTcsvLabel = uilabel(app.HelpTab);
            app.CMatlabOpticalFilmweightSPRR_INTcsvLabel.Text = 'C:\Matlab\Optical Filmweight\SPRR_INT.csv';
            app.ES8000Label = uilabel(app.HelpTab);
            app.ES8000Label.Text = 'ES8000';
            app.EPGLabel = uilabel(app.HelpTab);
            app.EPGLabel.Text = 'EPG';
            app.CMatlabES8000RR_ES8000csvLabel = uilabel(app.HelpTab);
            app.CMatlabES8000RR_ES8000csvLabel.Text = 'C:\Matlab\ES8000\RR_ES8000.csv';
            app.CMatlabEPGStack01xlsLabel = uilabel(app.HelpTab);
            app.CMatlabEPGStack01xlsLabel.Text = 'C:\Matlab\EPG\Stack01.xls';
            app.DataD11D20D30Label = uilabel(app.HelpTab);
            app.DataD11D20D30Label.Text = 'Data: D11:  D20 / D30';
            app.FeaturenamesD11andD12Label = uilabel(app.HelpTab);
            app.FeaturenamesD11andD12Label.Text = 'Feature names: D11 and D12';
            app.text2 = uilabel(app.HelpTab);
            app.text2.Text = 'Data: full AH column , ';
            app.GageLabel = uilabel(app.HelpTab);
            app.GageLabel.Text = 'Gage';
            app.FilepathLabel = uilabel(app.HelpTab);
            app.FilepathLabel.Text = 'File path';
            app.ExcelcolumnsforeachfeatureLabel = uilabel(app.HelpTab);
            app.ExcelcolumnsforeachfeatureLabel.Text = 'Excel columns for each feature';
            app.Ifthereismorethan10resultsincolumnDitwillassumeLabel = uilabel(app.HelpTab);
            app.Ifthereismorethan10resultsincolumnDitwillassumeLabel.Text = 'If there is more than 10 results in column D it will assume ';
            app.text5 = uilabel(app.HelpTab);
            app.text5.Text = 'two features are being analysed and will take every other result';
            app.inthecolumnLabel = uilabel(app.HelpTab);
            app.inthecolumnLabel.Text = 'in the column';
            app.test = uilabel(app.HelpTab);
            app.test.Text = 'Nist is calculated assuming 8 repeats from any combination of Nist(1-3_';
            app.Stack02xlsLabel = uilabel(app.HelpTab);
            app.Stack02xlsLabel.Text = 'Stack02.xls';
            app.Stack03xlsLabel = uilabel(app.HelpTab);
            app.Stack03xlsLabel.Text = 'Stack03.xls';
            app.Stack04xlsLabel = uilabel(app.HelpTab);
            app.Stack04xlsLabel.Text = 'Stack04.xls';
            app.ifthereis30resultsitwilltakeevery3rdresultLabel = uilabel(app.HelpTab);
            app.ifthereis30resultsitwilltakeevery3rdresultLabel.Text = ', if there is 30 results it will take every 3rd result';
            app.NorepeatslastvalueinXcolumnLabel = uilabel(app.HelpTab);
            app.NorepeatslastvalueinXcolumnLabel.Text = 'No. repeats - last value in X column , ';
            app.NoSampleslastvalueinXcolumnLabel = uilabel(app.HelpTab);
            app.NoSampleslastvalueinXcolumnLabel.Text = 'No. Samples: last value in X column';
            app.FeaturenamefullACcolumnLabel = uilabel(app.HelpTab);
            app.FeaturenamefullACcolumnLabel.Text = ' Featurename : full AC column , ';
            app.LacquerPlatesLabel = uilabel(app.HelpTab);
            app.LacquerPlatesLabel.Text = 'Lacquer Plates';
            app.ProccessesLPAGfromeitherfilmscanorfilmweighttabsLabel = uilabel(app.HelpTab);
            app.ProccessesLPAGfromeitherfilmscanorfilmweighttabsLabel.Text = 'Proccesses LP(A-G) from either filmscan or filmweight tabs';
            app.filmscantakesthe4thresultandthenevery8thLabel = uilabel(app.HelpTab);
            app.filmscantakesthe4thresultandthenevery8thLabel.Text = '- filmscan, takes the 4th result and then every 8th';
            app.FIlmweight3rdresultandthenevery3rdLabel = uilabel(app.HelpTab);
            app.FIlmweight3rdresultandthenevery3rdLabel.Text = '- FIlmweight 3rd result and then every 3rd';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DrivelettertoPullStoredataEditFieldLabel = uilabel(app.SettingsTab);
            app.DrivelettertoPullStoredataEditFieldLabel.Text = 'Drive letter to Pull/Store data :';
            app.DrivelettertoPullStoredataEditField = uieditfield(app.SettingsTab, 'text');
            app.IfblankdefaultstoCLabel = uilabel(app.SettingsTab);
            app.IfblankdefaultstoCLabel.Text = 'If blank defaults to C';
            % Show the figure after all components are created
            app.Gage_RR_App.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appCombinedloop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Gage_RR_App)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Gage_RR_App)
        end
    end
end